classdef Mission_Planning_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MissionPlanningToolUIFigure     matlab.ui.Figure
        PowertoolMenu                   matlab.ui.container.Menu
        PowermodesMenu                  matlab.ui.container.Menu
        PowerconsumptiondataMenu        matlab.ui.container.Menu
        BatterymodeldataMenu            matlab.ui.container.Menu
        TorquetoolMenu                  matlab.ui.container.Menu
        TorquetooldataMenu              matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        MissionPlannerTab               matlab.ui.container.Tab
        ConsoleLogTextArea              matlab.ui.control.TextArea
        ConsoleLogTextAreaLabel         matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        InitialStateTab                 matlab.ui.container.Tab
        CLIMBParamTable                 matlab.ui.control.Table
        ShowCLIMBParametersButton       matlab.ui.control.Button
        StartSTKButton                  matlab.ui.control.Button
        ShowcurrentTLEButton            matlab.ui.control.Button
        NORADIDEditField                matlab.ui.control.NumericEditField
        NORADIDLabel                    matlab.ui.control.Label
        LoadmostrecentTLEButton         matlab.ui.control.Button
        TLEInfoTable                    matlab.ui.control.Table
        OrbitPropagationTab             matlab.ui.container.Tab
        AccessDataLabel                 matlab.ui.control.Label
        PerigeePassesLabel              matlab.ui.control.Label
        ScenarioStopTimeEditField       matlab.ui.control.EditField
        ScenarioStopTimeEditFieldLabel  matlab.ui.control.Label
        ScenarioStartTimeEditField      matlab.ui.control.EditField
        ScenarioStartTimeEditFieldLabel  matlab.ui.control.Label
        ResetScenarioButton             matlab.ui.control.Button
        PropagateButton                 matlab.ui.control.Button
        PerigeePassDateTableInfo_1      matlab.ui.control.Table
        AccessTableInfo_1               matlab.ui.control.Table
        ManeuverPropagationTab          matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        ApogeeRiseManeuverTab           matlab.ui.container.Tab
        ThrusterEfficiencyEditField     matlab.ui.control.NumericEditField
        ThrusterEfficiencyEditFieldLabel  matlab.ui.control.Label
        ThrustNEditField                matlab.ui.control.NumericEditField
        ThrustNEditFieldLabel           matlab.ui.control.Label
        IspsEditField                   matlab.ui.control.NumericEditField
        IspsEditFieldLabel              matlab.ui.control.Label
        ManeuverDurationinminEditField  matlab.ui.control.NumericEditField
        ManeuverDurationinminEditFieldLabel  matlab.ui.control.Label
        DeleteManeuversButton           matlab.ui.control.Button
        ActivityNameEditFieldLabel_5    matlab.ui.control.Label
        ActivityNameEditFieldLabel_4    matlab.ui.control.Label
        ManOutputGlobalTable            matlab.ui.control.Table
        ManeuverOutputTable             matlab.ui.control.Table
        PropagateManeuversButton        matlab.ui.control.Button
        ManeuverInputTable              matlab.ui.control.Table
        PerigeeDecreaseManeuverTab      matlab.ui.container.Tab
        ActivityNameEditFieldLabel_7    matlab.ui.control.Label
        ActivityNameEditFieldLabel_6    matlab.ui.control.Label
        ManeuverOutputTable_2           matlab.ui.control.Table
        ManeuverInputTable_2            matlab.ui.control.Table
        ComparisonInitialStateTab       matlab.ui.container.Tab
        AccessStartTimeComparisonLabel  matlab.ui.control.Label
        PerigeePassTimeComparisonLabel  matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        AccessComparisonTable           matlab.ui.control.Table
        PerigeeComparisonTable          matlab.ui.control.Table
        AccessWindowsTab                matlab.ui.container.Tab
        MinimumElevationindegEditField  matlab.ui.control.NumericEditField
        MinimumElevationindegEditFieldLabel  matlab.ui.control.Label
        GetAccessDataButton             matlab.ui.control.Button
        ActivityNameEditFieldLabel_3    matlab.ui.control.Label
        AccessTableInfo_2               matlab.ui.control.Table
        CustomAcitvityTab               matlab.ui.container.Tab
        PointingDropDown                matlab.ui.control.DropDown
        PointingDropDownLabel           matlab.ui.control.Label
        ClearallActivitiesButton        matlab.ui.control.Button
        ActivityNameEditFieldLabel_2    matlab.ui.control.Label
        PowerWEditField                 matlab.ui.control.NumericEditField
        PowerWEditFieldLabel            matlab.ui.control.Label
        ActivityStopTimeEditField       matlab.ui.control.EditField
        ActivityStopTimeEditFieldLabel  matlab.ui.control.Label
        ActivityStartTimeEditField      matlab.ui.control.EditField
        ActivityStartTimeEditFieldLabel  matlab.ui.control.Label
        ActivityNameEditField           matlab.ui.control.EditField
        ActivityNameEditFieldLabel      matlab.ui.control.Label
        AddActivityButton               matlab.ui.control.Button
        ActivityOutputTable             matlab.ui.control.Table
        AttitudeProfileTab              matlab.ui.container.Tab
        PointingChangeManeuverLabel     matlab.ui.control.Label
        ThrustPointingLabel             matlab.ui.control.Label
        TargetPointingLabel             matlab.ui.control.Label
        DurationminutesEditField        matlab.ui.control.NumericEditField
        AttitudeGlobalOutputTable       matlab.ui.control.Table
        ClearAttitudeButton             matlab.ui.control.Button
        AttitudeOutputTable             matlab.ui.control.Table
        AddAttitudeProfileButton        matlab.ui.control.Button
        DurationminutesEditFieldLabel   matlab.ui.control.Label
        TrailTimeminutesEditField_2     matlab.ui.control.NumericEditField
        TrailTimeminutesEditField_2Label  matlab.ui.control.Label
        LeadTimeminutesEditField_2      matlab.ui.control.NumericEditField
        LeadTimeminutesEditField_2Label  matlab.ui.control.Label
        TrailTimeminutesEditField       matlab.ui.control.NumericEditField
        TrailTimeminutesEditFieldLabel  matlab.ui.control.Label
        LeadTimeminutesEditField        matlab.ui.control.NumericEditField
        LeadTimeminutesEditFieldLabel   matlab.ui.control.Label
        SequenceofEventsTab             matlab.ui.container.Tab
        ExportSoEButton                 matlab.ui.control.Button
        ConflictOutputTable             matlab.ui.control.Table
        ConflictCheckButton             matlab.ui.control.Button
        CreateActivityScheduleButton    matlab.ui.control.Button
        SoETable                        matlab.ui.control.Table
        ConstraintAnalysisTab_2         matlab.ui.container.Tab
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        InitialBatteryCapacityEditField  matlab.ui.control.NumericEditField
        InitialBatteryCapacityEditFieldLabel  matlab.ui.control.Label
        InitialAngularMomentumfromADCSinNmsLabel  matlab.ui.control.Label
        ADCSConstraintAnalysisButton    matlab.ui.control.Button
        PowerConstraintAnalysisButton   matlab.ui.control.Button
        ConstraintTextArea              matlab.ui.control.TextArea
        SoCPlot_1                       matlab.ui.control.UIAxes
        RPMPlot_1                       matlab.ui.control.UIAxes
        UpdateSchedulePlotButton        matlab.ui.control.Button
        SchedulePlot_1                  matlab.ui.control.UIAxes
        ConstraintAnalysisTab           matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        PowerTab_2                      matlab.ui.container.Tab
        SoCPlot_2                       matlab.ui.control.UIAxes
        BattPowerPlot                   matlab.ui.control.UIAxes
        PowerConPlot                    matlab.ui.control.UIAxes
        PowerGenPlot                    matlab.ui.control.UIAxes
        SchedulePlot_2                  matlab.ui.control.UIAxes
        ADCSTab_2                       matlab.ui.container.Tab
        YPRratesPlot                    matlab.ui.control.UIAxes
        YPRPlot                         matlab.ui.control.UIAxes
        RPMPlot_2                       matlab.ui.control.UIAxes
        SchedulePlot_3                  matlab.ui.control.UIAxes
        AngularMomPlot                  matlab.ui.control.UIAxes
        LongTermPropulsoinToolTab       matlab.ui.container.Tab
        UsePerigeedecreasemaneuversLabel  matlab.ui.control.Label
        SkipeveryxmaneuverEditField_2   matlab.ui.control.NumericEditField
        SkipeveryxmaneuverEditField_2Label  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        ManeuverSettingsLabel           matlab.ui.control.Label
        ClearAstrogatorButton           matlab.ui.control.Button
        ManueverGlobalLongTermTable     matlab.ui.control.Table
        SkipeveryxmaneuverEditField     matlab.ui.control.NumericEditField
        SkipeveryxmaneuverEditFieldLabel  matlab.ui.control.Label
        StoppingConditionLabel          matlab.ui.control.Label
        LongTermPropagateButton         matlab.ui.control.Button
        StartTimeEditField              matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        StopTimeEditField               matlab.ui.control.EditField
        StopTimeEditFieldLabel          matlab.ui.control.Label
        ThrusterEfficiencyEditField_2   matlab.ui.control.NumericEditField
        ThrusterEfficiencyEditField_2Label  matlab.ui.control.Label
        ThrustNEditField_2              matlab.ui.control.NumericEditField
        ThrustNEditField_2Label         matlab.ui.control.Label
        IspsEditField_2                 matlab.ui.control.NumericEditField
        IspsEditField_2Label            matlab.ui.control.Label
        ManeuverDurationinminEditField_2  matlab.ui.control.NumericEditField
        ManeuverDurationinminEditField_2Label  matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        ApogeeAltitudekmEditField       matlab.ui.control.NumericEditField
        ApogeeAltitudekmEditFieldLabel  matlab.ui.control.Label
        AltitudePlotLongTerm            matlab.ui.control.UIAxes
    end

    %% Description:
    %
    properties (Access = public)
       % Global Variables that can be used everywhere within the code with app.variable

       % General
       STKApp
       CLIMB            % Climb satellite object from STK
       CLIMB_longterm   % Climb satellite object from STK used for long-term tool
       FHWN             % Facility FHWN
       root
       TLE_Epoch        % TLE Epoch
       Orbit_data_array % TLE orbital elements from function TLE_processing_fcn
       scenario
       main_directory_path = "C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\Mission_Planning_Tool"

       % Scenario
       scenario_start_time      % Scenario Start Time
       scenario_stop_time       % Scenario Stop Time

       % Access
       access_start_time_init       % First propagation (inintial state without maneuvers)
       access_stop_time_init        % First propagation
       access_start_time            % 2nd propagation
       access_stop_time             % 2nd propagation
       access_start_time_sband
       access_stop_time_sband
       access_start_time_uhf
       access_stop_time_uhf

       % Perigee
       peri_pass_time_init          % First propagation
       peri_pass_time          % 2end propagation

       % Maneuver
       maneuver_start_time  
       maneuver_stop_time
       maneuver_select_logic     % Logic array which indcates at which perigee passes manuever is selected
        
       % Activities Custom
       activityStartTime_custom 
       activityStopTime_custom

       % Attiude to STK
       sunpointing_start_time
       sunpointing_stop_time
       thrustpointing_start_time
       thrustpointing_stop_time
       targetpointing_start_time
       targetpointing_stop_time
       pointchange_start_time
       pointchange_stop_time
       pointchange_name

       % Attitude data from stk
       attitude_start_time % From all attitude profiles
       attitude_stop_time
       sunpointing_startstop_time % datetime array with [start time, stop time]
       thrustpointing_startstop_time
       targetpointing_startstop_time

       slew_startstop_time  % start and stop times for all slew maneuvers
       slew_startstop_s2th_time % start and stop times for slew sun to thrust pointing
       slew_startstop_th2s_time % start and stop times for slew thrust to sun pointing
       slew_startstop_s2ta_time % start and stop times for slew sun to target pointing
       slew_startstop_ta2s_time % start and stop times for slew target to sun pointing

       % SoE
       SoE      % Sequence of Events (tabel with all activitites together

    end

   
    properties (Access = private)
        DialogApp % Description
    end

    %% Functions
    methods (Access = public)
    
        %% Function Console Log
        function ConsoleLog(app,newstr)
            app.ConsoleLogTextArea.Value =  [app.ConsoleLogTextArea.Value; newstr];
            scroll(app.ConsoleLogTextArea, 'bottom');
            %app.ConsoleLogTextArea.Value = str;
        end

        %% Function Orbital Elements
        function [OE_array,TLE_epoch,epoch_jul] = TLE_processing_fcn(app,fname)
            %% Description
            % This function takes TLE as input from txt file and extracts orbital elements
            % Source: https://de.mathworks.com/matlabcentral/fileexchange/39364-two-line-element
            
            %%
            fid = fopen(fname, 'r');        % Open the TLE file for reading
            OE_array = [];                  % Initialize an empty array to store orbital elements
            TLE_epoch = [];
            mu = 398600; % Standard gravitational parameter for the earth
            
            % Read and process TLE sets from the file
            while ~feof(fid)
            % while fopen(fid)
        
                % Read two lines from the file
                L1c = fgets(fid);
                L2c = fgets(fid);
                
                % Display the two lines
                %fprintf(L1c);
                %fprintf(L2c);
                
                % Extract relevant data from the lines
                %L1 = sscanf(L1c,'%d%6d%*c%9d%*c%*2f%f%f%5d%*c%*d%5d%*c%*d%d%5d',[1,9])  % deletes first two digits (year) of epoch
        
                L1 = sscanf(L1c,'%d%6d%*c%9d%*c%f%f%5d%*c%*d%5d%*c%*d%d%5d%*2f',[1,9]);   % keeps first two digits (year) of epoch
                L2 = sscanf(L2c,'%d%6d%f%f%f%f%f%f%f',[1,8]);
                
                % Epoch
                epoch_jul = L1(1,4);   % Epoch Date and Julian Date Fraction
                
                % Apparently there is no direct way in matlab to convert tle epoch
                % in date, therefore the apporach below is used (there is for sure a simpler way)
                epoch_str =  num2str(epoch_jul);                            % convert tle to str
                year = str2num(epoch_str(1:2))+2000;                        % extract number of year (first 2 digits)
                epoch_dayfract = str2num(epoch_str(3:end));                % exctract the other digits (day fraction)
                date_0 = append('1 Jan ',num2str(year),' 00:00:00.000');    % combine it and convert to date
                epoch = char(datetime(date_0,'Format','d MMM uuuu HH:mm:ss.SSS') + days(epoch_dayfract-1)); % actual date of TLE
        
                
                epoch_dayfract = epoch_dayfract + 3;
                if epoch_dayfract > 365
                    epoch_dayfract = epoch_dayfract - 365;
                    year = year + 1;
                end
        
                date_0 = append('1 Jan ',num2str(year),' 00:00:00.000');    % combine it and convert to date
                End_Time = char(datetime(date_0,'Format','d MMM uuuu HH:mm:ss.SSS') + days(epoch_dayfract-1)); 
                
        
                % Orbital elements
                Db = L1(1,5);          % Ballistic Coefficient
                inc = L2(1,3);         % Inclination [deg]
                RAAN = L2(1,4);        % Right Ascension of the Ascending Node [deg]
                e = L2(1,5)/1e7;       % Eccentricity
                w = L2(1,6);           % Argument of periapsis [deg]
                M = L2(1,7);           % Mean anomaly [deg]
                n = L2(1,8);           % Mean motion [Revs per day]
        
                a = (mu/(n*2*pi/(24*3600))^2)^(1/3);  % Semi-major axis [km]
                ra = a*(e+1)-6378;      % Apogee
                rp = a*(1-e)-6378;      % Perigee
            
                % Calculate the eccentric anomaly using Mean anomaly
                err = 1e-10;  % Calculation Error
                M_rad = M*pi/180;
                E0 = M;
                t = 1;
                itt = 0;
                while (t)
                    E = M + e*sind(E0);     % Eccentrcic anomaly
                    if (abs(E-E0) < err)
                        t = 0;
                    end
                    E0 = E;
                    itt = itt + 1;
                end
                
                % get true anoamly
                theta = acosd((cosd(E)-e)/(1-e*cosd(E)));   % Not 100% sure this is correct
        
                % Six orbital elements
                OE = [a e inc ra rp RAAN w E n Db M];
                OE_array = [OE_array; OE];                  % Append OE to the array
                TLE_epoch = [TLE_epoch; epoch];
               
            end
            
            % Close the file
            fclose(fid);
        end

        %%
        function setInitState(app,simulation_start_time)
            %% Description:
            % Get Propagator property of satellite
            % Note: Astrogator needs to be set as propagator before calling this function with app.CLIMB.SetPropagatorType('ePropagatorAstrogator');
            % Astrogator Propagator (from code snipets: https://help.agi.com/stkdevkit/11.4.0/Content/stkObjects/ObjModMatlabCodeSamples.htm)
            
            %%
            astrogator = app.CLIMB.Propagator;      % Get Propagator property of satellite
            astrogator.MainSequence.RemoveAll();    % Clear Astrogator segments
            
            % Get Spacecraft Paramaters from the CLIMB_Parameter.xlsx file.
            % The parameters are called by the variable name of the second column of the excel
            % file. Therefore, making it possible to add stuff and change
            % rows of the paramers. Only the order of the three columns is
            % important
            CLIMB_parameters = readtable(append(app.main_directory_path,"\CLIMB_Parameter.xlsx")); % Get Climb Parameter table
            idx = ismember(CLIMB_parameters.Var2, "");  % Get index of all empty rows
            CLIMB_parameters(idx,:) = [];               % Delete all empty rows 
            CLIMB_parameters.Properties.RowNames = [CLIMB_parameters.Var2]; % Set the variable names as the table row names (important to call the paramers by the variable names)

            %------------------Insert Initial state Segment----------------
            initState = astrogator.MainSequence.Insert('eVASegmentTypeInitialState','Initail State','-');
            initState.OrbitEpoch = app.TLE_Epoch;   % Set Orbit Epoch to TLE Epoch
            
            % Initial Orbit State
            % Get the orbital elelemnts from the TLE with the function Orbit_data_array
            % and set as initial state of the astrogator
            initState.SetElementType('eVAElementTypeKeplerian');
            kep = initState.Element;
            kep.ApoapsisAltitudeSize = app.Orbit_data_array(:,4);
            kep.Eccentricity = app.Orbit_data_array(:,2);
            kep.Inclination = app.Orbit_data_array(:,3);
            kep.RAAN = app.Orbit_data_array(:,6);
            kep.ArgOfPeriapsis = app.Orbit_data_array(:,7);
            kep.MeanAnomaly = app.Orbit_data_array(:,11);
            
            % Spacecraft Parameters:
            sc_parameter = initState.SpacecraftParameters;
            sc_parameter.DryMass = CLIMB_parameters.Var3('DryMass');    % dry mass - kg
            sc_parameter.Cd = CLIMB_parameters.Var3('Cd');              % coefficient Cd
            sc_parameter.DragArea = CLIMB_parameters.Var3('DragArea');  % Drag-area - m^2
            sc_parameter.Cr = CLIMB_parameters.Var3('Cr');              % coefficient Cr
            sc_parameter.SolarRadiationPressureArea = CLIMB_parameters.Var3('SolarRadiationPressureArea');  % Solar radiation pressure Pressure Area m^2
            sc_parameter.Ck = CLIMB_parameters.Var3('Ck');                                                  % coefficient Cr
            sc_parameter.RadiationPressureArea = CLIMB_parameters.Var3('RadiationPressureArea');            % Radiation Pressure Area - m^2
            sc_parameter.K1 = CLIMB_parameters.Var3('K1'); 
            sc_parameter.K2 = CLIMB_parameters.Var3('K2');
            
            % Fuel Tank:
            ft = initState.FuelTank;
            ft.TankPressure = CLIMB_parameters.Var3('TankPressure');        % Tank pressure - Pa
            ft.TankVolume = CLIMB_parameters.Var3('TankVolume');            % Tank Volume - m^2
            ft.TankTemperature = CLIMB_parameters.Var3('TankTemperature');  % Tank Temperature
            ft.FuelDensity = CLIMB_parameters.Var3('FuelDensity');          % Fuel density - kg/m^3
            ft.FuelMass = CLIMB_parameters.Var3('FuelMass');                % Fuel Mass - kg
            ft.MaximumFuelMass = CLIMB_parameters.Var3('MaximumFuelMass');  % Maximum Fuel Mass - kg

            %------------------Insert Propagator Segment-------------------
            astro_propagator_segment = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propagate_1','-');
            astro_propagator_segment.PropagatorName = 'Earth HPOP Default v10';
            astro_propagator_segment.Properties.Color = 16777215;     % red
            propagator_stopcond = astro_propagator_segment.StoppingConditions.Add('Epoch');
            propagator_stopcond.Properties.Trip = simulation_start_time;
            astro_propagator_segment.StoppingConditions.Remove('Duration');

        end
        
        %% 
        function SchedulePlot(app,SchedulePlot)
        % Creates the schedule plot of all activities

            % Second Schedule Plot 
            yaxis_names = {'Attitude Pointing','Pointing Change','Contact Windows','Orbital Maneuvers','Perigee Passes','Custom Activity'};
            % cla(app.SchedulePlot_1,app.SchedulePlot_3) % Delete all plots
            % ax=[app.SchedulePlot_1,app.SchedulePlot_3];
            %SchedulePlot = [app.SchedulePlot_1,app.SchedulePlot_2,app.SchedulePlot_3];
            cla(SchedulePlot); % Delete all plots
            ax=SchedulePlot;
            
            % ---------Plot---------
            % Use try/end to skip error if no access/manuever/activity data
            % are available (e.g. no maneuvers scheduled)

            % ---Attitude---
            disp("targetpointing_startstop_time")
            disp(app.targetpointing_startstop_time)
            disp("slew_startstop_time")
            disp(app.slew_startstop_time)
            
            % Plot pointing profiles
            try
            plot(ax,[app.attitude_start_time(1),app.attitude_stop_time(1)],[1 1], 'linewidth',3,'color','yellow') % initial sun pointing segment
            plot(ax,app.sunpointing_startstop_time,[1 1], 'linewidth',3,'color','yellow')
            end
            try
            plot(ax,app.thrustpointing_startstop_time,[1 1], 'linewidth',3,'color','blue')
            end
            try
            plot(ax,app.targetpointing_startstop_time,[1 1], 'linewidth',3,'color','green')
            end
            
            % Plot slew profiles
            try
            plot(ax,app.slew_startstop_time,[2 2], 'linewidth',3,'color','black') 
            end
            

            % ---Access Data---
            try
            access_start = app.access_start_time;
            access_stop = app.access_stop_time;
            plot(ax,[access_start access_stop],[3 3], 'linewidth',5,'color','green')
            end

            % ---Perigee---
            try
            plot(ax,app.peri_pass_time,5,'+','linewidth',0.5,'color','blue')
            end

            % ---Maneuver Data---
            try
            man_start = app.maneuver_start_time;
            man_stop = app.maneuver_stop_time;
            plot(ax,[man_start man_stop],[4 4],'linewidth',1,'color','blue')
            end
            
            % ---Custom activities---
            try
            activity_start = datetime(app.ActivityOutputTable.Data.Var3);
            activity_stop = datetime(app.ActivityOutputTable.Data.Var4);
            plot(ax,[activity_start activity_stop],[6 6],'linewidth',1,'color','#D95319')
            end
            
            % ---Plot Settings---
            % Set axis limits
            ylim(ax, [0,7])
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')]')
            
            % Set y-axis labels
            yticks(ax,[1 2 3 4 5 6]);  % Position the yaxis names
            yticklabels(ax,yaxis_names);            % Use activity names as labels
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadmostrecentTLEButton
        function LoadmostrecentTLEButtonPushed(app, event)
            %% Description
            % This function runs python scrip TLE_acquisition.py which uses spacetrack.org API 
            % to get most recent TLE and saves it in txt file current_TLE.txt
            % The Orbital elements are then extracted with function TLE_processing_fcn and shown in table: TLEInfoTable
            % This function is completely indepenet from rest of the code,
            % only needs function TLE_processing_fcn
            
            %%
            % Acquire current TLE from python script (-> runs spacetrack library to get most recent TLE of chosen NORAD ID)
            % Python script writes the TLE to text file "current_TLE.txt"
            pyrunfile(append(app.main_directory_path,'\TLE_acquisition.py'))
            
            % Get TLE txt file and extract orbital elements with TLE_processing_fcn function. Could also be done directly in python script with ephem or other library
            TLE_file = append(app.main_directory_path,'\current_TLE.txt');  % TLE file path\name
            [app.Orbit_data_array,app.TLE_Epoch] = TLE_processing_fcn(app,TLE_file); % function that extracts orbital elements from TLEs
            
            a = app.Orbit_data_array(:,1);
            ra = app.Orbit_data_array(:,4);
            rp = app.Orbit_data_array(:,5);
            ecc = app.Orbit_data_array(:,2);
            Incl = app.Orbit_data_array(:,3);
            RAAN = app.Orbit_data_array(:,6);
            ArgPeri = app.Orbit_data_array(:,7);
            MeanAno = app.Orbit_data_array(:,11);
            MeanMotion = app.Orbit_data_array(:,8); % Something wrong here, is it true anomaly?
            Db = app.Orbit_data_array(:,5); %ballistic coefficienbt
            Orbit_array_sorted = [ra rp a ecc Incl RAAN MeanAno ArgPeri MeanMotion Db];

            Name={'Apogee (km)','Perigee (km)','Semi-Major Axis (km)','Eccentrictiy','Inclination (deg)','RAAN (deg)','Mean Anomaly (deg)','Argument of Perigee (deg)','Mean Motion','Ballsistic Coef'};
            app.TLEInfoTable.Data=table(["TLE Epoch",Name]',[{app.TLE_Epoch},num2cell(Orbit_array_sorted)]');

        end

        % Button pushed function: ShowcurrentTLEButton
        function ShowcurrentTLEButtonPushed(app, event)
            %% Description: 

            %%

            TLE_file = append(app.main_directory_path,'\current_TLE.txt');  % TLE file path\name
            [app.Orbit_data_array,app.TLE_Epoch] = TLE_processing_fcn(app,TLE_file); % function that extracts orbital elements from TLEs
 
            a = app.Orbit_data_array(:,1);
            ra = app.Orbit_data_array(:,4);
            rp = app.Orbit_data_array(:,5);
            ecc = app.Orbit_data_array(:,2);
            Incl = app.Orbit_data_array(:,3);
            RAAN = app.Orbit_data_array(:,6);
            ArgPeri = app.Orbit_data_array(:,7);
            MeanAno = app.Orbit_data_array(:,11);
            MeanMotion = app.Orbit_data_array(:,8); % Something wrong here, is it true anomaly?
            Db = app.Orbit_data_array(:,5); %ballistic coefficienbt
            Orbit_array_sorted = [ra rp a ecc Incl RAAN MeanAno ArgPeri MeanMotion Db];

            Name={'Apogee (km)','Perigee (km)','Semi-Major Axis (km)','Eccentrictiy','Inclination (deg)','RAAN (deg)','Mean Anomaly (deg)','Argument of Perigee (deg)','Mean Motion','Ballistic Coefficient'};
            app.TLEInfoTable.Data=table(["TLE Epoch",Name]',[{app.TLE_Epoch},num2cell(Orbit_array_sorted)]');
        end

        % Button pushed function: StartSTKButton
        function StartSTKButtonPushed(app, event)
            %% Description:
            % Connetcs to STK and opens Scenrio CLIMB_scenario_v2\CLIMB_scenario1.sc
            
            
            %% Set Scenario time from GUI user input
            app.scenario_start_time = app.ScenarioStartTimeEditField.Value;
            app.scenario_stop_time = app.ScenarioStopTimeEditField.Value;
            % start_time_value = datetime(app.scenario_start_time, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % stop_time_value=datetime(app.Scenario_Stop_Time, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');

            %% Connect to STK

            % app.STKApp = actxGetRunningServer('STK12.application'); % Get running STK scenario 

            if isempty(app.STKApp) || ~iscom(app.STKApp)
                    app.STKApp = actxserver('STK12.Application');
                    % app.STKApp.visible = false;
            end
            app.root = app.STKApp.Personality2;
            
            %% Open Scenario and set scenario time
            disp('Opening STK Scenario')
            app.ConsoleLog("Opening STK Scenario");
            
            scenario_file = append(app.main_directory_path,'\STK\CLIMB_scenario_v2\CLIMB_scenario1.sc');
            app.root.LoadScenario(scenario_file)

            app.scenario = app.root.CurrentScenario;
            app.scenario.SetTimePeriod(app.scenario_start_time,app.scenario_stop_time);
            app.scenario.StartTime = app.scenario_start_time;
            app.scenario.StopTime = app.scenario_stop_time;
            app.root.ExecuteCommand('Animate * Reset');

            disp('STK Scenario is Running')
            app.ConsoleLog("STK Scenario is Running");

        end

        % Button pushed function: PropagateButton
        function PropagateButtonPushed(app, event)
            %% Description
            
            %% Delete Parameter
            app.maneuver_start_time = [];
            app.maneuver_stop_time = [];
            app.access_start_time = [];
            app.access_stop_time = [];
            app.peri_pass_time = [];

            app.ManOutputGlobalTable.Data = [];
            app.AccessTableInfo_2.Data = [];
            %% Get Inputs from TLE and scenario start/stop time
            disp("Getting Input files")
            app.ConsoleLog("Getting Input files");
            
            % Set scenario time. This is already done in the code above when clicking the "STK Start" button, however, 
            % is here defined again to be able to change simultion time also afterwards.
            app.scenario_start_time = app.ScenarioStartTimeEditField.Value;
            app.scenario_stop_time = app.ScenarioStopTimeEditField.Value;
            app.scenario.SetTimePeriod(app.scenario_start_time,app.scenario_stop_time);
            app.scenario.StartTime = app.scenario_start_time;
            app.scenario.StopTime = app.scenario_stop_time;

            % TLE----------------
            % TLE_file = 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\Mission_Planning_Tool\TLE_PEGASUS_07082023.txt';  % TLE file path\name
            
            % Acquire current TLE from python script (-> runs spacetrack library to get most recent TLE of chosen NORAD ID)
            % Python script writes the TLE to text file "current_TLE.txt"
            % pyrunfile('C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\Mission_Planning_Tool\TLE_acquisition.py')
            % 
            % % Get TLE txt file and extract orbital elements with TLE_processing_fcn function. Could also be done directly in python script with ephem or other library
            TLE_file = append(app.main_directory_path,'\current_TLE.txt');  % TLE file path\name
            [app.Orbit_data_array,app.TLE_Epoch] = TLE_processing_fcn(app,TLE_file); % function that extracts orbital elements from TLEs
            
            
            %% Open Objects
            disp('Opening Objects')
            app.ConsoleLog("Opening Objects");
            
            app.CLIMB = app.scenario.Children.Item('CLIMB');            % Open Satellite CLIMB
            app.FHWN = app.scenario.Children.Item('FHWN');              % Ground Station
            
            %% Set propagator astrogator, set initial state and propagate over scenario timeframe
            % Astrogator Propagator (from code snipets:
            % https://help.agi.com/stkdevkit/11.4.0/Content/stkObjects/ObjModMatlabCodeSamples.htm)
            app.CLIMB.SetPropagatorType('ePropagatorAstrogator');   % Set astrogator
            astrogator = app.CLIMB.Propagator;      % get astrogator property
            astrogator.MainSequence.RemoveAll();    % clear astrogator MSC (remove all segments)
            
            % Run setInitState function which sets the initial state
            % Inserts the following segments:
            %   - Initial State Segment: TLE as initial state
            %   - Propagator Segment: Propagates from TLE epoch to scenario start time set in GUI by the user
            setInitState(app,app.scenario_start_time);

            %------------------Insert Propagator Segment-------------------
            % Propagates until scenario stop time
            astro_propagator_segment = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propagate_2','-');
            astro_propagator_segment.PropagatorName = 'Earth HPOP Default v10';
            astro_propagator_segment.Properties.Color = 65280;     % green
            propagator_stopcond = astro_propagator_segment.StoppingConditions.Add('Epoch');
            propagator_stopcond.Properties.Trip = app.scenario_stop_time;
            astro_propagator_segment.StoppingConditions.Remove('Duration');
            
            % Run Astrogator
            astrogator.RunMCS;

            %% Access Report and Visualization in Tabel: AccessTableInfo_1
            % Get acces start/stop time and duration
            disp('Getting access windows')
            app.ConsoleLog("Getting access windows");

            % Set access targets and compute access
            access = app.FHWN.GetAccessToObject(app.CLIMB); % acess from FHWN to CLIMB
            access.ComputeAccess;

            % Get access data provider and get report for time range scenario.StartTime to scenario.StopTime
            accessDP = access.DataProviders.Item('Access Data').Exec(app.scenario.StartTime,app.scenario.StopTime);

            % Access Start/Stop times and duration
            app.access_start_time = datetime(accessDP.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.access_stop_time = datetime(accessDP.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.root.UnitPreferences.Item('Time').SetCurrentUnit('Min');
            accessDuration = accessDP.DataSets.GetDataSetByName('Duration').GetValues;
            
            % If maneuvers are computed later, access_start_time will be
            % overwritten and changed. However, it can be interesting to
            % still have the initial state to compare it later how times 
            % have changed due to maneuvers
            app.access_start_time_init = app.access_start_time;
            app.access_stop_time_init = app.access_stop_time;

            % Visualize access data in Table: AccessTableInfo_1
            access_id = 1:length(accessDuration);   % Access numbering
            app.AccessTableInfo_1.Data = table(access_id',app.access_start_time_init,app.access_stop_time_init,accessDuration);%,maxElevation);

            %% Perigee Passes Report and visualize data in ManeuverInputTable, PerigeePassDateTableInfo
            disp('Getting pergiee passes')
            app.ConsoleLog("Getting perigee passes");

            % Get Perigee pass data
            peri_pass_DP = app.CLIMB.DataProviders.Item('Passes').Exec(app.scenario.StartTime,app.scenario.StopTime);
            app.peri_pass_time = datetime(peri_pass_DP.DataSets.GetDataSetByName('Time of Perigee').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            
            % If maneuvers are computed later, peri_pass_time will be
            % overwritten and changed. However, it can be interesting to
            % still have the initial state to compare it later how the times 
            % have changed due to maneuvers
            app.peri_pass_time_init = app.peri_pass_time;

            % Note: Somehow STK often also reports perigee pass after or before
            % scenario end or start time (dont know why) which results in NaT value for the last
            % or first Perigee pass date in the app.peri_pass_time_1 vector.
            % Solution -> idx = isnat(app.peri_pass_time_1) -> gives NaT indexes
            idx = isnat(app.peri_pass_time_init); % get index of NaT values
            app.peri_pass_time_init(idx) = [];    % Delete NaT values

            % Create table for maneuvers selection with checkbox and
            checkboxColumn2 = true(height(app.peri_pass_time_init), 1); % create logical array for checkbox
       
            % Visualize data in Tables: ManeuverInputTable, PerigeePassDateTableInfo
            app.ManeuverInputTable.Data = table([1:length(app.peri_pass_time_init)]',app.peri_pass_time_init,checkboxColumn2);         % Table for maneuver input
            app.PerigeePassDateTableInfo_1.Data = table([1:length(app.peri_pass_time_init)]',app.peri_pass_time_init);   % Table for perigee pass times
            
            %% Schedule Plot 
            % Creates the schedule plot of all activities
            SchedulePlot(app,app.SchedulePlot_1);

        end

        % Button pushed function: PropagateManeuversButton
        function PropagateManeuversButtonPushed(app, event)
         
%--------------------------------------------------------------------------------
%----------------------------- Second Propagation Button Pushed -----------------
%--------------------------------------------------------------------------------
            %% Delete old values
            app.maneuver_start_time = [];
            app.maneuver_stop_time = [];
            app.access_start_time = [];
            app.access_stop_time = [];
            app.peri_pass_time = [];
            
            app.ManeuverOutputTable.Data = [];
            app.ManOutputGlobalTable.Data = [];
            app.AccessTableInfo_2.Data = [];
            
            %% User Input
            thrust_duration = app.ManeuverDurationinminEditField.Value;
            thrust = app.ThrustNEditField.Value;
            Isp = app.IspsEditField.Value;
            thruster_efficiency = app.ThrusterEfficiencyEditField.Value; 

            %% Get Scencario and Objects
            % scenario = app.root.CurrentScenario;
            % FHWN = app.scenario.Children.Item('FHWN');
            % Sensor = FHWN.Children.Item('Sensor');

            %% Set Egnine from Component Browser        
            engine = app.scenario.ComponentDirectory.GetComponents('eComponentAstrogator').GetFolder('Engine Models'); % Get the Engine Models Folder
            engine_model = engine.Item('Enpulsion');
            engine_model.Thrust = thrust; % Thrust in N
            engine_model.Isp = Isp;       % Isp in s
            
            %% Get Propagator Astrogator
            % Astrogator Propagator (from code snipets:
            % https://help.agi.com/stkdevkit/11.4.0/Content/stkObjects/ObjModMatlabCodeSamples.htm) 
            % No need to set astrogator again since it is already set in first propagation
            astrogator = app.CLIMB.Propagator;      % get astrogator property
            astrogator.MainSequence.RemoveAll();    % Clear all segments from the MCS
     
            %% Create Sequence
            %---------------------Insert initial state---------------------
            % Run setInitState function which sets the initial state
            % Inserts the following segments:
            %   - Initial State Segment: TLE as initial state
            %   - Propagator Segment: Propagates from TLE epoch to scenario start time set in GUI by the user
            setInitState(app,app.scenario_start_time)
 
            %   Manuever Input selection table
            %-------------------------------------------------------------------------------------------
        
            % Manuever Selection
            % x = x(x{:, 2} ~= 0, :); delete rows where x(:,2)==0 ("~" not % operator)
            maneuver_table_input = app.ManeuverInputTable.Data; % table with manuever data imput
            app.maneuver_select_logic = maneuver_table_input{:,3}; % maneuver selection (1 or 0)
            maneuver_table_input = maneuver_table_input(maneuver_table_input{:,3} ~=0,:); % delete unselected maneuvers

            maneuver_date = maneuver_table_input{:,2};  % selected maneuver dates
            %maneuver_start_date = datetime(maneuver_date)-minutes(thrust_duration/2); % maneuver start date (perigee pass date - maneuver duration/2)
            %maneuver_start_date = char(maneuver_start_date);    % Convert to char for STK input -> te get single elements maneuver_start_date(i,:)

            maneuvers_count = length(maneuver_date(:,1));
            maneuver_id = 1:maneuvers_count;

            % Loop which creates a propagaor segment and afterwards manuever segment. The propagator goes until
            % perigee_date-man_duration/2 
            %
            
            %------------------------------------------------------------------------------------------- 
            for i=1:length(app.maneuver_select_logic)
                %disp(i)
                %%----------------------------Insert Propagator-----------------------------
                
                astro_propagator_segment = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propagate_2','-');
                astro_propagator_segment.PropagatorName = 'Earth HPOP Default v10';
                astro_propagator_segment.Properties.Color = 65280;     % green
                propagator1_stopcond = astro_propagator_segment.StoppingConditions.Add('Periapsis');
                propagator1_stopcond.Properties.RepeatCount = 1; % amount of circulation before proceeding
                %propagator1_stopcond.Properties.Trip = maneuver_start_date(i,:);
                astro_propagator_segment.StoppingConditions.Remove('Duration');
            
                %%----------------------------Insert Maneuver------------------------------
   
                % Only do maneuver if selected in table, else skip it
                if app.maneuver_select_logic(i)==1
                    % Add Maneuver Segment
                    % astro_maneuver_segment = astrogator.MainSequence.Insert('eVASegmentTypeManeuver','Maneuver','-');  % Default maneuver
                    astro_maneuver_segment = astrogator.MainSequence.InsertByName('ManeuverClimbV1','-');  % Maneuver from component browser in STK
                    astro_maneuver_segment.Properties.Color = 255;    % color red
                    astro_maneuver_segment.Maneuver.ThrustEfficiency = thruster_efficiency;

                    % Maneuver propagator
                    prop_manu = astro_maneuver_segment.Maneuver.Propagator;
                    stopcond = prop_manu.StoppingConditions.Add('Duration');         % in min
                    stopcond.Properties.Trip = thrust_duration;                 % in min
                    prop_manu.StoppingConditions.Remove('Duration');
                end


                % % % Only do maneuver if selected in table, else skip it
                % if app.apo_maneuver_select_logic(i)==1
                %     %%----------------------------Insert Propagator-----------------------------
                % 
                %     astro_prop_apo = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propagate_21','-');
                %     astro_prop_apo.PropagatorName = 'Earth HPOP Default v10';
                %     astro_prop_apo.Properties.Color = 65280;     % green
                %     astro_prop_apo.StoppingConditions.Add('Apoapsis');
                %     astro_prop_apo.Properties.RepeatCount = 1; % amount of circulation before proceeding
                %     astro_prop_apo.StoppingConditions.Remove('Duration');
                % 
                %     %%----------------------------Insert Maneuver------------------------------
                % 
                %     % Add Maneuver Segment
                %     astro_maneuver_segment = astrogator.MainSequence.Insert('eVASegmentTypeManeuver','Maneuver','-');  % Default maneuver
                %     astro_man_peri = astrogator.MainSequence.InsertByName('ManeuverClimbV12','-');  % Maneuver from component browser in STK
                %     astro_man_peri.Maneuver.SetAttitudeControlType('eVAAttitudeControlAntiVelocityVector'); % Set thrust vector to anti velocity direction
                %     astro_man_peri.Properties.Color = 16777215;    % color magenta
                %     astro_man_peri.Maneuver.ThrustEfficiency = thruster_efficiency;
                % 
                %     % Maneuver propagator
                %     stopcond = astro_man_peri.Maneuver.Propagator.StoppingConditions.Add('Duration');         % in min
                %     stopcond.Properties.Trip = thrust_duration;                 % in min
                %     astro_man_peri.Maneuver.Propagator.StoppingConditions.Remove('Duration');
                % end

            end
            %%----------------------------Insert End Propagator-----------------------
            % Insert last final propagator which propagates until scenario stop time
            % Without that, propagation would stop after last maneuver PROBLEM: Somehow if using the stopping condition "Epoch"
            % after a porpagator with stopping condition "Periapsis" the propagation is arounf 6 months long, despite havinf the right
            % stop date inserted. Therefore curretly stopping cond "duration" is used
            astro_propagator_segment = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propagate_3','-');
            astro_propagator_segment.PropagatorName = 'Earth HPOP Default v10';
            astro_propagator_segment.Properties.Color = 65280;     % green
            % propagator1_stopcond = astro_propagator_segment.StoppingConditions.Add('Epoch');
            % propagator1_stopcond.Properties.Trip = app.scenario_stop_time;
            propagator1_stopcond = astro_propagator_segment.StoppingConditions.Add('Duration');
            propagator1_stopcond.Properties.Trip = 120; %minutes
            astro_propagator_segment.StoppingConditions.Remove('Duration');
            
            %astro_propagator_segment = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propage_1','-');
            %%----------------------------Run Astrogator-----------------------------
            astrogator.RunMCS; % This command runs the astrogator elements set above


            %% Access Report and Visualize in AccessTableInfo_2

            disp('Getting access windows')
            app.ConsoleLog("Getting access windows");

            % Data provider
            % Access computed to FHWN (access diretly above horizon)
            % Access computed to Sensor (access x deg above horizon, x -> cone half angle adjusted in stk scenario)
            access = app.FHWN.GetAccessToObject(app.CLIMB); % Set access objects
            access.ComputeAccess;                           % Compute access
            accessDP = access.DataProviders.Item('Access Data').Exec(app.scenario.StartTime,app.scenario.StopTime); % Get report item and set report timeframe

            % Get access start/stop time and duration in min
            % Start/Stop Time
            app.access_start_time = datetime(accessDP.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.access_stop_time = datetime(accessDP.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');

            % % Duration
            % app.root.UnitPreferences.Item('Time').SetCurrentUnit('Min');
            % accessDuration = accessDP.DataSets.GetDataSetByName('Duration').GetValues;
            % 
            % % ---Create Access Table: AccessTableInfo_2 ---
            % % ColumnName = {'Access Start','Access End','Access Duration','Access Select','Access Type'};
            % checkbox_acc_sel = false(length(app.access_start_time_2), 1);     % create logical array for checkbox column
            % access_type(1:length(app.access_start_time_2)) = {'S-Band'};        % Create string array for inital drop down menu value        
            % 
            % % For drop down menu, the Data cant be in table fomrat because it would ignore ColumnFormat (dont know why)
            % app.AccessTableInfo_2.ColumnFormat = {[] [] [] [] {'UHF' 'S-Band'}};    % set format for columns, {} creates drop down menu
            % app.AccessTableInfo_2.Data = [cellstr(app.access_start_time_2), cellstr(app.access_stop_time_2), accessDuration, num2cell(checkbox_acc_sel),access_type'];  

            % 
            % Notes for debugging
            % app.accessStartTimes_2      %4×1 datetime array
            % app.accessStopTimes_2       %4×1 datetime array
            % accessDuration              %4×1 cell array {[3.4296]}
            % checkbox_acc_sel            %4×1 logical array YES
            % access_type'                %4×1 cell array {'UHF'}
            % 
            % cellstr(app.accessStartTimes_2)     %4×1 cell array
            % cellstr(app.accessStopTimes_2)      %4×1 cell array
            % num2cell(accessDuration)            %4×1 cell array {1×1 cell}
            % num2cell(checkbox_acc_sel)          %4×1 cell array
            % myData = access_type'               %4×1 cell array %YES
         
            
            %% Perigee Pass Report and Visualize data in Table PerigeePassDateTableInfo_2
            disp('Getting pergiee passes')
            app.ConsoleLog("Getting perigee passes");
            
            % Get Perigee Pass Data
            peri_pass_DP = app.CLIMB.DataProviders.Item('Passes').Exec(app.scenario_start_time,app.scenario_stop_time);
            app.peri_pass_time = datetime(peri_pass_DP.DataSets.GetDataSetByName('Time of Perigee').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            
            % Note: Somehow STK often also reports perigee pass after or before
            % scenario end or start time (dont know why) which results in NaT value for the last
            % or first Perigee pass date in the app.peri_pass_time_1 vector.
            % Solution -> idx = isnat(app.peri_pass_time_1) -> gives NaT indexes
            idx = isnat(app.peri_pass_time); % get index of NaT values
            app.peri_pass_time(idx) = [];    % Delete NaT values

            % Visualize data in Table: PerigeePassDateTableInfo_2
            app.PerigeePassDateTableInfo_2.Data = table([1:length(app.peri_pass_time)]',app.peri_pass_time);
            
            %% Maneuver Data Report and Visualiztion in Tables: ManeuverOutputTable, ManOutputGlobalTable
            
            maneuver_output_report = app.CLIMB.DataProviders.Item('Maneuver Summary').Exec(app.scenario_start_time,app.scenario_stop_time);
            try
            app.maneuver_start_time = datetime(maneuver_output_report.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.maneuver_stop_time = datetime(maneuver_output_report.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            maneuver_duration = maneuver_output_report.DataSets.GetDataSetByName('Duration').GetValues;
            maneuver_dv = maneuver_output_report.DataSets.GetDataSetByName('Delta V').GetValues;
            
            % From Code Snipptes
            % Code Snipptes = app.CLIMB.DataProviders.Item('Passes').Exec(app.Start_Time,app.End_Time);
            % app.per_pass_time=datetime(passes_res.DataSets.GetDataSetByName('Time of Perigee').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % satPosDP = satellite.DataProviders.Item('Cartesian Position').Group.Item('ICRF').Exec(scenario.StartTime,scenario.StopTime,60);
            % satx = cell2mat(satPosDP.DataSets.GetDataSetByName('x').GetValues);
        
            % Data Visualization in Table: ManeuverOutputTable 
            app.ManeuverOutputTable.Data=table(maneuver_id',app.maneuver_start_time,app.maneuver_stop_time,maneuver_duration,maneuver_dv);
             
            % Data Visualization in Table: ManOutputGlobalTable 
            varname = {'Total Number Maneuvers','Total Delta-V (m/s)','Total Thrust Time (min)'};
            total_dv = sum(cell2mat(maneuver_dv));
            total_thrust_time = sum(cell2mat(maneuver_duration));
            app.ManOutputGlobalTable.Data = table(varname',[string(maneuvers_count), total_dv, total_thrust_time]');    % maneuvers_count to get rid of decimals (int32() didnt work)
            
            end


            %% Schedule Plot 
            % Creates the schedule plot of all activities
            SchedulePlot(app,app.SchedulePlot_1);
        end

        % Button pushed function: GetAccessDataButton
        function GetAccessDataButtonPushed(app, event)
            %% Description
            % Computes access from FHWN to Satellite and writes output in Table: AccessTableInfo_2
            % Output:
            % - Start/Stop time and Duration
            % - Elevation, Azimuth
            % - maxElevastion, azimuth_asce, azimuth_desc
            
            %% Delete old parameters
            app.access_start_time = [];
            app.access_stop_time = [];
            app.AccessTableInfo_2.Data = [];

            %% Set min Elevation angle for FHWN
            minElConstraint = app.MinimumElevationindegEditField.Value; % User Input


            %% Compute Access
            access = app.FHWN.GetAccessToObject(app.CLIMB); % Set access objects
            access.ComputeAccess;                           % Compute access

            %% Get Start/Sop time and Duration
            % Get report item and set report timeframe
            accessDP_1 = access.DataProviders.Item('Access Data').Exec(app.scenario.StartTime,app.scenario.StopTime); 
            
            % Start/Stop Time and Duration
            app.access_start_time = datetime(accessDP_1.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.access_stop_time = datetime(accessDP_1.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');  
            app.root.UnitPreferences.Item('Time').SetCurrentUnit('Min');
            accessDuration = accessDP_1.DataSets.GetDataSetByName('Duration').GetValues;
            
            %% Get Azimuth, Elevation Data
            % When calling elevation values, only the values from the first pass will be stored. To get the values for every pass, a for-loop is used
            % For every pass maxEl, azimuth_asce and azimuth_desc is
            % calculated and savwd, the elevation and azimuth vaules are
            % then overwritten again

            maxElevastion = zeros(length(app.access_start_time), 1);
            azimuth_asce = zeros(length(app.access_start_time), 1);
            azimuth_desc = zeros(length(app.access_start_time), 1);
            
            % Get Azimuth and Elevation data
            % Iterate through the single satellite passes and extract
            % maxEl, azimuth_asce and azimuth_des from each pass
            for i=1:length(app.access_start_time)
                
                % Get AER data for pass time
                accessDP_2 = access.DataProviders.Item('AER Data').Group.Item('Default').Exec(string(app.access_start_time(i)),string(app.access_stop_time(i)),60);
                
                %time = accessDP_2.DataSets.GetDataSetByName('Time').GetValues;
                elevation = accessDP_2.DataSets.GetDataSetByName('Elevation').GetValues;
                azimuth = accessDP_2.DataSets.GetDataSetByName('Azimuth').GetValues;

                maxElevastion(i) = max(cell2mat(elevation));
                azimuth_asce(i) = cell2mat(azimuth(1));
                azimuth_desc(i) = cell2mat(azimuth(end));

            end
            
            %% Create Access Table: AccessTableInfo_2
            % ColumnName = {'Access Start','Access End','Access Duration','Access Select','Access Type'};
            checkbox_acc_sel = false(length(app.access_start_time), 1);     % create logical array for checkbox column
            access_type(1:length(app.access_start_time)) = {'S-Band'};        % Create string array for inital drop down menu value        
            
            % disp(num2cell([1:length(app.access_start_time_2)]'))
            % disp(cellstr(app.access_start_time_2))
            % disp(cellstr(app.access_stop_time_2))
            % disp(accessDuration)
            % disp(num2cell(maxElevastion))
            % disp(num2cell(azimuth_asce))
            % disp(num2cell(azimuth_desc))
            % disp(num2cell(checkbox_acc_sel))
            % disp(access_type')

            % For drop down menu, the Data cant be in table fomrat because it would ignore ColumnFormat (dont know why)
            app.AccessTableInfo_2.ColumnFormat = {[] [] [] [] [] [] [] [] {'UHF' 'S-Band'}};    % set format for columns, {} creates drop down menu
            app.AccessTableInfo_2.Data = [num2cell([1:length(app.access_start_time)]'),cellstr(app.access_start_time), cellstr(app.access_stop_time), accessDuration,num2cell(maxElevastion),num2cell(azimuth_asce),num2cell(azimuth_desc),num2cell(checkbox_acc_sel),access_type'];  

        end

        % Button pushed function: AddActivityButton
        function AddActivityButtonPushed(app, event)
            %% Description

            %%
            % Add Custom Activities, add to a table and plot in graph
            activityName = {app.ActivityNameEditField.Value}; % input from activity name text field and convert to cell with {}
            app.activityStartTime_custom = datetime(app.ActivityStartTimeEditField.Value,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.activityStopTime_custom = datetime(app.ActivityStopTimeEditField.Value,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            activityPower = app.PowerWEditField.Value;
            
            activityDuration = app.activityStopTime_custom-app.activityStartTime_custom;
            numRows = size(app.ActivityOutputTable.Data, 1); % Get the current number of rows

            % Create table with activity data and insert in new row in Table: ActivityOutputTable
            % Without numRows+1, it would overwrite the current activity row in table every time the "add activity" button is pushed
            newRow = table(numRows+1,activityName,app.activityStartTime_custom,app.activityStopTime_custom,activityDuration,activityPower);
            app.ActivityOutputTable.Data = [app.ActivityOutputTable.Data; newRow];


            %% Schedule Plot 
            % Creates the schedule plot of all activities
            SchedulePlot(app,app.SchedulePlot_1);
        end

        % Button pushed function: AddAttitudeProfileButton
        function AddAttitudeProfileButtonPushed(app, event)
            %% Description
            % To insert attitude profile to STK see link below. It uses the root.ExecuteCommand() function with which every 
            % single attitude pointing mode or slew maneuver can be commanded to stk.
            % To insert e.g. a sun pointing segment starting at "06-Aug-2023 21:06:18", you do:
            % root.ExecuteCommand('AddAttitude */Satellite/CLIMB Profile SunPointing "06-Aug-2023 21:06:18" AlignConstrain Axis 1 1 0 "Sun" Axis 0 0 -1 "Velocity"')
            % -> (AddAttitude <ObjectPath> Profile [<ProfileName>] {StartTime} {ProfileType} <Parameters>)
            % For adding attitude profile to STK see: https://help.agi.com/stkdevkit/11.4.0/#../Subsystems/connectCmds/Content/cmd_AddAttitudeProfile.htm?Highlight=AddAttitude
            % And for different attitude modes: https://help.agi.com/stk/index.htm#attitude/attitudeBasicTypes.htm
            
            % Four attitude maneuvers are defined within this tool
            % - Thrust pointing
            % - Target pointing (target = GS FHWN)
            % - Sun pointing
            % - Attitude change maneuver (this is split again into: "Slew_sun_thrust","Slew_thrust_sun","Slew_sun_target","Slew_target_sun"

            % To insert the attitude profiles, for every of the above
            % mentioned maneuvers, the following paramers are needed
            % - Profile Name (e.g. Sun_Pointing)
            % - Start Date (e.g. 06-Aug-2023 21:06:18)
            % - Attitude maneuver type (e.g. AlignConstrain)
            % - Parameters realated to the type (different for every type, for AlignConstrain e.g. Axis 1 1 0 "Sun" Axis 0 0 -1 "Velocity")
            
            %% Extract S-Band and UHF access start/stop times from access table (AccessTableInfo_2)
            % This is needed to insert attitude changes for target pointing
         
            % Only s_band is used for pointing
            access_table_2 = app.AccessTableInfo_2.Data; % get access table
            index_sband = find(cell2mat(access_table_2(:,8))==1 & string(access_table_2(:,9))=="S-Band");   % find rows of of table where checkbox=true and s-band is selected
            index_uhf = find(cell2mat(access_table_2(:,8))==1 & string(access_table_2(:,9))=="UHF");       % find rows of of table where checkbox=true and uhf is selected
           
            % S-Band Start/stop times
            app.access_start_time_sband = app.access_start_time(index_sband);
            app.access_stop_time_sband = app.access_stop_time(index_sband);
           
            % UHF Start/stop times (not needed for attitude)
            app.access_start_time_uhf = app.access_start_time(index_uhf);
            app.access_stop_time_uhf = app.access_stop_time(index_uhf);
 
            %% Get input values from GUI
            pointchange_duration = minutes(app.DurationminutesEditField.Value);     % Time for pointing change maneuver
            leadtime_thrustpoint = minutes(app.LeadTimeminutesEditField.Value);     % Thrust pointing start time before thrust manuever starts
            trailtime_thrustpoint = minutes(app.TrailTimeminutesEditField.Value);   % Thrust pointing end time after thrust manuever ends
            leadtime_targetpoint = minutes(app.LeadTimeminutesEditField_2.Value);
            trailtime_targetpoint = minutes(app.TrailTimeminutesEditField_2.Value);

            %% Attitude Profile Names
            sunpointing_name = "Sun_Pointing";
            thrustpointing_name = "Thrust_Pointing";
            targetpointing_name = "FHWN_Pointing";
            app.pointchange_name = ["Slew_sun_thrust","Slew_thrust_sun","Slew_sun_target","Slew_target_sun"];
            
            %% Define attiude manuever start/stop times

            % Thrust pointing start and stop times
            app.thrustpointing_start_time = app.maneuver_start_time-leadtime_thrustpoint;
            app.thrustpointing_stop_time = app.maneuver_stop_time+trailtime_thrustpoint;

            % Target pointing start and stop times
            app.targetpointing_start_time =  app.access_start_time_sband-leadtime_targetpoint;
            app.targetpointing_stop_time = app.access_stop_time_sband+trailtime_targetpoint;

            % To prevent errors for the attitude manuevers if e.g. no thrust 
            % maneuvers are scheduled but s-band contacts are, a swicht is used 
            % to differentiat between four cases:
            % - case 1: thurst maneuvers and s-band contacts are scheduled (thrust pointing and target pointing needed) 
            % - case 2: No thurst maneuvers but s-band contacts are scheduled (no thrust pointing but target pointing needed) 
            % - case 3: thurst maneuvers but no s-band contacts are scheduled (thrust pointing but no target pointing needed) 
            % - case 4: No thurst maneuvers and no s-band contacts are scheduled (no thrust pointing and no target pointing needed 
            %           -> only sun pointing and no pointing changes)
            
            switch true
                case (~isempty(app.maneuver_start_time) & ~isempty(app.access_start_time_sband))
                    
                    % Sun pointing start times (stop time defined below in section STK attitude report)
                    % Sun point start date = [At beginning of scenario; after thrust + slew duration; after acess + slew duration]
                    % Initial attitude of stk scenario is already sun pointing therefore no need to define initial attitude here
                    app.sunpointing_start_time = [app.thrustpointing_stop_time+pointchange_duration;... 
                                                  app.targetpointing_stop_time+pointchange_duration];

                    % Pointing change maneuvers start and stop times 
                    % Pointing change start date = [before thrust; after thrust; before access; after access]
                    app.pointchange_start_time = [app.thrustpointing_start_time-pointchange_duration;...
                                           app.thrustpointing_stop_time;...
                                           app.targetpointing_start_time-pointchange_duration;...
                                           app.targetpointing_stop_time];
                    
                    % Define names for different slews (not the most elegant solution, could be improved)
                    % Slew type = [Slew_sun_thrust; Slew_thrust_sun; Slew_sun_target; Slew_target_sun]
                    slew_1(1:length(app.thrustpointing_start_time)) = app.pointchange_name(1);
                    slew_2(1:length(app.thrustpointing_start_time)) = app.pointchange_name(2);
                    slew_3(1:length(app.targetpointing_start_time)) = app.pointchange_name(3);
                    slew_4(1:length(app.targetpointing_stop_time)) = app.pointchange_name(4);
                    slew_name_list = [slew_1 slew_2 slew_3 slew_4];

                case (isempty(app.maneuver_start_time) & ~isempty(app.access_start_time_sband))

                    app.sunpointing_start_time = app.targetpointing_stop_time+pointchange_duration;
                    app.pointchange_start_time = [app.targetpointing_start_time-pointchange_duration;... 
                                                  app.targetpointing_stop_time];
                    
                    slew_3(1:length(app.targetpointing_start_time)) = app.pointchange_name(3);
                    slew_4(1:length(app.targetpointing_stop_time)) = app.pointchange_name(4);
                    slew_name_list = [slew_3 slew_4];

                case (~isempty(app.maneuver_start_time) & isempty(app.access_start_time_sband))

                    app.sunpointing_start_time = app.thrustpointing_stop_time+pointchange_duration;
                    app.pointchange_start_time = [app.thrustpointing_start_time-pointchange_duration;...
                                                  app.thrustpointing_stop_time];

                    slew_1(1:length(app.thrustpointing_start_time)) = app.pointchange_name(1);
                    slew_2(1:length(app.thrustpointing_start_time)) = app.pointchange_name(2);
                    slew_name_list = [slew_1 slew_2];

                case (isempty(app.maneuver_start_time) & isempty(app.access_start_time_sband))
                    
                    % Set attitude to sun pointing only
                    app.sunpointing_start_time = datetime(app.scenario_start_time, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
                    app.pointchange_start_time = []; 
                    slew_name_list = [];
                    
            end

            %% Add attitude profile to STK via a command
            % Pre allocate arrays for attitude profile stk commands
            thrustpointing_command = strings([length(app.thrustpointing_start_time),1]);
            targetpointing_command = strings([length(app.targetpointing_start_time),1]);
            sunpointing_command = strings([length(app.sunpointing_start_time),1]);
            pointchange_command = strings([length(app.pointchange_start_time),1]);
            
            % Here the attitude profiles are added to STK using the
            % root.ExecuteCommand() function. Every iteration of a loop adds one profile to stk
            % First the command string created with e.g. thrustpointing_command and then commanded to stk
            % "try" is used to skio errors if e.g. no thrust pointing is scheduled

            % Thrust Pointing
            try
                for i=1:height(app.thrustpointing_start_time)
                    thrustpointing_command(i) = append('AddAttitude */Satellite/CLIMB Profile ',thrustpointing_name,' "',string(app.thrustpointing_start_time(i)),'" AlignConstrain Axis 0 0 -1 "Velocity" Axis 1 1 0 "Sun"');
                    % example: thrustpointing_command = 'AddAttitude */Satellite/CLIMB Profile Thrust_Pointing "06-Aug-2023 21:06:18" AlignConstrain Axis 0 0 -1 "Velocity" Axis 1 1 0 "Sun"';
                    app.root.ExecuteCommand(thrustpointing_command(i));
                end
            end

            % Target Pointing
            try
                for i=1:height(app.targetpointing_start_time)
                    targetpointing_command(i) = append('AddAttitude */Satellite/CLIMB Profile ',targetpointing_name,' "',string(app.targetpointing_start_time(i)),'" AlignConstrain Axis 0 1 0 "FHWN" Axis 0 0 -1 "Velocity"');
                    % example: targetpointing_command = 'AddAttitude */Satellite/CLIMB Profile Target_Pointing "06-Aug-2023 21:06:18" AlignConstrain Axis 0 1 0 "FHWN" Axis 0 0 -1 "Velocity"';
                    app.root.ExecuteCommand(targetpointing_command(i))
                end
            end

            % Sun Pointing
            try
                for i=1:height(app.sunpointing_start_time)
                    sunpointing_command(i) = append('AddAttitude */Satellite/CLIMB Profile ',sunpointing_name,' "',string(app.sunpointing_start_time(i)),'" AlignConstrain Axis 1 1 0 "Sun" Axis 0 0 -1 "Velocity"');
                    % example: sunpointing_command = 'AddAttitude */Satellite/CLIMB Profile Sun_Pointing "06-Aug-2023 21:06:18" AlignConstrain Axis 1 1 0 "Sun" Axis 0 0 -1 "Velocity"';
                    app.root.ExecuteCommand(sunpointing_command(i));
                end
            end

            % Pointing Change Manuever
            try
                for i=1:height(app.pointchange_start_time)
                    pointchange_command(i) = append('AddAttitude */Satellite/CLIMB Profile ',slew_name_list(i),' "',string(app.pointchange_start_time(i)),'" FixedTimeSlew Smooth On');
                    % example: slew_command = 'AddAttitude */Satellite/CLIMB Profile Slew "06-Aug-2023 21:06:18" FixedTimeSlew Smooth On';
                    app.root.ExecuteCommand(pointchange_command(i)); % maybe use char()
                end
            end
                        
            %% Attitude Data Report and Visualiztion in Tables: AttitudeOutputTable, AttOutputGlobalTable

            % Get attitude data from STK
            attitudeDP = app.CLIMB.DataProviders.Item('Attitude Segment Schedule').Exec(app.scenario_start_time,app.scenario_stop_time);
            app.attitude_start_time = datetime(attitudeDP.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.attitude_stop_time = datetime(attitudeDP.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            attitude_name = attitudeDP.DataSets.GetDataSetByName('Name').GetValues;
            attitude_type = attitudeDP.DataSets.GetDataSetByName('Description').GetValues;
            attitude_duration = app.attitude_stop_time-app.attitude_start_time;
            attitude_id = 1:length(app.attitude_start_time); % Number attitude profiles
            
            % Datetime array with [start time, stop time] for different type of attitude proflie
            % Used for global attitude statistics and for schedule graph.
            % This data is directly extracted from STK -> better than using the start and stop dates defined at beginning of chapter
            app.sunpointing_startstop_time = [app.attitude_start_time(attitude_name==sunpointing_name) app.attitude_stop_time(attitude_name==sunpointing_name)];
            app.thrustpointing_startstop_time = [app.attitude_start_time(attitude_name==thrustpointing_name) app.attitude_stop_time(attitude_name==thrustpointing_name)];
            app.targetpointing_startstop_time = [app.attitude_start_time(attitude_name==targetpointing_name) app.attitude_stop_time(attitude_name==targetpointing_name)];
            
            % Totel time of attitude profiles
            sunpointing_total_time = sum(app.sunpointing_startstop_time(:,2)-app.sunpointing_startstop_time(:,1));
            thrustpointing_total_time = sum(app.thrustpointing_startstop_time(:,2)-app.thrustpointing_startstop_time(:,1));
            targetpointing_total_time = sum(app.targetpointing_startstop_time(:,2)-app.targetpointing_startstop_time(:,1));

            %  Get slew start stop times for all slew maneuvers and seperately for slew maneuver types
            app.slew_startstop_time = [app.attitude_start_time(attitude_type=="Fixed Time Slew") app.attitude_stop_time(attitude_type=="Fixed Time Slew")];    % start and stop times for all slew maneuvers
            app.slew_startstop_s2th_time = [app.attitude_start_time(attitude_name==app.pointchange_name(1)) app.attitude_stop_time(attitude_name==app.pointchange_name(1))]; % start and stop times for slew sun to thrust pointing
            app.slew_startstop_th2s_time = [app.attitude_start_time(attitude_name==app.pointchange_name(2)) app.attitude_stop_time(attitude_name==app.pointchange_name(2))]; % start and stop times for slew thrust to sun pointing
            app.slew_startstop_s2ta_time = [app.attitude_start_time(attitude_name==app.pointchange_name(3)) app.attitude_stop_time(attitude_name==app.pointchange_name(3))]; % start and stop times for slew sun to target pointing
            app.slew_startstop_ta2s_time = [app.attitude_start_time(attitude_name==app.pointchange_name(4)) app.attitude_stop_time(attitude_name==app.pointchange_name(4))]; % start and stop times for slew target to sun pointing

            % Insert data to table AttitudeOutputTable
            app.AttitudeOutputTable.Data = table(attitude_id',attitude_name,app.attitude_start_time,app.attitude_stop_time,attitude_duration,attitude_type);

            % Data Visualization in Table: ManOutputGlobalTable 
            varname = {'Total Number Attitude Profiles','Number Slew Maneuvers','Total Time Sun Pointing','Total Time Thrust Pointing','Total Time Target (FHWN) Pointing'};
            app.AttitudeGlobalOutputTable.Data = table(varname', [num2cell(length(attitude_name)),num2cell(height(app.slew_startstop_time)),{sunpointing_total_time},{thrustpointing_total_time},{targetpointing_total_time}]');
            

            %% Schedule Plot 
            % Creates the schedule plot of all activities
            SchedulePlot(app,app.SchedulePlot_1);
           
        end

        % Button pushed function: ClearAttitudeButton
        function ClearAttitudeButtonPushed(app, event)
            % Clears all attiude profiles except the first one (sun pointing)
            
            app.attitude_start_time = [];
            app.sunpointing_startstop_time = [];
            app.thrustpointing_startstop_time = [];
            app.targetpointing_startstop_time = [];
            app.slew_startstop_time = [];

            app.root.ExecuteCommand('SetAttitude */Satellite/CLIMB ClearData AllProfiles');
            app.AttitudeOutputTable.Data = []; % clear attitude table
            app.AttitudeGlobalOutputTable.Data = []; % clear global attitude table


        end

        % Button pushed function: CreateActivityScheduleButton
        function CreateActivityScheduleButtonPushed(app, event)
            
            % SoE Table Output
            % Creates a list of all activities (manuevers, access times,
            % custom activites) and sorts it chronoligical with start_time
            % Every activity table needs same variable/column names -> VariableNames = ["Name","Start Time","Stop Time","Duration"];
            
            % ----manuevers----
            % try -> skips part if error occurs -> skips if e.g. no maneuvers planned
            try
            maneuver_id = 1:length(app.maneuver_start_time);
            manuever_name = append(string(maneuver_id'),"_Maneuver");    % Append maneuver number and "manuever" -> 1_maneuver, 2_...
            manuever_duration = app.maneuver_stop_time-app.maneuver_start_time;
            Maneuvers = table(manuever_name, app.maneuver_start_time, app.maneuver_stop_time,manuever_duration);
            Maneuvers.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
            end
            
            % ----Access Times----
            try
            % Selected access
            access_table_2 = app.AccessTableInfo_2.Data;
            access_table_2 = access_table_2(cell2mat(access_table_2(:,8)) ~= 0, :); % delete rows where x(:,2)==0 ("~" not operator)
            
            access_start = datetime(access_table_2(:,2));
            access_stop = datetime(access_table_2(:,3));
            access_duration = access_stop - access_start;
            access_mode = string(access_table_2(:,9));% string with "UHF" or "S-Band"
            access_id = 1:length(access_start);
            access_name = append(string(access_id'),"_Access_",access_mode); % Append access number, "access" and mode (UHF or S-Band) for name in SoE
            
            Access = table(access_name,access_start,access_stop,access_duration);
            Access.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
            end
            
            % ----Custom Activities----
            try
            Activities = app.ActivityOutputTable.Data;
            Activities(:,'Var1') = [];   % Remove Access ID
            Activities(:,'Var6') = [];   % Remove Power column
            Activities.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
            end
            
            % ----Attitude Times----
            % For noe, only attitude change maneuvers are treated as an
            % activity but not ponting itself
            try
            % Slew Sun to Thrust Pointing
            slew_id = 1:length(app.slew_startstop_s2th_time);
            slew_name_append = append(string(slew_id'),"_",app.pointchange_name(1));
            slew_table_s2th = table(slew_name_append,app.slew_startstop_s2th_time(:,1),app.slew_startstop_s2th_time(:,2),app.slew_startstop_s2th_time(:,2)-app.slew_startstop_s2th_time(:,1));
            slew_table_s2th.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
            end
            try
            % Slew Thrust to Sun Pointing
            slew_id = 1:length(app.slew_startstop_th2s_time);
            slew_name_append = append(string(slew_id'),"_",app.pointchange_name(2));
            slew_table_th2s = table(slew_name_append,app.slew_startstop_th2s_time(:,1),app.slew_startstop_th2s_time(:,2),app.slew_startstop_th2s_time(:,2)-app.slew_startstop_th2s_time(:,1));
            slew_table_th2s.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
            end
            try
            % Slew Sun to Target Pointing
            slew_id = 1:height(app.slew_startstop_s2ta_time);
            slew_name_append = append(string(slew_id'),"_",app.pointchange_name(3));
            slew_table_s2ta = table(slew_name_append,app.slew_startstop_s2ta_time(:,1),app.slew_startstop_s2ta_time(:,2),app.slew_startstop_s2ta_time(:,2)-app.slew_startstop_s2ta_time(:,1));
            slew_table_s2ta.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
            end
            % Slew Target to Sun Pointing
            try
            slew_id = 1:height(app.slew_startstop_ta2s_time);
            slew_name_append = append(string(slew_id'),"_",app.pointchange_name(4));
            slew_table_ta2s = table(slew_name_append,app.slew_startstop_ta2s_time(:,1),app.slew_startstop_ta2s_time(:,2),app.slew_startstop_ta2s_time(:,2)-app.slew_startstop_ta2s_time(:,1));
            slew_table_ta2s.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
            end
            
            % ----Create and Sort SoE----
            app.SoE = [Maneuvers;Activities;Access;slew_table_s2th;slew_table_th2s;slew_table_s2ta;slew_table_ta2s];
            [~, idx] = sort(app.SoE{:,'Start Time'},'ascend');       % SoE{:,'Start Time'} -> datetime. Sort chronol with start time
            app.SoE = app.SoE(idx,:);
            SoE_activities_id = 1:height(app.SoE);
            app.SoE = [table(SoE_activities_id'),app.SoE];
            
            % Activity Table 
            app.SoETable.Data = app.SoE;

        end

        % Button pushed function: ConflictCheckButton
        function ConflictCheckButtonPushed(app, event)
            % Button checks for conflicts (overlap) of activities in SoE, shows
            % results in table: ConflictOutputTable and adds column with Time 
            % between activiteies in table: SoETable
                
            activity_start = datetime(app.SoE{:,'Start Time'});
            activity_stop = datetime(app.SoE{:,'Stop Time'});
            activity_start(1) = []; % delete first activity start time
            activity_stop(end) = [];  % delete last activity stop time
    
            time_between_activites = activity_start-activity_stop; % calculate duration between stop time of acitivty (activity_i) 
            % and start time of next activity (activity_i+1) 
            % If one of the durtions is negative e.g. -00:05:00, there is a conflict
    
            index_conflict = find(time_between_activites<0);    % finds index of duration<0
            number_conflicts = length(index_conflict);          % number of conflicts
            
            % Conflict output Table: ConflictOutputTable
            number_conf_out = append("Number Conflicts: ",string(number_conflicts));
            shortest_time_conflict = append("Shortest time between activities: ",string(min(time_between_activites)));
            app.ConflictOutputTable.Data = [number_conf_out; shortest_time_conflict];
    
            
            % Add column with Time between activiteies in table: SoETable
            timeBactivity = table([time_between_activites;duration('00:00:00')]);
            timeBactivity.Properties.VariableNames = "Time to next Activity";
            app.SoE = [app.SoE, timeBactivity];
            app.SoETable.Data = app.SoE;
        

        end

        % Button pushed function: UpdateSchedulePlotButton
        function UpdateSchedulePlotButtonPushed(app, event)
            % Creates the schedule plot of all activities
            SchedulePlot(app,app.SchedulePlot_1);

        end

        % Button pushed function: PowerConstraintAnalysisButton
        function PowerConstraintAnalysisButtonPushed(app, event)
            disp('Creating Solar Power report')
            app.ConsoleLog("Creating Solar Power report");
            
            % Description: 
            % Input:
            % -CLIMB_Solar_Panel_Power CHECK
            % -Scenario_time CHECK
            % -Access_schedule CHECK
            % -Battery_InitialStatus_Model_Parameters CHECK
            % -CLIMB Power Budget V2 CHECK
            % -Fire_schedule CHECK
            % -RPM_overTime Left out for now
            
            % Output: 
            % -PowerTool.csv
            
            %% ----Create Fire_schedule.csv file----
            Fire_schedule_csv = table(app.peri_pass_time,app.maneuver_select_logic);
            Fire_schedule_csv.Properties.VariableNames = {'Perigee pass time', 'Propulsion On/ Off'};
            writetable(Fire_schedule_csv, append(app.main_directory_path,'\Output_CSV_files\Fire_schedule.csv'));
    
            %% ----Create Scenario_time.csv file----
            Scenario_time_csv = table(datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'));
            Scenario_time_csv.Properties.VariableNames = {'Scenario start time','Scenario stop time'};
            writetable(Scenario_time_csv, append(app.main_directory_path,'\Output_CSV_files\Scenario_time.csv'));
                   
            %% ----Create Access_schedule.csv file----
            access_table_2 = app.AccessTableInfo_2.Data; % get access table
    
            % For access status, only calculate for access that is selected in GUI and is set to S-Band. 
            % In power budget, only S-band is considered since UHF data is sent via beacons all the time and without specific adcs settings
            index_sband = find(cell2mat(access_table_2(:,8))==1 & string(access_table_2(:,9))=="S-Band");
            access_select = zeros(length(app.access_start_time),1);
            access_select(index_sband) = 1;
            Access_schedule_csv = table(app.access_start_time,app.access_stop_time,app.access_stop_time-app.access_start_time,access_select);
            Access_schedule_csv.Properties.VariableNames = {'Access window start', 'Access window end', 'Access duration', 'Access Status'};
            writetable(Access_schedule_csv, append(app.main_directory_path,'\Output_CSV_files\Access_schedule.csv'));
    
            %% ----Set the Solar Panel Tool----
            % More information in the link below
            % https://help.agi.com/stkdevkit/11.4.0/#../Subsystems/connectCmds/Content/cmd_VOSolarPanel.htm?Highlight=Solar%20Panel%20Tool
    
            % Set step size and watts as unit for power
            stepsize = 600; % Simulation step size in seconds
            app.root.UnitPreferences.Item('Power').SetCurrentUnit('W'); % default would be dBW
            
            % Define commands for power tool settings
            power_tool_command_addsolarpanels = "VO */Satellite/CLIMB SolarPanel Visualization Radius On 1 AddGroup Solar_Panels";
            power_tool_command_settings = append("VO */Satellite/CLIMB SolarPanel Compute",' "',app.scenario_start_time,'" "',app.scenario_stop_time,'" ',string(stepsize));
            % power_tool_command_settings must look like this: VO */Satellite/CLIMB SolarPanel Compute "6 Aug 2023 20:15:04.320" "9 Aug 2023 20:15:04.320" 600'
            
            % Ecxecute Solar Panel Tool Commands
            app.root.ExecuteCommand(power_tool_command_addsolarpanels);
            app.root.ExecuteCommand(power_tool_command_settings);
     
            % Get Report
            Solar = app.CLIMB.DataProviders.Item('Solar Panel Power').Exec(app.scenario_start_time,app.scenario_stop_time,stepsize);
            SolarTime = Solar.DataSets.GetDataSetByName('Time').GetValues;
            SolarPower = Solar.DataSets.GetDataSetByName('Power').GetValues;
            SolarIntensity = Solar.DataSets.GetDataSetByName('Solar Intensity').GetValues;
            
            %% ----Create CLIMB_Solar_Panel_Power.csv file----
            % Write CLIMB_Solar_Panel_Power.csv file
            SolarMatrix = [SolarTime,SolarPower,SolarIntensity];
            SolarTable = array2table(SolarMatrix);
            SolarTable.Properties.VariableNames(1:3) = {'Time (UTCG)','Power (W)','Solar Intensity'};
            writetable(SolarTable,append(app.main_directory_path,'\Output_CSV_files\CLIMB_Solar_Panel_Power.csv'));
    
            %% ----Run Python Power Tool and get Power Report----
            % Run Python power tool
            %pyrunfile(append(app.main_directory_path,'\Power_Tool\PowerTool.py'))
            
            % Get 
            power_tool_output_csv= readtable(append(app.main_directory_path,'\Power_Tool\PowerTool.csv'), 'HeaderLines', 1);
            
            power_tool_time = datetime(table2cell(power_tool_output_csv(:,1)), 'InputFormat', 'dd-MM-yyyy HH:mm:ss');
            propulsion_power = table2array(power_tool_output_csv(:,2));
            downlink_power = table2array(power_tool_output_csv(:,3));
            total_power_consumed = table2array(power_tool_output_csv(:,4));
            power_generated = table2array(power_tool_output_csv(:,5));
            power_from_battery = table2array(power_tool_output_csv(:,6));
            bat_capacity = table2array(power_tool_output_csv(:,7));
            bat_SOC = table2array(power_tool_output_csv(:,8));
    
            %% Plot
            
            
            % ---- Batter SoC ----
            cla(app.SoCPlot_1) % Delete all plots
            ax = app.SoCPlot_1;
    
            plot(ax,power_tool_time,bat_SOC)
            yline(ax,35, '--', 'color','red')

            ylabel(ax,"Battery SoC")
            ylim(ax,[0,101])
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
    
    
            % ----Power Generated----
            cla(app.PowerGenPlot) % Delete all plots
            ax = app.PowerGenPlot;
    
            plot(ax,power_tool_time,power_generated)
            
            ylabel(ax,"Power Generated in W")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
            
            % ----Power Consumption----
            cla(app.PowerConPlot) % Delete all plots
            ax = app.PowerConPlot;
    
            plot(ax,power_tool_time,total_power_consumed)
            
            ylabel(ax,"Power Consumption in W")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
    
            % ----Battery Power Output----
            cla(app.BattPowerPlot) % Delete all plots
            ax = app.BattPowerPlot;
    
            plot(ax,power_tool_time,-power_from_battery)
            yline(ax,0, '--', 'color','black')
            
            ylabel(ax,"Battery Power Output in W")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
    
            % ---- Batter SoC ----
            cla(app.SoCPlot_2) % Delete all plots
            ax = app.SoCPlot_2;
    
            plot(ax,power_tool_time,bat_capacity)
            
            ylabel(ax,"Battery Capacitiy in Ah")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])


            % Creates the Schedule Plot of all Activities
            SchedulePlot(app,app.SchedulePlot_2);

        end

        % Button pushed function: ADCSConstraintAnalysisButton
        function ADCSConstraintAnalysisButtonPushed(app, event)
            % Code from STP TorqueTool1.m
            % Main difference: Input for YPR values etc not from CSV files but
            % directly from STK
    
            %Misalignment_angle
                
            %% Creating Torque Report from STK
            disp('Creating Torque report')
            app.ConsoleLog("Creating Torque report");
            
            Torque = app.CLIMB.DataProviders.Item('Vectors(Body)').Group.Item('TotalTorque').Exec(app.scenario_start_time,app.scenario_stop_time,5);
            TorqueX=cell2mat(Torque.DataSets.GetDataSetByName('x').GetValues);
            TorqueY=cell2mat(Torque.DataSets.GetDataSetByName('y').GetValues);
            TorqueZ=cell2mat(Torque.DataSets.GetDataSetByName('z').GetValues);
            Torque_time = datetime(Torque.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
    
            %% Creating Angular Momentum Report from STK
            disp('Creating Angular Momentum report')
            app.ConsoleLog("Creating Angular Momentum report");
            
            AngMomentum = app.CLIMB.DataProviders.Item('Vectors(Body)').Group.Item('AngMomentum').Exec(app.scenario_start_time,app.scenario_stop_time,5);
            AngMomentumX = cell2mat(AngMomentum.DataSets.GetDataSetByName('x').GetValues);
            AngMomentumY = cell2mat(AngMomentum.DataSets.GetDataSetByName('y').GetValues);
            AngMomentumZ = cell2mat(AngMomentum.DataSets.GetDataSetByName('z').GetValues);
            AngMomentum_time = datetime(AngMomentum.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
    
    
            %% Creating angle change and angular velocity report
            disp('Creating angle change and angular velocity report')
            app.ConsoleLog("Creating angle change and angular velocity report");
            
            % YPR - Yaw, Pitch, Roll angels in deg
            YPR = app.CLIMB.DataProviders.Item('Attitude YPR').Group.Item('Seq RPY').Exec(app.scenario_start_time,app.scenario_stop_time,1);
            Yaw = cell2mat(YPR.DataSets.GetDataSetByName('Yaw').GetValues);
            Pitch = cell2mat(YPR.DataSets.GetDataSetByName('Pitch').GetValues);
            Roll = cell2mat(YPR.DataSets.GetDataSetByName('Roll').GetValues);
            YPR_time = datetime(YPR.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % Yaw_rate = cell2mat(YPR.DataSets.GetDataSetByName('Yaw Rate').GetValues);
            % Pitch_rate = cell2mat(YPR.DataSets.GetDataSetByName('Pitch Rate').GetValues);
            % Roll_rate = cell2mat(YPR.DataSets.GetDataSetByName('Roll Rate').GetValues);
     
            % Correction due to -180 to +180 limit
            for i = 1:(length(Yaw)-1)
             
                while Yaw(i) < (Yaw(i+1)-90)
                    Yaw(i+1) = Yaw(i+1)-360;
                end
                while Yaw(i) > (Yaw(i+1)+90)
                    Yaw(i+1) = Yaw(i+1)+360;
                end
                while Pitch(i) < (Pitch(i+1)-90)
                    Pitch(i+1) = Pitch(i+1)-360;
                end
                while Pitch(i) > (Pitch(i+1)+90)
                    Pitch(i+1) = Pitch(i+1)+360;
                end
                while Roll(i) < (Roll(i+1)-90)
                    Roll(i+1) = Roll(i+1)-360;
                end
                while Roll(i) > (Roll(i+1)+90)
                    Roll(i+1) = Roll(i+1)+360;
                end
            
            end
    
            %% Constants Input from Torque_tool_data.xlsx
    
            Torque_tool_data  = readtable(append(app.main_directory_path,'\Torque_tool_data.xlsx'));
            
            % CubeSat CAD data
            % Moment of Inertia
            I_xx = table2array(Torque_tool_data(2,2));    % kg*m^2 (Roll)
            I_yy = table2array(Torque_tool_data(3,2));    % kg*m^2 (Pitch)
            I_zz = table2array(Torque_tool_data(4,2));    % kg*m^2 (Yaw)
            
            % Center of Mass shift
            cm_x = table2array(Torque_tool_data(7,2));    % in m
            cm_y = table2array(Torque_tool_data(8,2));    % in m
            
            Height_z = table2array(Torque_tool_data(11,2));     % in m, distance from center of mass to propulsion system bottom
            
            % ADCS data sheet
            ADCS_max_L = table2array(Torque_tool_data(2,6));    % Max Angular momentum of ADCS in mNms
            ADCS_max_T = table2array(Torque_tool_data(1,6));    % Max torque of ADCS in mNm
            RPM_limit = table2array(Torque_tool_data(3,6));     % Max RPM of ADCS (8000 for CW0017 and 10000 for CW0057) 
            I_fw = table2array(Torque_tool_data(5,6));          % Moment of Inertia of ADCS in kg*m^2 (2.4E-05 for CW0017 and 7E-05 for CW0057 and CS has 0.1432 kg*m^2 (need 3.2 E-05))
            
            % ADCS last status from downlink data (initial state)
            RPM_wheel_initial=table2array(Torque_tool_data(15,6));
            L_x_cubesat_initial=table2array(Torque_tool_data(16,6));
            L_y_cubesat_initial=table2array(Torque_tool_data(17,6));
            L_z_cubesat_initial=table2array(Torque_tool_data(18,6));
            
            % Contributors to torque due to CM shift
            F = table2array(Torque_tool_data(15,2));        % Prop system thrust in N
            t_thrust = table2array(Torque_tool_data(16,2)); % Thrust time in s
                   
            % Contributors to torque due to thrust vector misallignment 
            Misalignment_angle = table2array(Torque_tool_data(17,2)); % in deg
       

            %%
            % Fire schedule
            % Fire_data=readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Fire_schedule.csv",'HeaderLines', 1);
            % Fire_times = table2array(Fire_data(:,1));
            % Fire_status = table2array(Fire_data(:,2));
            Burn_time_status = zeros((size(YPR_time,1))-1,1); %  delta_L_x=zeros(size(L_x, 1), 1);
            
            for i = 1:height(app.maneuver_start_time)
        
                for j = 1:size(Burn_time_status)
                    if YPR_time(j)>app.maneuver_start_time(i) && YPR_time(j)<app.maneuver_stop_time(i)
     
                        Burn_time_status(j) = 1;

                    end
        
                end
        
            end

            %% Torque
            TorqueX = 1000*TorqueX; % mNm
            TorqueY = 1000*TorqueY; % mNm
            TorqueZ = 1000*TorqueZ; % mNm
        
            T=sqrt(TorqueX.^2+TorqueY.^2+TorqueZ.^2); % Abs. Torque
        
            Max_Torque = max(T);% Max. Torque
            disp(['Maximum Instantenious Torque (mNm): ', num2str(Max_Torque)]);
        
            cum_T_x = sum(TorqueX);
            cum_T_y = sum(TorqueY);
            cum_T_z = sum(TorqueZ);
            Total_torque = sqrt(cum_T_x^2+cum_T_y^2+cum_T_z^2);% Abs. Cum. Torque
            disp(['Total Torque (mNm): ', num2str(Max_Torque)]);

            %% Angular Momentum
            AngMomentumX=1000*AngMomentumX; % 1000*kg*m^2/sec= mNms % + L_cm_x*      
            AngMomentumY=1000*AngMomentumY; % 1000*kg*m^2/sec= mNms
            AngMomentumZ=1000*AngMomentumZ; % 1000*kg*m^2/sec= mNms
        
            L=sqrt(AngMomentumX.^2+AngMomentumY.^2+AngMomentumZ.^2);
            Max_L=max(L);
            disp(['Maximum instantaneous Angular Momentum (mNms) ', num2str(Max_L)]);
            
            delta_L_x=zeros(size(AngMomentumX, 1), 1);
            for i = 2:length(delta_L_x)
                delta_L_x_i = AngMomentumX(i);
                delta_L_x_iminus1 = AngMomentumX(i-1);
                delta_L_x(i) = AngMomentumX(i)-AngMomentumX(i-1);
            end
            AngMomentumX(1)=L_x_cubesat_initial+AngMomentumX(1);
            Cum_L_x=AngMomentumX(1)+cumsum(delta_L_x);
        
        
            delta_L_y=zeros(size(AngMomentumY, 1), 1);
            for i = 2:length(delta_L_y)
                delta_L_y_i = AngMomentumY(i);
                delta_L_y_iminus1 = AngMomentumY(i-1);
                delta_L_y(i) = AngMomentumY(i)-AngMomentumY(i-1);
            end
            AngMomentumY(1)=L_y_cubesat_initial+AngMomentumY(1);
            Cum_L_y=L(1)+cumsum(delta_L_y);
        
            delta_L_z=zeros(size(AngMomentumZ, 1), 1);
            for i = 2:length(delta_L_z)
                delta_L_z_i = AngMomentumZ(i);
                delta_L_z_iminus1 = AngMomentumZ(i-1);
                delta_L_z(i) = AngMomentumZ(i)-AngMomentumZ(i-1);
            end
            AngMomentumZ(1)=L_z_cubesat_initial+AngMomentumZ(1);
            Cum_L_z=L(1)+cumsum(delta_L_z);
        
            L_limit_pos=ADCS_max_L*ones(size(AngMomentum_time));
            L_limit_neg=-ADCS_max_L*ones(size(AngMomentum_time));
    
    
            %%  Angular velcoity derivation
            time_step = seconds(YPR_time(2) - YPR_time(1)); % Calculate the time step in seconds
            
            Yaw_rate = diff(Yaw(:))./time_step; % deg/s
            Pitch_rate = diff(Pitch(:))./time_step;
            Roll_rate = diff(Roll(:))./time_step;
      
            %% Angular momentum due to CM shift
            Angular_momentum_pitch_thrust_persecond = F*cm_x; % Nm
            Angular_momentum_roll_thrust_persecond = F*cm_y; % Nm
            Thrust_momentum_pitch = Angular_momentum_pitch_thrust_persecond*time_step*Burn_time_status; 
            Thrust_momentum_roll = Angular_momentum_roll_thrust_persecond*time_step*Burn_time_status;
            Thrust_momentum_pitch = cumtrapz(Thrust_momentum_pitch);
            Thrust_momentum_roll = cumtrapz(Thrust_momentum_roll);
        
            %% Angular mometum due to Thrust vector misallignment 
            Thrust_momentum_PR=F*Height_z*sind(Misalignment_angle); % Either could be added to X or Y, roll or pitch, Nm
            Angular_momentum_misall_PR=cumtrapz(Thrust_momentum_PR*time_step*Burn_time_status);
        
            %% Total angular momentum   
            Angular_momentum_yaw_STK = (Yaw_rate*pi/180) * I_zz; % Nms
            Angular_momentum_pitch_STK = (Pitch_rate*pi/180) * I_yy;
            Angular_momentum_roll_STK = (Roll_rate*pi/180) * I_xx;
        
            Angular_momentum_yaw = Angular_momentum_yaw_STK ; % Nms
            Angular_momentum_pitch= Angular_momentum_pitch_STK + Thrust_momentum_pitch+Angular_momentum_misall_PR; 
            Angular_momentum_roll= Angular_momentum_roll_STK +Thrust_momentum_roll;
    
    
            %% RPM of the wheel
            RPM_x = Angular_momentum_roll/I_fw*30/pi+RPM_wheel_initial; 
            RPM_y = Angular_momentum_pitch/I_fw*30/pi+RPM_wheel_initial;
            RPM_z = Angular_momentum_yaw/I_fw*30/pi+RPM_wheel_initial;
            
            % figure Name RPM
            %     hold on
            %     plot(YPR_time(1:length(Yaw_rate)),RPM_x, 'Color', 'red','LineWidth',2)
            %     plot(YPR_time(1:length(Yaw_rate)),RPM_y, 'Color', 'black', 'LineWidth',2)
            %     plot(YPR_time(1:length(Yaw_rate)),RPM_z,'Color', 'green','LineWidth',2)
            % 
            %     yline(RPM_limit, '--', 'LineWidth',2, 'Color', 'red')
            %     yline(-RPM_limit, '--', 'LineWidth',2, 'Color', 'red')
            %     ylim([-8000 8000])
            %     xlabel('Time','FontSize',24,'FontWeight','bold') 
            %     ylabel('RPMs','FontSize',24,'FontWeight','bold') 
            %     legend({'RPM around x','RPM around y','RPM around z', ...
            %         'Upper Limit' ,'Lower Limit'},'Location','southeast','FontSize',24,'FontWeight','bold')
            %     set(gca,'FontSize', 24)
            %     hold off 
            
            try
            % Date where ADCS RPM limimt is reached
            ADCS_saturation_date = YPR_time(RPM_x>=RPM_limit | RPM_y>=RPM_limit | RPM_z>=RPM_limit);
            ADCS_saturation_date = ADCS_saturation_date(1);
            app.ConstraintTextArea.Value = append("Saturation Date: ",string(ADCS_saturation_date));
            catch
                app.ConstraintTextArea.Value = "No ADCS saturation limit exceeded";
            end
        
        
            %% Plot
        
            cla(app.RPMPlot_1) % Delete all plots
            ax = app.RPMPlot_1;
        
            plot(ax,YPR_time(1:length(Yaw_rate)),RPM_x,'color','#D95319')%,'linewidth',1,'color','#D95319')
            plot(ax,YPR_time(1:length(Yaw_rate)),RPM_y,'color','#0072BD')
            plot(ax,YPR_time(1:length(Yaw_rate)),RPM_z,'color','#EDB120')
        
            yline(ax,RPM_limit, '--', 'color','red')
            ylabel(ax,"RPM of Wheels")
            legend(ax,"Yaw","Pitch","Roll")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
            %-------------------------------------------
            cla(app.RPMPlot_2) % Delete all plots
            ax = app.RPMPlot_2;
        
            plot(ax,YPR_time(1:length(Yaw_rate)),RPM_x,'color','#D95319')%,'linewidth',1,'color','#D95319')
            plot(ax,YPR_time(1:length(Yaw_rate)),RPM_y,'color','#0072BD')
            plot(ax,YPR_time(1:length(Yaw_rate)),RPM_z,'color','#EDB120')
        
            yline(ax,RPM_limit, '--', 'color','red')
            ylabel(ax,"RPM of Wheels")
            legend(ax,"Yaw","Pitch","Roll")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
        
            %-------------------------------------------
            cla(app.AngularMomPlot) % Delete all plots
            ax = app.AngularMomPlot;
        
            plot(ax,YPR_time(1:length(Angular_momentum_yaw)),Angular_momentum_yaw,'color','#D95319')%,'linewidth',1,'color','#D95319')
            plot(ax,YPR_time(1:length(Angular_momentum_yaw)),Angular_momentum_pitch,'color','#0072BD')
            plot(ax,YPR_time(1:length(Angular_momentum_yaw)),Angular_momentum_roll,'color','#EDB120')
        
            yline(ax,ADCS_max_L/1000, '--', 'color','red')
            ylabel(ax,"Angular Momentum in Nms")
            legend(ax,"Yaw","Pitch","Roll")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
            
            
            %-------------------------------------------
            cla(app.YPRPlot) % Delete all plots
            ax = app.YPRPlot;
        
            plot(ax,YPR_time(1:length(Yaw)),Yaw,'color','#D95319')%,'linewidth',1,'color','#D95319')
            plot(ax,YPR_time(1:length(Yaw)),Pitch,'color','#0072BD')
            plot(ax,YPR_time(1:length(Yaw)),Roll,'color','#EDB120')
        
            % yline(ax,RPM_limit, '--', 'color','red')
            ylabel(ax,"YPR angels in deg")
            legend(ax,"Yaw","Pitch","Roll")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
            
            
            %-------------------------------------------
            cla(app.YPRratesPlot) % Delete all plots
            ax = app.YPRratesPlot;
        
            plot(ax,YPR_time(1:length(Yaw_rate)),Yaw_rate,'color','#D95319')%,'linewidth',1,'color','#D95319')
            plot(ax,YPR_time(1:length(Yaw_rate)),Pitch_rate,'color','#0072BD')
            plot(ax,YPR_time(1:length(Yaw_rate)),Roll_rate,'color','#EDB120')
        
            % yline(ax,RPM_limit, '--', 'color','red')
            ylabel(ax,"YPR rates in deg/s")
            legend(ax,"Yaw","Pitch","Roll")
            xlim(ax, [datetime(app.scenario_start_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(app.scenario_stop_time,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])
    
    
            % Creates the Schedule Plot of all Activities
            SchedulePlot(app,app.SchedulePlot_3);

        end

        % Button pushed function: LongTermPropagateButton
        function LongTermPropagateButtonPushed(app, event)
%--------------------------------------------------------------------------
%---------------------- Long Term Propagation Tool ------------------------
%--------------------------------------------------------------------------

            % Description:


            %% Input
            start_date = app.StartTimeEditField.Value;
            %start_date = datetime(app.StartTimeEditField.Value,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            skip_count = app.SkipeveryxmaneuverEditField.Value;
            maxIter = app.MaxIterationsEditField.Value;
            maxApoAlt = app.ApogeeAltitudekmEditField.Value;
            maxDate = app.StopTimeEditField.Value;

            thrust_duration = app.ManeuverDurationinminEditField_2.Value;
            thrust = app.ThrustNEditField_2.Value;
            Isp = app.IspsEditField_2.Value;
            thruster_efficiency = app.ThrusterEfficiencyEditField_2.Value ;

            % Get Scenario and Satellite
            %app.scenario = app.root.CurrentScenario;
            app.CLIMB_longterm = app.scenario.Children.Item('CLIMB_LongTerm');       % Open Satellite CLIMB_LongTerm
            
            %% Set Engine Parameters

            % Get the Engine Models Folder
            engine = app.scenario.ComponentDirectory.GetComponents('eComponentAstrogator').GetFolder('Engine Models');
            engine_model = engine.Item('EnpulsionLongTerm');

            % Set Parameters
            engine_model.Thrust = thrust;
            engine_model.Isp = Isp;

            %% Set Astrogator
            disp('Set Astrogator')
            app.CLIMB_longterm.SetPropagatorType('ePropagatorAstrogator');
            astrogator = app.CLIMB_longterm.Propagator;
            astrogator.MainSequence.RemoveAll();    %Clear all segments from the MCS


            %% Create Sequence
            %---------------------Insert initial state---------------------
            % Run setInitState function which sets the initial state
            % Inserts the following segments:
            %   - Initial State Segment: TLE as initial state
            %   - Propagator Segment: Propagates from TLE epoch to scenario start time set in GUI by the user
            % setInitState(app,start_date)


            %----------------- Insert an initial state ----------------
            initState = astrogator.MainSequence.Insert('eVASegmentTypeInitialState','Initail State','-');
            initState.OrbitEpoch = start_date;

            % Initial State
            ra = app.Orbit_data_array(:,4);
            rp = app.Orbit_data_array(:,5);
            ecc = app.Orbit_data_array(:,2);
            Incl = app.Orbit_data_array(:,3);
            RAAN = app.Orbit_data_array(:,6);
            ArgPeri = app.Orbit_data_array(:,7);
            MeanAno = app.Orbit_data_array(:,11);

            % Elements:
            initState.SetElementType('eVAElementTypeKeplerian');
            kep = initState.Element;
            kep.ApoapsisAltitudeSize = ra;
            kep.Eccentricity = ecc;
            kep.Inclination = Incl;
            kep.RAAN = RAAN;
            kep.ArgOfPeriapsis = ArgPeri;
            kep.MeanAnomaly = MeanAno;

            % Spacecraft Parameters:
            sc = initState.SpacecraftParameters;
            sc.DryMass = 4.5;                                      % dry mass - kg
            sc.Cd = 2.2;                                                 % coefficient Cd
            sc.DragArea = 0.08;                                           % Drag-area - m^2
            sc.Cr = 2;                                                 % coefficient Cr
            sc.SolarRadiationPressureArea = 0.07219; % Solar radiation pressure Pressure Area m^2
            sc.Ck= 0;                                                  % coefficient Cr
            sc.RadiationPressureArea = 0.079947;           % Radiation Pressure Area - m^2
            sc.K1 = 1; 
            sc.K2 = 1;

            % Fuel Tank:
            ft = initState.FuelTank;
            ft.TankPressure = 5000;               % Tank pressure - Pa
            ft.TankVolume = 0.0006;                 % Tank Volume - m^2
            ft.TankTemperature = 429.75;            % Tank Temperature
            ft.FuelDensity = 7310;              % Fuel density - kg/m^3
            ft.FuelMass = 0.25;                % Fuel Mass - kg
            ft.MaximumFuelMass = 0.25;     % Maximum Fuel Mass - kg
            
            %--------------------- First Propagator ------------------
            astro_propagator1_segment = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propagate1','-');
            astro_propagator1_segment.PropagatorName = 'Earth HPOP Default v10';
            astro_propagator1_segment.Properties.Color = 65280;     % green
            propagator1_stopcond = astro_propagator1_segment.StoppingConditions.Add('Periapsis');
            propagator1_stopcond.Properties.RepeatCount = 1; % amount of circulation before proceeding
            astro_propagator1_segment.StoppingConditions.Remove('Duration');
            
            %--------------------- Maneuver Segment -------------------
            astro_maneuver_segment = astrogator.MainSequence.InsertByName('ManeuverClimbV1','-');  % Maneuver from component browser in STK
            astro_maneuver_segment.Maneuver.ThrustEfficiency = thruster_efficiency;
            prop_manu = astro_maneuver_segment.Maneuver.Propagator;
            stopcond = prop_manu.StoppingConditions.Add('Duration');         % in min, dont know why
            stopcond.Properties.Trip = thrust_duration;                 % in min, dont know why
            prop_manu.StoppingConditions.Remove('Duration');
     
            %-------------------- Insert 2nd Propagator----------------
            astro_propagator2_segment = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propagate2','-');
            astro_propagator2_segment.PropagatorName = 'Earth HPOP Default v10';
            astro_propagator2_segment.Properties.Color = 65280;     % green
            propagator2_stopcond = astro_propagator2_segment.StoppingConditions.Add('Periapsis');
            propagator2_stopcond.Properties.RepeatCount = 1; % amount of circulation before proceeding
            astro_propagator2_segment.StoppingConditions.Remove('Duration');
            
            
            %% Set maneuver skip settings
            skip_maneuver = skip_count;
            maneuver_count = 0;

            %% ----------------------------Run Astrogator-----------------------------
            
            for i=1:maxIter

                disp(['--- Iteration: ', num2str(i),' ---'])

                % Delete Manuever segment if i==skipcount and run astrogator
                if(i==skip_maneuver)
                    astrogator.MainSequence.Remove("ManeuverClimbV1");
                    astrogator.RunMCS
                    disp(['Skip Maneuver',num2str(skip_maneuver)])
                else
                    astrogator.RunMCS
                    maneuver_count = maneuver_count+1;
                    maneuver_output_report = app.CLIMB_longterm.DataProviders.Item('Maneuver Summary').Exec(start_date,maxDate);
                    dv_array(maneuver_count) = maneuver_output_report.DataSets.GetDataSetByName('Delta V').GetValues;
                    disp("Fire")
                end
                
                % Delete first propagator segment after frist astrogator run
                if(i==1)
                    astrogator.MainSequence.Remove("Propagate1");
                end
                
                
                % Get updated orbital elements and set them as initial state
                Epoch_new{i} = astro_propagator2_segment.GetResultValue('Epoch');             % get date from the last step
                initState.OrbitEpoch = Epoch_new{i};                           % {} -> bracket for cell array
            
                ra_new(i) = astro_propagator2_segment.GetResultValue('Altitude_Of_Apoapsis'); % get altitude of the apoapsis from the last step;
                kep.ApoapsisAltitudeSize = ra_new(i);
            
                rp_new(i) = astro_propagator2_segment.GetResultValue('Altitude_Of_Periapsis');
            
                Incl_new(i) = astro_propagator2_segment.GetResultValue('Inclination');
                kep.Inclination = Incl_new(i);
            
                RAAN_new(i) = astro_propagator2_segment.GetResultValue('RAAN');
                kep.RAAN = RAAN_new(i);
            
                ecc_new(i) = astro_propagator2_segment.GetResultValue('Eccentricity');        % get the value of the eccentritiy from the last step
                kep.Eccentricity = ecc_new(i);                                  % update the value
            
                ArgPeri_new(i) = astro_propagator2_segment.GetResultValue('Argument of Periapsis');
                kep.ArgOfPeriapsis = ArgPeri_new(i);
            
                TrueAno_new(i) = astro_propagator2_segment.GetResultValue('True_Anomaly');
                kep.TrueAnomaly = TrueAno_new(i);                               % value of Propagator END; stays const.
            
                
                if(i==skip_maneuver)
                    % Remove propagagor to insert manuever again infont of 2end propagator (must keep the right order)
                    astrogator.MainSequence.Remove("Propagate2");
                    
                    % Insert Manuever again
                    astro_maneuver_segment = astrogator.MainSequence.InsertByName('ManeuverClimbV1','-');  % Maneuver from component browser in STK
                    astro_maneuver_segment.Maneuver.ThrustEfficiency = thruster_efficiency;
                    prop_manu = astro_maneuver_segment.Maneuver.Propagator;
                    stopcond = prop_manu.StoppingConditions.Add('Duration');         % in min, dont know why
                    stopcond.Properties.Trip = thrust_duration;                 % in min, dont know why
                    prop_manu.StoppingConditions.Remove('Duration');
                    
                    % Insert 2end propagator again
                    astro_propagator2_segment = astrogator.MainSequence.Insert('eVASegmentTypePropagate','Propagate2','-');
                    astro_propagator2_segment.PropagatorName = 'Earth HPOP Default v10';
                    astro_propagator2_segment.Properties.Color = 65280;     % green
                    propagator2_stopcond = astro_propagator2_segment.StoppingConditions.Add('Periapsis');
                    propagator2_stopcond.Properties.RepeatCount = 1; % amount of circulation before proceeding
                    astro_propagator2_segment.StoppingConditions.Remove('Duration');
                    
                    % increase maneuver skip count
                    skip_maneuver=skip_maneuver+skip_count;
                    
                end
               
                %disp(datetime(Epoch_new(i),'InputFormat','dd MMM uuuu HH:mm:ss.SSS'))          
                disp(['Apogee = ', num2str(ra_new(i))])
                if(ra_new(i) >= maxApoAlt || datetime(Epoch_new{i},'InputFormat','dd MMM uuuu HH:mm:ss.SSS')>=datetime(maxDate,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'))
                    break
                end
            end

            
            %% Output
            epoch_array = datetime(Epoch_new,'InputFormat','dd MMM uuuu HH:mm:ss.SSS');
            epoch_days = daysact(epoch_array(1),epoch_array(end));
            % years_pl = linspace(0,epoch_days,length(ra_new));
            epoch_array(end);
            Epoch_new(end);

            % Data Visualization in Table: ManueverGlobalLongTermTable 
            varname = {'Start Date', 'Stop Date', 'Total Time (days)', 'Total Apogee Rise (km)','Initial Apogee Altitude (km)','Final Apogee Altitude (km)','Total Detla V (m/s)','Delta V per Maneuver', 'Total Number Maneuvers','Total Number Rotations'};
            
            total_time = days(epoch_array(end)-datetime(start_date,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'));
            total_apo_rise = ra_new(end)-ra;
            total_dv = sum(cell2mat(dv_array));

            longterm_table = table(varname', [{start_date},Epoch_new(end),{total_time},num2cell(total_apo_rise),num2cell(ra_new(1)),num2cell(ra_new(end)),num2cell(total_dv),dv_array(end),num2cell(maneuver_count),num2cell(i)]');
            app.ManueverGlobalLongTermTable.Data = longterm_table;
            

            %% Plot

            cla(app.AltitudePlotLongTerm) % Delete all plots
            ax = app.AltitudePlotLongTerm;

            plot(ax,epoch_array,ra_new,'color','#0072BD')
            plot(ax,epoch_array,rp_new,'color','#D95319')
            
            legend(ax,"Apogee","Perigee")
            xlim(ax, [datetime(start_date,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS'),datetime(epoch_array(end),'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS')])


        end

        % Button pushed function: ClearAstrogatorButton
        function ClearAstrogatorButtonPushed(app, event)
            % Clears Atrogator MCS from satellite CLIMB_LongTerm
            %app.scenario = app.root.CurrentScenario;
            app.CLIMB_longterm = app.scenario.Children.Item('CLIMB_LongTerm');       % Open Satellite CLIMB_LongTerm
            astrogator = app.CLIMB_longterm.Propagator;
            astrogator.MainSequence.RemoveAll();    %Clear all segments from the MCS
            
        end

        % Button pushed function: ExportSoEButton
        function ExportSoEButtonPushed(app, event)
            % Description: Saves the Sequence of Evenets (app.SoE) defined in function AddActivityButtonPushed(app, event)
            writetable(app.SoE,'CLIMB_SoE.xlsx')
            % save('SoE.xlsx',app.SoE)
        end

        % Button pushed function: ShowCLIMBParametersButton
        function ShowCLIMBParametersButtonPushed(app, event)
            
            CLIMB_parameters = readtable(append(app.main_directory_path,"\CLIMB_Parameter.xlsx")); % Get Climb Parameter table
            idx = ismember(CLIMB_parameters.Var2, "");  % Get index of all empty rows
            CLIMB_parameters(idx,:) = [];               % Delete all empty rows 
            app.CLIMBParamTable.Data = CLIMB_parameters;


        end

        % Button pushed function: ClearallActivitiesButton
        function ClearallActivitiesButtonPushed(app, event)
            % Clears custom activity table. Since the Schedule Plots and
            % SoE takes the start and end times for custom activty from
            % this table, it will also be cleared for plot and SoE
            app.ActivityOutputTable.Data = [];
        end

        % Button pushed function: ResetScenarioButton
        function ResetScenarioButtonPushed(app, event)
            %% Description
            % Clears app variables and all tables and plots
            % Unfortunalty could not find command which clears everything
            % with a sinlge line like e.g. "clear all". Therfore necessary
            % to clear every sinlge variable manually


            %% Clear app Variables
            % Access
            app.access_start_time = [];       % 2nd propagation
            app.access_stop_time = [];        % 2nd propagation
            app.access_start_time_sband = [];
            app.access_stop_time_sband = [];
            app.access_start_time_uhf = [];
            app.access_stop_time_uhf = [];
            
            app.peri_pass_time_init = [];          % First propagation
            app.peri_pass_time = [];          % 2end propagation
            
            % Maneuver
            app.maneuver_start_time = [];  
            app.maneuver_stop_time = [];
            
            % Activities Custom
            app.activityStartTime_custom = []; 
            app.activityStopTime_custom = [];
            
            % Attiude to STK
            app.sunpointing_start_time = [];
            app.sunpointing_stop_time = [];
            app.thrustpointing_start_time = [];
            app.thrustpointing_stop_time = [];
            app.targetpointing_start_time = [];
            app.targetpointing_stop_time = [];
            app.pointchange_start_time = [];
            app.pointchange_stop_time = [];
            app.pointchange_name = [];
            
            % Attitude data from stk
            app.attitude_start_time = []; % From all attitude profiles
            app.attitude_stop_time = [];
            app.sunpointing_startstop_time = []; % datetime array with [start time, stop time]
            app.thrustpointing_startstop_time = [];
            app.targetpointing_startstop_time = [];
            
            app.slew_startstop_time = [];  % start and stop times for all slew maneuvers
            
            % Sequence of events
            app.SoE = [];

            %% Clear Tables
            % OrbitPropagationTab
            app.PerigeePassDateTableInfo_1.Data = [];
            app.AccessTableInfo_1.Data = [];
            app.PerigeePassDateTableInfo_2.Data = [];

            % ManeuverSelectionTab
            app.ManOutputGlobalTable.Data = [];
            app.ManeuverOutputTable.Data = [];
            app.ManeuverInputTable.Data = [];

            % AcitvitySchedulerTab
            app.ActivityOutputTable.Data = [];
            app.AccessTableInfo_2.Data = [];

            % AttitudeProfileTab
            app.AttitudeGlobalOutputTable.Data = [];
            app.AttitudeOutputTable.Data = [];

            % SequenceofEventsTab
            app.ConflictOutputTable.Data = [];
            app.SoETable.Data = [];

            % ConstraintAnalysisTab_2
            app.ConstraintTextArea.Value = '';

            %% Clear Plots
            
            % MissionPlannerTab
            cla(app.SchedulePlot_1);

            % ConstraintAnalysisTab_2
            cla(app.SoCPlot_1);
            cla(app.RPMPlot_1);
            
            % PowerTab_2
            cla(app.SchedulePlot_2);
            cla(app.SoCPlot_2);
            cla(app.BattPowerPlot);
            cla(app.PowerConPlot);
            cla(app.PowerGenPlot);
            
            % ADCSTab_2
            cla(app.SchedulePlot_3);
            cla(app.YPRratesPlot);
            cla(app.YPRPlot);
            cla(app.RPMPlot_2);
            cla(app.AngularMomPlot);

            %% Clear Astrogator and Attitude Profile and set initial state
            setInitState(app,app.scenario_start_time);
            app.root.ExecuteCommand('SetAttitude */Satellite/CLIMB ClearData AllProfiles');
        end

        % Cell edit callback: AccessTableInfo_2
        function AccessTableInfo_2CellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            % app.AccessTableInfo_2.ColumnFormat = {[] [] [] [] {'UHF' 'S-Band'}};    % set format for columns, {} creates drop down menu
            % app.AccessTableInfo_2 = uitable(app.CustomAcitvityTab);
            % app.AccessTableInfo_2.ColumnName = {'Access Start'; 'Access End'; 'Access duration'; 'Access Select'; 'Access Type'};
            % app.AccessTableInfo_2.RowName = {};
            % app.AccessTableInfo_2.ColumnEditable = [false false false true true];
            % app.AccessTableInfo_2.CellEditCallback = createCallbackFcn(app, @AccessTableInfo_2CellEdit, true);
            % app.AccessTableInfo_2.Position = [640 24 519 429];


        end

        % Button pushed function: DeleteManeuversButton
        function DeleteManeuversButtonPushed(app, event)
            %% Description

            %%
            app.maneuver_start_time = [];
            app.maneuver_stop_time = [];
            app.access_start_time = [];
            app.access_stop_time = [];
            app.peri_pass_time = [];
            
            app.ManeuverOutputTable = [];
            app.ManOutputGlobalTable.Data = [];
            app.AccessTableInfo_2.Data = [];
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %% Description
            % Compares perigee pass and access times of initial propagation
            % (without maneuvers) to 2end propagaiton (with manuevers) to
            % see change due to orbital maneuvers

            %% Process data and visualize in table PerigeeComparisonTable and AccessComparisonTable
            
            % Make peri_pass_time_init and peri_pass_time the same length
            lendif = length(app.peri_pass_time)-length(app.peri_pass_time_init);
            app.peri_pass_time_init(end+1:end+lendif) = NaT;
            
            % Make access_start_time_init and access_start_time the same length
            lendif = length(app.access_start_time)-length(app.access_start_time_init);
            app.access_start_time_init(end+1:end+lendif) = NaT;
            
            % Vusualize in table
            app.PerigeeComparisonTable.Data = table([1:length(app.peri_pass_time)]',app.peri_pass_time_init, app.peri_pass_time, app.peri_pass_time-app.peri_pass_time_init);
            app.AccessComparisonTable.Data = table([1:length(app.access_start_time)]',app.access_start_time_init, app.access_start_time, app.access_start_time-app.access_start_time_init);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MissionPlanningToolUIFigure = uifigure('Visible', 'off');
            app.MissionPlanningToolUIFigure.Name = 'CLIMB Mission Planning Tool';
            app.PowertoolMenu = uimenu(app.MissionPlanningToolUIFigure);
            app.PowertoolMenu.Text = 'Power tool';
            app.PowermodesMenu = uimenu(app.PowertoolMenu);
            app.PowermodesMenu.Text = 'Power modes';
            app.PowerconsumptiondataMenu = uimenu(app.PowertoolMenu);
            app.PowerconsumptiondataMenu.Text = 'Power consumption data';
            app.BatterymodeldataMenu = uimenu(app.PowertoolMenu);
            app.BatterymodeldataMenu.Text = 'Battery model data';
            app.TorquetoolMenu = uimenu(app.MissionPlanningToolUIFigure);
            app.TorquetoolMenu.Text = 'Torque tool';
            app.TorquetooldataMenu = uimenu(app.TorquetoolMenu);
            app.TorquetooldataMenu.Text = 'Torque tool data';
            app.Menu = uimenu(app.MissionPlanningToolUIFigure);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.MissionPlanningToolUIFigure);
            app.MissionPlannerTab = uitab(app.TabGroup);
            app.MissionPlannerTab.Title = 'Mission Planner';
            app.SchedulePlot_1 = uiaxes(app.MissionPlannerTab);
            title(app.SchedulePlot_1, 'Schedule Plot')
            zlabel(app.SchedulePlot_1, 'Z')
            app.UpdateSchedulePlotButton = uibutton(app.MissionPlannerTab, 'push');
            app.UpdateSchedulePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSchedulePlotButtonPushed, true);
            app.UpdateSchedulePlotButton.Text = 'Update Schedule Plot';
            app.TabGroup2 = uitabgroup(app.MissionPlannerTab);
            app.InitialStateTab = uitab(app.TabGroup2);
            app.InitialStateTab.Title = 'Initial State';
            app.TLEInfoTable = uitable(app.InitialStateTab);
            app.TLEInfoTable.ColumnName = {''; ''};
            app.TLEInfoTable.RowName = {};
            app.LoadmostrecentTLEButton = uibutton(app.InitialStateTab, 'push');
            app.LoadmostrecentTLEButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmostrecentTLEButtonPushed, true);
            app.LoadmostrecentTLEButton.Text = 'Load most recent TLE';
            app.NORADIDLabel = uilabel(app.InitialStateTab);
            app.NORADIDLabel.Text = 'NORAD ID';
            app.NORADIDEditField = uieditfield(app.InitialStateTab, 'numeric');
            app.NORADIDEditField.ValueDisplayFormat = '%.0f';
            app.NORADIDEditField.Value = 56937;
            app.ShowcurrentTLEButton = uibutton(app.InitialStateTab, 'push');
            app.ShowcurrentTLEButton.ButtonPushedFcn = createCallbackFcn(app, @ShowcurrentTLEButtonPushed, true);
            app.ShowcurrentTLEButton.Text = 'Show current TLE';
            app.StartSTKButton = uibutton(app.InitialStateTab, 'push');
            app.StartSTKButton.ButtonPushedFcn = createCallbackFcn(app, @StartSTKButtonPushed, true);
            app.StartSTKButton.Text = 'Start STK';
            app.ShowCLIMBParametersButton = uibutton(app.InitialStateTab, 'push');
            app.ShowCLIMBParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCLIMBParametersButtonPushed, true);
            app.ShowCLIMBParametersButton.Text = 'Show CLIMB Parameters';
            app.CLIMBParamTable = uitable(app.InitialStateTab);
            app.CLIMBParamTable.ColumnName = {'Parameter'; 'Variable Name'; 'Value'};
            app.CLIMBParamTable.RowName = {};
            app.OrbitPropagationTab = uitab(app.TabGroup2);
            app.OrbitPropagationTab.Title = 'Orbit Propagation';
            app.AccessTableInfo_1 = uitable(app.OrbitPropagationTab);
            app.AccessTableInfo_1.ColumnName = {''; 'Access Window Start'; 'Access Window Start'; 'Accesss Duration (min)'; 'Max Elevation (deg)'};
            app.AccessTableInfo_1.RowName = {};
            app.PerigeePassDateTableInfo_1 = uitable(app.OrbitPropagationTab);
            app.PerigeePassDateTableInfo_1.ColumnName = {''; 'Perigee Pass Date'};
            app.PerigeePassDateTableInfo_1.RowName = {};
            app.PropagateButton = uibutton(app.OrbitPropagationTab, 'push');
            app.PropagateButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateButtonPushed, true);
            app.PropagateButton.Text = 'Propagate';
            app.ResetScenarioButton = uibutton(app.OrbitPropagationTab, 'push');
            app.ResetScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScenarioButtonPushed, true);
            app.ResetScenarioButton.Text = 'Reset Scenario';
            app.ScenarioStartTimeEditFieldLabel = uilabel(app.OrbitPropagationTab);
            app.ScenarioStartTimeEditFieldLabel.Text = 'Scenario Start  Time';
            app.ScenarioStartTimeEditField = uieditfield(app.OrbitPropagationTab, 'text');
            app.ScenarioStartTimeEditField.Value = '22 May 2024 20:15:04.320';
            app.ScenarioStopTimeEditFieldLabel = uilabel(app.OrbitPropagationTab);
            app.ScenarioStopTimeEditFieldLabel.Text = 'Scenario Stop Time';
            app.ScenarioStopTimeEditField = uieditfield(app.OrbitPropagationTab, 'text');
            app.ScenarioStopTimeEditField.Value = '23 May 2024 20:15:04.320';
            app.PerigeePassesLabel = uilabel(app.OrbitPropagationTab);
            app.PerigeePassesLabel.Text = 'Perigee Passes';
            app.AccessDataLabel = uilabel(app.OrbitPropagationTab);
            app.AccessDataLabel.Text = 'Access Data';
            app.ManeuverPropagationTab = uitab(app.TabGroup2);
            app.ManeuverPropagationTab.Title = 'Maneuver Propagation';
            app.TabGroup5 = uitabgroup(app.ManeuverPropagationTab);
            app.ApogeeRiseManeuverTab = uitab(app.TabGroup5);
            app.ApogeeRiseManeuverTab.Title = 'Apogee Rise Maneuver';
            app.ManeuverInputTable = uitable(app.ApogeeRiseManeuverTab);
            app.ManeuverInputTable.ColumnName = {''; 'Perigee Pass Time'; 'Maneuver Select'};
            app.ManeuverInputTable.RowName = {};
            app.ManeuverInputTable.ColumnEditable = [false false true];
            app.PropagateManeuversButton = uibutton(app.ApogeeRiseManeuverTab, 'push');
            app.PropagateManeuversButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateManeuversButtonPushed, true);
            app.PropagateManeuversButton.Text = 'Propagate Maneuvers';
            app.ManeuverOutputTable = uitable(app.ApogeeRiseManeuverTab);
            app.ManeuverOutputTable.ColumnName = {''; 'Start Time'; 'Stop Time'; 'Duration (min)'; 'Delta-V (m/s)'};
            app.ManeuverOutputTable.RowName = {};
            app.ManOutputGlobalTable = uitable(app.ApogeeRiseManeuverTab);
            app.ManOutputGlobalTable.ColumnName = {''; ''};
            app.ManOutputGlobalTable.RowName = {};
            app.ActivityNameEditFieldLabel_4 = uilabel(app.ApogeeRiseManeuverTab);
            app.ActivityNameEditFieldLabel_4.Text = 'Maneuver Output';
            app.ActivityNameEditFieldLabel_5 = uilabel(app.ApogeeRiseManeuverTab);
            app.ActivityNameEditFieldLabel_5.Text = 'Maneuver Selection';
            app.DeleteManeuversButton = uibutton(app.ApogeeRiseManeuverTab, 'push');
            app.DeleteManeuversButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteManeuversButtonPushed, true);
            app.DeleteManeuversButton.Text = 'Delete Maneuvers';
            app.ManeuverDurationinminEditFieldLabel = uilabel(app.ApogeeRiseManeuverTab);
            app.ManeuverDurationinminEditFieldLabel.Text = 'Maneuver Duration in (min)';
            app.ManeuverDurationinminEditField = uieditfield(app.ApogeeRiseManeuverTab, 'numeric');
            app.ManeuverDurationinminEditField.Value = 10;
            app.IspsEditFieldLabel = uilabel(app.ApogeeRiseManeuverTab);
            app.IspsEditFieldLabel.Text = 'Isp (s)';
            app.IspsEditField = uieditfield(app.ApogeeRiseManeuverTab, 'numeric');
            app.IspsEditField.Value = 1900;
            app.ThrustNEditFieldLabel = uilabel(app.ApogeeRiseManeuverTab);
            app.ThrustNEditFieldLabel.Text = 'Thrust (N)';
            app.ThrustNEditField = uieditfield(app.ApogeeRiseManeuverTab, 'numeric');
            app.ThrustNEditField.Value = 0.00035;
            app.ThrusterEfficiencyEditFieldLabel = uilabel(app.ApogeeRiseManeuverTab);
            app.ThrusterEfficiencyEditFieldLabel.Text = 'Thruster Efficiency';
            app.ThrusterEfficiencyEditField = uieditfield(app.ApogeeRiseManeuverTab, 'numeric');
            app.ThrusterEfficiencyEditField.Value = 1;
            app.PerigeeDecreaseManeuverTab = uitab(app.TabGroup5);
            app.PerigeeDecreaseManeuverTab.Title = 'Perigee Decrease Maneuver';
            app.ManeuverInputTable_2 = uitable(app.PerigeeDecreaseManeuverTab);
            app.ManeuverInputTable_2.ColumnName = {''; 'Perigee Pass Time'; 'Maneuver Select'};
            app.ManeuverInputTable_2.RowName = {};
            app.ManeuverInputTable_2.ColumnEditable = [false false true];
            app.ManeuverOutputTable_2 = uitable(app.PerigeeDecreaseManeuverTab);
            app.ManeuverOutputTable_2.ColumnName = {''; 'Start Time'; 'Stop Time'; 'Duration (min)'; 'Delta-V (m/s)'};
            app.ManeuverOutputTable_2.RowName = {};
            app.ActivityNameEditFieldLabel_6 = uilabel(app.PerigeeDecreaseManeuverTab);
            app.ActivityNameEditFieldLabel_6.Text = 'Maneuver Output';
            app.ActivityNameEditFieldLabel_7 = uilabel(app.PerigeeDecreaseManeuverTab);
            app.ActivityNameEditFieldLabel_7.Text = 'Maneuver Selection';
            app.ComparisonInitialStateTab = uitab(app.TabGroup5);
            app.ComparisonInitialStateTab.Title = 'Comparison Initial State';
            app.PerigeeComparisonTable = uitable(app.ComparisonInitialStateTab);
            app.PerigeeComparisonTable.ColumnName = {''; 'Initial Pass Time'; 'Final Pass Time'; 'Difference'};
            app.PerigeeComparisonTable.RowName = {};
            app.PerigeeComparisonTable.ColumnEditable = [false false true];
            app.AccessComparisonTable = uitable(app.ComparisonInitialStateTab);
            app.AccessComparisonTable.ColumnName = {''; 'Initial Start Time'; 'Final Start Time'; 'Difference'};
            app.AccessComparisonTable.RowName = {};
            app.AccessComparisonTable.ColumnEditable = [false false true];
            app.LoadDataButton = uibutton(app.ComparisonInitialStateTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PerigeePassTimeComparisonLabel = uilabel(app.ComparisonInitialStateTab);
            app.PerigeePassTimeComparisonLabel.Text = 'Perigee Pass Time Comparison';
            app.AccessStartTimeComparisonLabel = uilabel(app.ComparisonInitialStateTab);
            app.AccessStartTimeComparisonLabel.Text = 'Access Start Time Comparison';
            app.AccessWindowsTab = uitab(app.TabGroup2);
            app.AccessWindowsTab.Title = 'Access Windows';
            app.AccessTableInfo_2 = uitable(app.AccessWindowsTab);
            app.AccessTableInfo_2.ColumnName = {''; 'Start Time'; 'Stop Time'; 'Duration (min)'; 'Max El (deg)'; 'Az Start (deg)'; 'Az Stop (deg)'; 'Select'; 'Type'};
            app.AccessTableInfo_2.RowName = {};
            app.AccessTableInfo_2.ColumnEditable = [false false false true true false false true true];
            app.AccessTableInfo_2.CellEditCallback = createCallbackFcn(app, @AccessTableInfo_2CellEdit, true);
            app.ActivityNameEditFieldLabel_3 = uilabel(app.AccessWindowsTab);
            app.ActivityNameEditFieldLabel_3.Text = 'Ground Contact';
            app.GetAccessDataButton = uibutton(app.AccessWindowsTab, 'push');
            app.GetAccessDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetAccessDataButtonPushed, true);
            app.GetAccessDataButton.Text = 'Get Access Data';
            app.MinimumElevationindegEditFieldLabel = uilabel(app.AccessWindowsTab);
            app.MinimumElevationindegEditFieldLabel.Text = 'Minimum Elevation in deg';
            app.MinimumElevationindegEditField = uieditfield(app.AccessWindowsTab, 'numeric');
            app.CustomAcitvityTab = uitab(app.TabGroup2);
            app.CustomAcitvityTab.Title = 'Custom Acitvity';
            app.ActivityOutputTable = uitable(app.CustomAcitvityTab);
            app.ActivityOutputTable.ColumnName = {'Activity ID'; 'Activity Name'; 'Start Time'; 'Stop Time'; 'Duration (min)'; 'Power (W)'};
            app.ActivityOutputTable.RowName = {};
            app.AddActivityButton = uibutton(app.CustomAcitvityTab, 'push');
            app.AddActivityButton.ButtonPushedFcn = createCallbackFcn(app, @AddActivityButtonPushed, true);
            app.AddActivityButton.Text = 'Add Activity';
            app.ActivityNameEditFieldLabel = uilabel(app.CustomAcitvityTab);
            app.ActivityNameEditFieldLabel.Text = 'Activity Name';
            app.ActivityNameEditField = uieditfield(app.CustomAcitvityTab, 'text');
            app.ActivityNameEditField.Value = 'Science Activity';
            app.ActivityStartTimeEditFieldLabel = uilabel(app.CustomAcitvityTab);
            app.ActivityStartTimeEditFieldLabel.Text = 'Activity Start Time ';
            app.ActivityStartTimeEditField = uieditfield(app.CustomAcitvityTab, 'text');
            app.ActivityStartTimeEditField.Value = '23 May 2024 02:00:04.320';
            app.ActivityStopTimeEditFieldLabel = uilabel(app.CustomAcitvityTab);
            app.ActivityStopTimeEditFieldLabel.Text = 'Activity Stop Time ';
            app.ActivityStopTimeEditField = uieditfield(app.CustomAcitvityTab, 'text');
            app.ActivityStopTimeEditField.Value = '23 May 2024 02:15:04.320';
            app.PowerWEditFieldLabel = uilabel(app.CustomAcitvityTab);
            app.PowerWEditFieldLabel.Text = 'Power (W)';
            app.PowerWEditField = uieditfield(app.CustomAcitvityTab, 'numeric');
            app.ActivityNameEditFieldLabel_2 = uilabel(app.CustomAcitvityTab);
            app.ActivityNameEditFieldLabel_2.Text = 'Custom Activities';
            app.ClearallActivitiesButton = uibutton(app.CustomAcitvityTab, 'push');
            app.ClearallActivitiesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallActivitiesButtonPushed, true);
            app.ClearallActivitiesButton.Text = 'Clear all Activities';
            app.PointingDropDownLabel = uilabel(app.CustomAcitvityTab);
            app.PointingDropDownLabel.Text = 'Pointing';
            app.PointingDropDown = uidropdown(app.CustomAcitvityTab);
            app.PointingDropDown.Items = {'None', 'Nadir', 'FHWN', 'Sun', 'Velocity'};
            app.PointingDropDown.Value = 'None';
            app.AttitudeProfileTab = uitab(app.TabGroup2);
            app.AttitudeProfileTab.Title = 'Attitude Profile';
            app.LeadTimeminutesEditFieldLabel = uilabel(app.AttitudeProfileTab);
            app.LeadTimeminutesEditFieldLabel.Text = 'Lead Time (minutes)';
            app.LeadTimeminutesEditField = uieditfield(app.AttitudeProfileTab, 'numeric');
            app.LeadTimeminutesEditField.Value = 5;
            app.TrailTimeminutesEditFieldLabel = uilabel(app.AttitudeProfileTab);
            app.TrailTimeminutesEditFieldLabel.Text = 'Trail Time (minutes)';
            app.TrailTimeminutesEditField = uieditfield(app.AttitudeProfileTab, 'numeric');
            app.TrailTimeminutesEditField.Value = 5;
            app.LeadTimeminutesEditField_2Label = uilabel(app.AttitudeProfileTab);
            app.LeadTimeminutesEditField_2Label.Text = 'Lead Time (minutes)';
            app.LeadTimeminutesEditField_2 = uieditfield(app.AttitudeProfileTab, 'numeric');
            app.LeadTimeminutesEditField_2.Value = 5;
            app.TrailTimeminutesEditField_2Label = uilabel(app.AttitudeProfileTab);
            app.TrailTimeminutesEditField_2Label.Text = 'Trail Time (minutes)';
            app.TrailTimeminutesEditField_2 = uieditfield(app.AttitudeProfileTab, 'numeric');
            app.TrailTimeminutesEditField_2.Value = 5;
            app.DurationminutesEditFieldLabel = uilabel(app.AttitudeProfileTab);
            app.DurationminutesEditFieldLabel.Text = 'Duration (minutes)';
            app.AddAttitudeProfileButton = uibutton(app.AttitudeProfileTab, 'push');
            app.AddAttitudeProfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddAttitudeProfileButtonPushed, true);
            app.AddAttitudeProfileButton.Text = 'Add Attitude Profile';
            app.AttitudeOutputTable = uitable(app.AttitudeProfileTab);
            app.AttitudeOutputTable.ColumnName = {''; 'Name'; 'Start Time'; 'Stop Time'; 'Duration'; 'Type'};
            app.AttitudeOutputTable.RowName = {};
            app.ClearAttitudeButton = uibutton(app.AttitudeProfileTab, 'push');
            app.ClearAttitudeButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAttitudeButtonPushed, true);
            app.ClearAttitudeButton.Text = 'Clear Attitude';
            app.AttitudeGlobalOutputTable = uitable(app.AttitudeProfileTab);
            app.AttitudeGlobalOutputTable.ColumnName = '';
            app.AttitudeGlobalOutputTable.RowName = {};
            app.DurationminutesEditField = uieditfield(app.AttitudeProfileTab, 'numeric');
            app.DurationminutesEditField.Value = 5;
            app.TargetPointingLabel = uilabel(app.AttitudeProfileTab);
            app.TargetPointingLabel.Text = 'Target Pointing';
            app.ThrustPointingLabel = uilabel(app.AttitudeProfileTab);
            app.ThrustPointingLabel.Text = 'Thrust Pointing';
            app.PointingChangeManeuverLabel = uilabel(app.AttitudeProfileTab);
            app.PointingChangeManeuverLabel.Text = 'Pointing Change Maneuver';
            app.SequenceofEventsTab = uitab(app.TabGroup2);
            app.SequenceofEventsTab.Title = 'Sequence of Events';
            app.SoETable = uitable(app.SequenceofEventsTab);
            app.SoETable.ColumnName = {'ID'; 'Name'; 'Start Time'; 'Stop Time'; 'Duration'; 'Time to next Activity'};
            app.SoETable.RowName = {};
            app.CreateActivityScheduleButton = uibutton(app.SequenceofEventsTab, 'push');
            app.CreateActivityScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @CreateActivityScheduleButtonPushed, true);
            app.CreateActivityScheduleButton.Text = 'Create Activity Schedule';
            app.ConflictCheckButton = uibutton(app.SequenceofEventsTab, 'push');
            app.ConflictCheckButton.ButtonPushedFcn = createCallbackFcn(app, @ConflictCheckButtonPushed, true);
            app.ConflictCheckButton.Text = 'Conflict Check';
            app.ConflictOutputTable = uitable(app.SequenceofEventsTab);
            app.ConflictOutputTable.ColumnName = '';
            app.ConflictOutputTable.RowName = {};
            app.ExportSoEButton = uibutton(app.SequenceofEventsTab, 'push');
            app.ExportSoEButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSoEButtonPushed, true);
            app.ExportSoEButton.Text = 'Export SoE';
            app.ConstraintAnalysisTab_2 = uitab(app.TabGroup2);
            app.ConstraintAnalysisTab_2.Title = 'Constraint Analysis';
            app.RPMPlot_1 = uiaxes(app.ConstraintAnalysisTab_2);
            title(app.RPMPlot_1, 'RPM')
            zlabel(app.RPMPlot_1, 'Z')
            app.SoCPlot_1 = uiaxes(app.ConstraintAnalysisTab_2);
            title(app.SoCPlot_1, 'SoC')
            zlabel(app.SoCPlot_1, 'Z')
            app.ConstraintTextArea = uitextarea(app.ConstraintAnalysisTab_2);
            app.PowerConstraintAnalysisButton = uibutton(app.ConstraintAnalysisTab_2, 'push');
            app.PowerConstraintAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PowerConstraintAnalysisButtonPushed, true);
            app.PowerConstraintAnalysisButton.Text = 'Power Constraint Analysis';
            app.ADCSConstraintAnalysisButton = uibutton(app.ConstraintAnalysisTab_2, 'push');
            app.ADCSConstraintAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ADCSConstraintAnalysisButtonPushed, true);
            app.ADCSConstraintAnalysisButton.Text = 'ADCS Constraint Analysis';
            app.InitialAngularMomentumfromADCSinNmsLabel = uilabel(app.ConstraintAnalysisTab_2);
            app.InitialAngularMomentumfromADCSinNmsLabel.Text = 'Initial Angular Momentum from ADCS in Nms';
            app.InitialBatteryCapacityEditFieldLabel = uilabel(app.ConstraintAnalysisTab_2);
            app.InitialBatteryCapacityEditFieldLabel.Text = 'Initial Battery Capacity';
            app.InitialBatteryCapacityEditField = uieditfield(app.ConstraintAnalysisTab_2, 'numeric');
            app.xLabel = uilabel(app.ConstraintAnalysisTab_2);
            app.xLabel.Text = 'x ';
            app.xEditField = uieditfield(app.ConstraintAnalysisTab_2, 'numeric');
            app.yEditFieldLabel = uilabel(app.ConstraintAnalysisTab_2);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.ConstraintAnalysisTab_2, 'numeric');
            app.zEditFieldLabel = uilabel(app.ConstraintAnalysisTab_2);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.ConstraintAnalysisTab_2, 'numeric');
            app.ConsoleLogTextAreaLabel = uilabel(app.MissionPlannerTab);
            app.ConsoleLogTextAreaLabel.Text = 'Console Log:';
            app.ConsoleLogTextArea = uitextarea(app.MissionPlannerTab);
            app.ConsoleLogTextArea.Editable = 'off';
            app.ConsoleLogTextArea.Value = {'Logging'};
            app.ConstraintAnalysisTab = uitab(app.TabGroup);
            app.ConstraintAnalysisTab.Title = 'Constraint Analysis';
            app.TabGroup4 = uitabgroup(app.ConstraintAnalysisTab);
            app.PowerTab_2 = uitab(app.TabGroup4);
            app.PowerTab_2.Title = 'Power';
            app.SchedulePlot_2 = uiaxes(app.PowerTab_2);
            title(app.SchedulePlot_2, 'Schedule Plot')
            zlabel(app.SchedulePlot_2, 'Z')
            app.PowerGenPlot = uiaxes(app.PowerTab_2);
            title(app.PowerGenPlot, 'Power Generation')
            zlabel(app.PowerGenPlot, 'Z')
            app.PowerConPlot = uiaxes(app.PowerTab_2);
            title(app.PowerConPlot, 'Power Consumption')
            zlabel(app.PowerConPlot, 'Z')
            app.BattPowerPlot = uiaxes(app.PowerTab_2);
            title(app.BattPowerPlot, 'Battery Power Output')
            zlabel(app.BattPowerPlot, 'Z')
            app.SoCPlot_2 = uiaxes(app.PowerTab_2);
            title(app.SoCPlot_2, 'Battery SoC')
            zlabel(app.SoCPlot_2, 'Z')
            app.ADCSTab_2 = uitab(app.TabGroup4);
            app.ADCSTab_2.Title = 'ADCS';
            app.AngularMomPlot = uiaxes(app.ADCSTab_2);
            title(app.AngularMomPlot, 'Total Angular Momentum')
            zlabel(app.AngularMomPlot, 'Z')
            app.SchedulePlot_3 = uiaxes(app.ADCSTab_2);
            title(app.SchedulePlot_3, 'Schedule Plot')
            zlabel(app.SchedulePlot_3, 'Z')
            app.RPMPlot_2 = uiaxes(app.ADCSTab_2);
            title(app.RPMPlot_2, 'RPM')
            zlabel(app.RPMPlot_2, 'Z')
            app.YPRPlot = uiaxes(app.ADCSTab_2);
            title(app.YPRPlot, 'YPR')
            zlabel(app.YPRPlot, 'Z')
            app.YPRratesPlot = uiaxes(app.ADCSTab_2);
            title(app.YPRratesPlot, 'YPR Rates')
            zlabel(app.YPRratesPlot, 'Z')
            app.LongTermPropulsoinToolTab = uitab(app.TabGroup);
            app.LongTermPropulsoinToolTab.Title = 'Long-Term Propulsoin Tool';
            app.AltitudePlotLongTerm = uiaxes(app.LongTermPropulsoinToolTab);
            xlabel(app.AltitudePlotLongTerm, 'Days')
            ylabel(app.AltitudePlotLongTerm, 'Altitude in km')
            zlabel(app.AltitudePlotLongTerm, 'Z')
            app.ApogeeAltitudekmEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.ApogeeAltitudekmEditFieldLabel.Text = 'Apogee Altitude (km)';
            app.ApogeeAltitudekmEditField = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.ApogeeAltitudekmEditField.Value = 600;
            app.MaxIterationsEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.MaxIterationsEditField.Value = 5000;
            app.ManeuverDurationinminEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.ManeuverDurationinminEditField_2Label.Text = 'Maneuver Duration in (min)';
            app.ManeuverDurationinminEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.ManeuverDurationinminEditField_2.Value = 10;
            app.IspsEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.IspsEditField_2Label.Text = 'Isp (s)';
            app.IspsEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.IspsEditField_2.Value = 1900;
            app.ThrustNEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.ThrustNEditField_2Label.Text = 'Thrust (N)';
            app.ThrustNEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.ThrustNEditField_2.Value = 0.00035;
            app.ThrusterEfficiencyEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.ThrusterEfficiencyEditField_2Label.Text = 'Thruster Efficiency';
            app.ThrusterEfficiencyEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.ThrusterEfficiencyEditField_2.Value = 1;
            app.StopTimeEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.LongTermPropulsoinToolTab, 'text');
            app.StopTimeEditField.Value = '6 Aug 2024 20:15:04.320';
            app.StartTimeEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.LongTermPropulsoinToolTab, 'text');
            app.StartTimeEditField.Value = '6 Aug 2023 20:15:04.320';
            app.LongTermPropagateButton = uibutton(app.LongTermPropulsoinToolTab, 'push');
            app.LongTermPropagateButton.ButtonPushedFcn = createCallbackFcn(app, @LongTermPropagateButtonPushed, true);
            app.LongTermPropagateButton.Text = 'Propagate';
            app.StoppingConditionLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.StoppingConditionLabel.Text = 'Stopping Condition';
            app.SkipeveryxmaneuverEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.SkipeveryxmaneuverEditFieldLabel.Text = 'Skip every x maneuver';
            app.SkipeveryxmaneuverEditField = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.ManueverGlobalLongTermTable = uitable(app.LongTermPropulsoinToolTab);
            app.ManueverGlobalLongTermTable.ColumnName = '';
            app.ManueverGlobalLongTermTable.RowName = {};
            app.ClearAstrogatorButton = uibutton(app.LongTermPropulsoinToolTab, 'push');
            app.ClearAstrogatorButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAstrogatorButtonPushed, true);
            app.ClearAstrogatorButton.Text = 'Clear Astrogator';
            app.ManeuverSettingsLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.ManeuverSettingsLabel.Text = 'Maneuver Settings';
            app.CheckBox = uicheckbox(app.LongTermPropulsoinToolTab);
            app.CheckBox.Text = '';
            app.SkipeveryxmaneuverEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.SkipeveryxmaneuverEditField_2Label.Text = 'Skip every x maneuver';
            app.SkipeveryxmaneuverEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.UsePerigeedecreasemaneuversLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.UsePerigeedecreasemaneuversLabel.Text = 'Use Perigee decrease maneuvers';
            % Show the figure after all components are created
            app.MissionPlanningToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mission_Planning_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MissionPlanningToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MissionPlanningToolUIFigure)
        end
    end
end