classdef Predictor_app_v3_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLIMBPredictorUIFigure          matlab.ui.Figure
        PowertoolMenu                   matlab.ui.container.Menu
        PowermodesMenu                  matlab.ui.container.Menu
        PowerconsumptiondataMenu        matlab.ui.container.Menu
        BatterymodeldataMenu            matlab.ui.container.Menu
        TorquetoolMenu                  matlab.ui.container.Menu
        TorquetooldataMenu              matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ScheduleTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        InitialStateTab                 matlab.ui.container.Tab
        PerigeePassesLabel              matlab.ui.control.Label
        AccessDataLabel                 matlab.ui.control.Label
        ConsoleLogTextArea              matlab.ui.control.TextArea
        ConsoleLogTextAreaLabel         matlab.ui.control.Label
        PerigeePassDateTableInfo        matlab.ui.control.Table
        AccessTableInfo_1               matlab.ui.control.Table
        ScenarioStopTimeEditField       matlab.ui.control.EditField
        ScenarioStopTimeEditFieldLabel  matlab.ui.control.Label
        ScenarioStartTimeEditField      matlab.ui.control.EditField
        ScenarioStartTimeEditFieldLabel  matlab.ui.control.Label
        PropagateButton                 matlab.ui.control.Button
        TLEInfoRequestButton            matlab.ui.control.Button
        TLEInfoTable                    matlab.ui.control.Table
        ManeuverSelectionTab            matlab.ui.container.Tab
        ThrusterEfficiencyEditField     matlab.ui.control.NumericEditField
        ThrusterEfficiencyEditFieldLabel  matlab.ui.control.Label
        ActivityNameEditFieldLabel_5    matlab.ui.control.Label
        ActivityNameEditFieldLabel_4    matlab.ui.control.Label
        SetforallManeuversButton        matlab.ui.control.Button
        ThrustNEditField                matlab.ui.control.NumericEditField
        ThrustNEditFieldLabel           matlab.ui.control.Label
        IspsEditField                   matlab.ui.control.NumericEditField
        IspsEditFieldLabel              matlab.ui.control.Label
        ManeuverDurationinminEditField  matlab.ui.control.NumericEditField
        ManeuverDurationinminEditFieldLabel  matlab.ui.control.Label
        ManOutputGlobalTable            matlab.ui.control.Table
        ManeuverOutputTable             matlab.ui.control.Table
        PropagateManeuversButton        matlab.ui.control.Button
        ManeuverInputTable              matlab.ui.control.Table
        AcitvitySchedulerTab            matlab.ui.container.Tab
        ActivityNameEditFieldLabel_3    matlab.ui.control.Label
        ActivityNameEditFieldLabel_2    matlab.ui.control.Label
        PowerWEditField                 matlab.ui.control.NumericEditField
        PowerWEditFieldLabel            matlab.ui.control.Label
        ActivityStopTimeEditField       matlab.ui.control.EditField
        ActivityStopTimeEditFieldLabel  matlab.ui.control.Label
        ActivityStartTimeEditField      matlab.ui.control.EditField
        ActivityStartTimeEditFieldLabel  matlab.ui.control.Label
        ActivityNameEditField           matlab.ui.control.EditField
        ActivityNameEditFieldLabel      matlab.ui.control.Label
        PerigeePassDateTableInfo_2      matlab.ui.control.Table
        AddActivityButton               matlab.ui.control.Button
        ActivityOutputTable             matlab.ui.control.Table
        AccessTableInfo_2               matlab.ui.control.Table
        SequenceofEventsTab             matlab.ui.container.Tab
        ConflictOutputTable             matlab.ui.control.Table
        ConflictCheckButton             matlab.ui.control.Button
        CreateActivityScheduleButton    matlab.ui.control.Button
        SoETable                        matlab.ui.control.Table
        PlotScheduleButton              matlab.ui.control.Button
        SchedulePlot_2                  matlab.ui.control.UIAxes
        LongTermPropulsoinToolTab       matlab.ui.container.Tab
        PropagateButton_2               matlab.ui.control.Button
        StartTimeEditField              matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        StopTimeEditField               matlab.ui.control.EditField
        StopTimeEditFieldLabel          matlab.ui.control.Label
        ThrusterEfficiencyEditField_2   matlab.ui.control.NumericEditField
        ThrusterEfficiencyEditField_2Label  matlab.ui.control.Label
        ThrustNEditField_2              matlab.ui.control.NumericEditField
        ThrustNEditField_2Label         matlab.ui.control.Label
        IspsEditField_2                 matlab.ui.control.NumericEditField
        IspsEditField_2Label            matlab.ui.control.Label
        ManeuverDurationinminEditField_2  matlab.ui.control.NumericEditField
        ManeuverDurationinminEditField_2Label  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        ApogeeAltitudekmEditField       matlab.ui.control.NumericEditField
        ApogeeAltitudekmEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ConstraintAnalysisTab           matlab.ui.container.Tab
        Calculatefortheupcoming72hoursbasedonthecurrenttimetableLabel  matlab.ui.control.Label
        CreateSTKButton                 matlab.ui.control.Button
    end


    properties (Access = public) % To keep STK open after button release
       % Global Variables that can be used everywhere within the code with app.variable
       % First Propagation without manuevers, 2nd with maneuvers

       % General
       STKApp
       CLIMB            % Climb satellite object from STK
       root
       TLE_Epoch        % TLE Epoch
       Orbit_data_array % TLE orbital elements from function TLE_processing_fcn

       %Scenario
       Scenario_Start_Time      % Scenario Start Time
       Scenario_Stop_Time       % Scenario Stop Time

       % Access
       accessStartTimes_str     % First propagation
       accessStopTimes_str      % First propagation
       accessStartTimes_1       % First propagation
       accessStopTimes_1        % First propagation
       accessStartTimes_2       % 2nd propagation
       accessStopTimes_2        % 2nd propagation

       % Perigee
       peri_pass_time_1            % First propagation
       peri_pass_time_2            % 2end propagation

       % Maneuver
       maneuver_start_time  
       maneuver_stop_time
        
       % Activities Custom
       activityStartTime_custom 
       activityStopTime_custom

       % SoE
       SoE      % Sequence of Events (tabel with all activitites together

    end


    properties (Access = private)
        DialogApp % Description
    end
    methods (Access = public)

        function ConsoleLog(app,newstr)
            app.ConsoleLogTextArea.Value =  [app.ConsoleLogTextArea.Value; newstr];
            scroll(app.ConsoleLogTextArea, 'bottom');
            %app.ConsoleLogTextArea.Value = str;
        end

        
        function [OE_array,TLE_epoch,epoch_jul] = TLE_processing_fcn(app,fname)
        % This function takes TLE as input from txt file and extracts orbital elements
        % Source: https://de.mathworks.com/matlabcentral/fileexchange/39364-two-line-element
        
        %   Epoch_vect = epoch
                
                fid = fopen(fname, 'r');        % Open the TLE file for reading
                OE_array = [];                  % Initialize an empty array to store orbital elements
                TLE_epoch = [];
                mu = 398600; % Standard gravitational parameter for the earth
                
                % Read and process TLE sets from the file
                while ~feof(fid)
                % while fopen(fid)
            
                    % Read two lines from the file
                    L1c = fgets(fid);
                    L2c = fgets(fid);
                    
                    % Display the two lines
                    %fprintf(L1c);
                    %fprintf(L2c);
                    
                    % Extract relevant data from the lines
                    %L1 = sscanf(L1c,'%d%6d%*c%9d%*c%*2f%f%f%5d%*c%*d%5d%*c%*d%d%5d',[1,9])  % deletes first two digits (year) of epoch
            
                    L1 = sscanf(L1c,'%d%6d%*c%9d%*c%f%f%5d%*c%*d%5d%*c%*d%d%5d%*2f',[1,9]);   % keeps first two digits (year) of epoch
                    L2 = sscanf(L2c,'%d%6d%f%f%f%f%f%f%f',[1,8]);
                    
                    % Epoch
                    epoch_jul = L1(1,4);   % Epoch Date and Julian Date Fraction
                    
                    % Apparently there is no direct way in matlab to convert tle epoch
                    % in date, therefore the apporach below is used (there is for sure a simpler way)
                    epoch_str =  num2str(epoch_jul);                            % convert tle to str
                    year = str2num(epoch_str(1:2))+2000;                        % extract number of year (first 2 digits)
                    epoch_dayfract = str2num(epoch_str(3:end));                % exctract the other digits (day fraction)
                    date_0 = append('1 Jan ',num2str(year),' 00:00:00.000');    % combine it and convert to date
                    epoch = char(datetime(date_0,'Format','d MMM uuuu HH:mm:ss.SSS') + days(epoch_dayfract-1)); % actual date of TLE
            
                    
                    epoch_dayfract = epoch_dayfract + 3;
                    if epoch_dayfract > 365
                        epoch_dayfract = epoch_dayfract - 365;
                        year = year + 1;
                    end
            
                    date_0 = append('1 Jan ',num2str(year),' 00:00:00.000');    % combine it and convert to date
                    End_Time = char(datetime(date_0,'Format','d MMM uuuu HH:mm:ss.SSS') + days(epoch_dayfract-1)); 
                    
            
                    % Orbital elements
                    Db = L1(1,5);          % Ballistic Coefficient
                    inc = L2(1,3);         % Inclination [deg]
                    RAAN = L2(1,4);        % Right Ascension of the Ascending Node [deg]
                    e = L2(1,5)/1e7;       % Eccentricity
                    w = L2(1,6);           % Argument of periapsis [deg]
                    M = L2(1,7);           % Mean anomaly [deg]
                    n = L2(1,8);           % Mean motion [Revs per day]
            
                    a = (mu/(n*2*pi/(24*3600))^2)^(1/3);  % Semi-major axis [km]
                    ra = a*(e+1)-6378;      % Apogee
                    rp = a*(1-e)-6378;      % Perigee
                
                    % Calculate the eccentric anomaly using Mean anomaly
                    err = 1e-10;  % Calculation Error
                    M_rad = M*pi/180;
                    E0 = M;
                    t = 1;
                    itt = 0;
                    while (t)
                        E = M + e*sind(E0);     % Eccentrcic anomaly
                        if (abs(E-E0) < err)
                            t = 0;
                        end
                        E0 = E;
                        itt = itt + 1;
                    end
                    
                    % get true anoamly
                    theta = acosd((cosd(E)-e)/(1-e*cosd(E)));   % Not 100% sure this is correct
            
                    % Six orbital elements
                    OE = [a e inc ra rp RAAN w E n Db M];
                    OE_array = [OE_array; OE];                  % Append OE to the array
                    TLE_epoch = [TLE_epoch; epoch];
                   
                end
                
                % Close the file
                fclose(fid);
            end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PropagateButton
        function PropagateButtonPushed(app, event)
            %% Description
            % Takes TLE and extracts orbital elements with function (TLE_processing_fcn) and
            % uses them as input for HPOP. User enters scenario start and stop time
            % for which HPOP propagates orbit. Output are access and
            % perigee pass information and displays it in tables and a graph. 
            % 
            % Takes TLE, Time and Attitude from latest status from the satellite
            % Take user Input in the form of [Time, Maneuvre] as well as Start time
            % Runs STK scenario on these Inputs
            % Outputs TLE and Attitude as well as Power generated, Angular
            % acceleration, deltaV all over time for the next 72 hours%% Get Inputs
            %% Get Inputs from TLE and scenario start/stop time
            disp("Getting Input files")
            app.ConsoleLog("Getting Input files");
            
            % TLE----------------
            TLE_file = 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\TLE_PEGASUS_07082023.txt';  % TLE file path\name
            [app.Orbit_data_array,app.TLE_Epoch] = TLE_processing_fcn(app,TLE_file); % function that extracts orbital elements from TLEs
            
            % Time----------------
            % Scenario time input
            app.Scenario_Start_Time = app.ScenarioStartTimeEditField.Value;
            app.Scenario_Stop_Time = app.ScenarioStopTimeEditField.Value;
            % start_time_value = datetime(app.Scenario_Start_Time, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % stop_time_value=datetime(app.Scenario_Stop_Time, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');

            % % For display and attitude input only
            % Start_Time_period=datetime(start_time_value, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % Start_Time_period=cellstr(Start_Time_period);
            % Stop_Time_period=datetime(app.End_Time, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % Stop_Time_period=cellstr(Stop_Time_period);
            % scenario_period = table(Start_Time_period, Stop_Time_period);
            % app.UITable3.Data=scenario_period;
            
            %% Connect to STK
            if isempty(app.STKApp) || ~iscom(app.STKApp)
                    app.STKApp = actxserver('STK12.Application');
                    %app.STKApp.visible = false;
            end
                app.root = app.STKApp.Personality2;
                
            %% Open Scenario and set scenario time
            disp('Opening STK Scenario')
            app.ConsoleLog("Opening STK Scenario");
            
            scenario_file = 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\CLIMB Operation\CLIMB_scenario_v2\CLIMB_scenario1.sc';
            
            app.root.LoadScenario(scenario_file)
            scenario = app.root.CurrentScenario;
            scenario.SetTimePeriod(app.Scenario_Start_Time,app.Scenario_Stop_Time);
            scenario.StartTime = app.Scenario_Start_Time;
            scenario.StopTime = app.Scenario_Stop_Time;
            app.root.ExecuteCommand('Animate * Reset');
            
            %% Open Objects
            disp('Opening Objects')
            app.ConsoleLog("Opening Objects");
            
            app.CLIMB = scenario.Children.Item('CLIMB');       % Open Satellite CLIMB
            FHWN = scenario.Children.Item('FHWN');             % Ground Station
            Sensor = FHWN.Children.Item('Sensor');               % Sensor on ground station, cone half angle set in STK scenario
            % Access computed to FHWN (access diretly above horizon)
            % Access computed to Sensor (access x deg above horizon, x -> cone half angle adjusted in stk scenario)
            
            %% Set propagator, initial state and propagate over scenario timeframe

            % IAgSatellite satellite: Satellite object
            % HPOP Propagator (from code snipets:
            % https://help.agi.com/stkdevkit/11.4.0/Content/stkObjects/ObjModMatlabCodeSamples.htm)
            app.CLIMB.SetPropagatorType('ePropagatorHPOP');
            set(app.CLIMB.Propagator,'Step',60);       % Propagator time steps in seconds
            
            propagator = app.CLIMB.Propagator.InitialState.Representation.ConvertTo('eOrbitStateClassical');
            propagator.AssignCartesian('eCoordinateSystemTrueOfDate',6678.14,0,0,0,6.78953,3.68641);
            propagator.SizeShapeType = 'eSizeShapeAltitude';
            propagator.LocationType = 'eLocationMeanAnomaly';
            propagator.Orientation.AscNodeType = 'eAscNodeRAAN';

            % Additional HPOP Settings
            % forceModel = app.CLIMB.Propagator.ForceModel;
            % forceModel.CentralBodyGravity.File = 'C:\Program Files\AGI\STK 12\STKData\CentralBodies\Earth\WGS84_EGM96.grv';
            % forceModel.CentralBodyGravity.MaxDegree = 21;
            % forceModel.CentralBodyGravity.MaxOrder = 21;
            % forceModel.Drag.Use=1;
            % forceModel.Drag.DragModel.Cd=0.01;
            % forceModel.Drag.DragModel.AreaMassRatio=0.01;
            % forceModel.SolarRadiationPressure.Use=0;
            
            % integrator = app.CLIMB.Propagator.Integrator;
            % integrator.DoNotPropagateBelowAlt=-1e6;
            % integrator.IntegrationModel=3;
            % integrator.StepSizeControl.Method=1;
            % integrator.StepSizeControl.ErrorTolerance=1e-13;
            % integrator.StepSizeControl.MinStepSize=0.1;
            % integrator.StepSizeControl.MaxStepSize=30;
            % integrator.Interpolation.Method=1;
            % integrator.Interpolation.Order=7;
            
            % Initial State from TLE
            ra = app.Orbit_data_array(:,4);
            rp = app.Orbit_data_array(:,5);
            ecc = app.Orbit_data_array(:,2);
            Incl = app.Orbit_data_array(:,3);
            RAAN = app.Orbit_data_array(:,6);
            ArgPeri = app.Orbit_data_array(:,7);
            MeanAno = app.Orbit_data_array(:,11);
            
            propagator.SizeShape.ApogeeAltitude = ra;      % Apogee
            propagator.SizeShape.PerigeeAltitude = rp;     % Perigee 
            propagator.Orientation.Inclination = Incl;     % Inclination
            propagator.Orientation.AscNode.Value = RAAN;   % RAAN
            propagator.Orientation.ArgOfPerigee = ArgPeri; % Arg. Perigee
            propagator.Location.Value = MeanAno;           % True Anomaly
            
            % propagate
            app.CLIMB.Propagator.InitialState.Representation.Assign(propagator);
            app.CLIMB.Propagator.Propagate;


            %% Access Report and Visualization in Tabel: AccessTableInfo_1
            % Get acces start/stop time and duration
            disp('Getting access windows')
            app.ConsoleLog("Getting access windows");
            
            % Access computed to FHWN (access diretly above horizon)
            % Access computed to Sensor (access x deg above horizon, x -> cone half angle adjusted in stk scenario)
            access = app.CLIMB.GetAccessToObject(FHWN);
            %access = app.CLIMB.GetAccessToObject(Sensor);

            access.ComputeAccess;
            accessDP = access.DataProviders.Item('Access Data').Exec(scenario.StartTime,scenario.StopTime);
            
            % Start time
            app.accessStartTimes_1=datetime(accessDP.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.accessStartTimes_str = datestr(app.accessStartTimes_1, 'dd mmm yyyy HH:MM:SS');
            app.accessStartTimes_str = cellstr(app.accessStartTimes_str);

            % Stop time
            app.accessStopTimes_1=datetime(accessDP.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.accessStopTimes_str= datestr(app.accessStopTimes_1, 'dd mmm yyyy HH:MM:SS');
            app.accessStopTimes_str=cellstr(app.accessStopTimes_str);

            % Duration
            app.root.UnitPreferences.Item('Time').SetCurrentUnit('Min');
            accessDuration = accessDP.DataSets.GetDataSetByName('Duration').GetValues;
            
            % Max Elevation -> No report for max elev found in STK -> wtf?
            % Would need elevation report for every access and than maxEl = max(elevetion_report);
            % maneuver_output_report= app.CLIMB.DataProviders.Item('Maneuver Summary').Exec(app.Start_Time,app.End_Time);
            % app.maneuver_start_time_out=datetime(maneuver_output_report.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % maxElevation = accessDP.DataSets.GetDataSetByName('Start Time').GetValues;  % No report for max el!!!?!?!?!? annoying
            
            % Visualize access data in Table: AccessTableInfo_1
            access_id = 1:length(accessDuration);   % Access numbering
            app.AccessTableInfo_1.Data = table(access_id',app.accessStartTimes_1,app.accessStopTimes_1,accessDuration);%,maxElevation);

            
            %% Perigee Passes Report
            disp('Getting pergiee passes')
            app.ConsoleLog("Getting perigee passes");

            % Get Perigee pass data
            peri_pass_DP = app.CLIMB.DataProviders.Item('Passes').Exec(scenario.StartTime,scenario.StopTime);
            app.peri_pass_time_1=datetime(peri_pass_DP.DataSets.GetDataSetByName('Time of Perigee').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            
            % Create table for maneuvers selection with checkbox and
            % overwrite check box to deselect all manuevers
            % IMPORTANT: deselect checkbox doesnt work!
            % IMPORTANT: If no manuever is selected, error for access report in second propagation
            per_pass_cell = cellstr(app.peri_pass_time_1);
            peri_pass_table = table(per_pass_cell);
            checkboxColumn2 = true(height(peri_pass_table), 1); % create logical array for checkbox
            peri_pass_table = [peri_pass_table,array2table(checkboxColumn2)];   % Add checkbox column (matlab makes checkbox from logical array)
            peri_pass_table = [{'Remove all burns (Overwrite)', false}; peri_pass_table];
            peri_pass_table(height(peri_pass_table),:) = [];
            
            % Visualize data in Tables: ManeuverInputTable, PerigeePassDateTableInfo
            app.ManeuverInputTable.Data = peri_pass_table;         % Table for maneuver input
            app.PerigeePassDateTableInfo.Data = table([1:length(app.peri_pass_time_1)]',app.peri_pass_time_1);   % Table for perigee pass times
            
            %% Schedule Plot
            %-----------------------------------------------------------------
            %------------------- Plot-----------------------------------------
            %-----------------------------------------------------------------

            activity_names = {'Contact Windows', 'Perigee Passes'}; % for visualization on y-axis
            
            access_start = app.accessStartTimes_1;
            access_stop = app.accessStopTimes_1;
            
            % Plot in app designer -> plot(app.SchedulePlot,x,y) 
            ax = app.SchedulePlot_2;
            plot(ax,app.peri_pass_time_1,2,'+','linewidth',0.5,'color','blue')
            plot(ax,[access_start access_stop],[1 1], 'linewidth',5,'color','green')
            
            ylim(ax, [0,3]);

            % Set y-axis labels
            yticks(ax,[1 2]);  % Position the ticks at the same locations as before
            yticklabels(ax,activity_names);            % Use activity names as labels

            %%  Console Log
            disp('App is running') % For progress track after button push
            app.ConsoleLog("App is running");
   
        end

        % Button pushed function: PropagateManeuversButton
        function PropagateManeuversButtonPushed(app, event)
         
%--------------------------------------------------------------------------------
%----------------------------- Second Propagation Button Pushed -----------------
%--------------------------------------------------------------------------------

            %% Get Scencario and Objects
            scenario = app.root.CurrentScenario;
            FHWN = scenario.Children.Item('FHWN');
            Sensor = FHWN.Children.Item('Sensor');

            %% Set Propagator and Engine

            % IAgSatellite satellite: Satellite object
            % Astrogator Propagator (from code snipets:
            % https://help.agi.com/stkdevkit/11.4.0/Content/stkObjects/ObjModMatlabCodeSamples.htm)
            app.CLIMB.SetPropagatorType('ePropagatorAstrogator');
            driver = app.CLIMB.Propagator;
            driver.MainSequence.RemoveAll();    % Clear all segments from the MCS

            % Get the Engine Models Folder
            engine = scenario.ComponentDirectory.GetComponents('eComponentAstrogator').GetFolder('Engine Models');
            engine_model = engine.Item('Constant Thrust and Isp');
            engine_model_clone = engine_model.CloneObject;

            % Grab a handle of the new Engine Model and edit properties
            Engine = engine.Item('Constant Thrust and Isp1');
            %Engine.Name = 'Enpulsion';
            Engine.Thrust = 0.00035;                         % Thrust - [N]
            Engine.Isp = 1900;       

            
            %% Create Sequence

            %-------------------------Insert an initial state--------------------------
            initState1 = driver.MainSequence.Insert('eVASegmentTypeInitialState','Initail State','-');
            initState1.OrbitEpoch = app.Scenario_Start_Time;
            % propagator = app.CLIMB.Propagator.InitialState.Representation.ConvertTo('eOrbitStateClassical');
            % propagator.AssignCartesian('eCoordinateSystemTrueOfDate',6678.14,0,0,0,6.78953,3.68641);
            % propagator.SizeShapeType = 'eSizeShapeAltitude';
            % propagator.LocationType = 'eLocationMeanAnomaly';
            % propagator.Orientation.AscNodeType = 'eAscNodeRAAN';
            
 
            % Initial State
            ra = app.Orbit_data_array(:,4);
            rp = app.Orbit_data_array(:,5);
            ecc = app.Orbit_data_array(:,2);
            Incl = app.Orbit_data_array(:,3);
            RAAN = app.Orbit_data_array(:,6);
            ArgPeri = app.Orbit_data_array(:,7);
            MeanAno = app.Orbit_data_array(:,11);

            % Elements:
            initState1.SetElementType('eVAElementTypeKeplerian');
            kep = initState1.Element;
            kep.ApoapsisAltitudeSize = ra;
            kep.Eccentricity = ecc;
            kep.Inclination = Incl;
            kep.RAAN = RAAN;
            kep.ArgOfPeriapsis = ArgPeri;
            kep.MeanAnomaly = MeanAno;
            
            % Spacecraft Parameters:
            sc = initState1.SpacecraftParameters;
            sc.DryMass = 4.5;                                      % dry mass - kg
            sc.Cd = 2.2;                                                 % coefficient Cd
            sc.DragArea = 0.08;                                           % Drag-area - m^2
            sc.Cr = 2;                                                 % coefficient Cr
            sc.SolarRadiationPressureArea = 0.07219; % Solar radiation pressure Pressure Area m^2
            sc.Ck= 0;                                                  % coefficient Cr
            sc.RadiationPressureArea = 0.079947;           % Radiation Pressure Area - m^2
            sc.K1 = 1; 
            sc.K2 = 1;
            
            % Fuel Tank:
            ft = initState1.FuelTank;
            ft.TankPressure = 5000;               % Tank pressure - Pa
            ft.TankVolume = 0.0006;                 % Tank Volume - m^2
            ft.TankTemperature = 429.75;            % Tank Temperature
            ft.FuelDensity = 7310;              % Fuel density - kg/m^3
            ft.FuelMass = 0.25;                % Fuel Mass - kg
            ft.MaximumFuelMass = 0.25;     % Maximum Fuel Mass - kg

            %   Manuever Input selection table
            %-------------------------------------------------------------------------------------------
            
            thrust_duration = 600/60; % in minutes, here still manually

            % Manuever Selection
            % x = x(x{:, 2} ~= 0, :); delete rows where x(:,2)==0 ("~" not % operator)
            maneuver_table_input = app.ManeuverInputTable.Data; % table with manuever data imput
            maneuver_locig = maneuver_table_input(:,2); % maneuver selection (1 or 0)
            maneuver_table_input = maneuver_table_input(maneuver_table_input{:,2} ~=0,:); % delete unselected maneuvers

            maneuver_date = maneuver_table_input{:,1};  % selected maneuver dates
            maneuver_start_date = datetime(maneuver_date)-minutes(thrust_duration/2); % maneuver start date (perigee pass date - maneuver duration/2)
            maneuver_start_date = char(maneuver_start_date);    % Convert to char for STK input -> te get single elements maneuver_start_date(i,:)

            maneuvers_count = length(maneuver_date(:,1));
            maneuver_id = 1:maneuvers_count;

            % Loop which creates a propagaor segment and afterwards manuever segment. The propagator goes until
            % perigee_date-man_duration/2 
            %
            % IMPORTANT: The propagator does not consider the change of the
            % perigee time from other manuevers, it just uses the perigee
            % pass dates from the first propagation. This needs to be fixed!!!
            % Solution:
            % Option 1: Use the Perigee Pass stopping condition for the
            % propagator and set the manuever to "center burn". Probelm:
            % dont know how to set "center brun = true" in matlab
            % Option 2: Use true anomaly as stopping condition and calculte
            % true anomaly for thrust_time/2 before perigee pass (true anomly = 0)
            %------------------------------------------------------------------------------------------- 
            for i=1:maneuvers_count
                %disp(i)
                %%----------------------------Insert Propagator-----------------------------
                propagator1 = driver.MainSequence.Insert('eVASegmentTypePropagate','Propage_1','-');
                propagator1.PropagatorName = 'Earth HPOP Default v10';
                propagator1.Properties.Color = 65280;     % green
                propagator1_stopcond = propagator1.StoppingConditions.Add('Epoch');
                propagator1_stopcond.Properties.Trip = maneuver_start_date(i,:);
                propagator1.StoppingConditions.Remove('Duration');
            

                %%----------------------------Insert Maneuver -----------------------------
                % Wanted to make thurster parameter selection for every
                % manuever, however, the engine for all maneuvers is selected
                % when running the astrogator (driver.RunMCS;) therfore
                % using the last set engine parameters for all maneuvers

                % %Get the Engine Models Folder
                % engine = scenario.ComponentDirectory.GetComponents('eComponentAstrogator').GetFolder('Engine Models');
                % engine_model = engine.Item('Constant Thrust and Isp');
                % engine_model_clone = engine_model.CloneObject;
                % Engine = engine.Item(item(i));
                % Engine.Name = name(i);
                % Engine.Thrust = thrust_engine(i);                         % Thrust - [N]
                % Engine.Isp = Isp;                               % Specific Impulse - [s]
                % %Engine.Thrust = thrust_engine(i);
            
                % Add Maneuver Segment
                in_manu = driver.MainSequence.Insert('eVASegmentTypeManeuver','Maneuver','-');
                in_manu.SetManeuverType('eVAManeuverTypeFinite');
                in_manu.Properties.Color = 16777215;    % color magenta
                maneuver1 = in_manu.Maneuver;
                
                % Attitude:
                maneuver1.SetAttitudeControlType('eVAAttitudeControlVelocityVector');
                alt_manu = maneuver1.AttitudeControl;
                
                % Engine:
                maneuver1.ThrustEfficiency = 1;
                maneuver1.SetPropulsionMethod('eVAPropulsionMethodEngineModel', 'Enpulsion'); % select engine fromm browser
                
                % Maneuver propagator
                prop_manu = maneuver1.Propagator;
                %maneuver1.CenterBurn = 1;
                %prop_manu.CenterBurn = 1;
                
                trip = prop_manu.StoppingConditions.Add('Duration');         % in min, dont know why
                trip.Properties.Trip = thrust_duration;                 % in min, dont know why
                prop_manu.StoppingConditions.Remove('Duration');

            end

            driver.RunMCS; % This command runs the astrogator elements set above


            %% Access Report and Visualize in AccessTableInfo_2

            disp('Getting access windows')
            app.ConsoleLog("Getting access windows");
            
            % Data provider
            % Access computed to FHWN (access diretly above horizon)
            % Access computed to Sensor (access x deg above horizon, x -> cone half angle adjusted in stk scenario)
            access = app.CLIMB.GetAccessToObject(FHWN);
            %access = app.CLIMB.GetAccessToObject(Sensor);
            access.ComputeAccess;
            accessDP = access.DataProviders.Item('Access Data').Exec(scenario.StartTime,scenario.StopTime);
            
            % Get access start/stop time and duration in min
            % Start Time
            app.accessStartTimes_2=datetime(accessDP.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            %accessStartTimes_str=datestr(app.accessStartTimes_2, 'dd mmm yyyy HH:MM:SS');
            %accessStartTimes_cell=cellstr(accessStartTimes_str);

            % Stop Time
            app.accessStopTimes_2=datetime(accessDP.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            %accessStopTimes_str= datestr(app.accessStopTimes_2, 'dd mmm yyyy HH:MM:SS');
            %accessStopTimes_cell=cellstr(accessStopTimes_str);

            % Duration
            app.root.UnitPreferences.Item('Time').SetCurrentUnit('Min');
            accessDuration = accessDP.DataSets.GetDataSetByName('Duration').GetValues;

            % ---Create Access Table: AccessTableInfo_2 ---

            % ColumnName = {'Access Start','Access End','Access Duration','Access Select','Access Type'};
            checkbox_acc_sel = true(length(app.accessStartTimes_2), 1);     % create logical array for checkbox column
            access_type(1:length(app.accessStartTimes_2)) = {'UHF'};        % Create string array for inital drop down menu value        
            
            % For drop down menu, the Data cant be in table fomrat because it would ignore ColumnFormat (dont know why)
            app.AccessTableInfo_2.ColumnFormat = {[] [] [] [] {'UHF' 'S-Band'}};    % set format for columns, {} creates drop down menu
            app.AccessTableInfo_2.Data = [cellstr(app.accessStartTimes_2), cellstr(app.accessStopTimes_2), accessDuration, num2cell(checkbox_acc_sel),access_type'];  
            
            % Notes for debugging
            % app.accessStartTimes_2      %4×1 datetime array
            % app.accessStopTimes_2       %4×1 datetime array
            % accessDuration              %4×1 cell array {[3.4296]}
            % checkbox_acc_sel            %4×1 logical array YES
            % access_type'                %4×1 cell array {'UHF'}
            % 
            % cellstr(app.accessStartTimes_2)     %4×1 cell array
            % cellstr(app.accessStopTimes_2)      %4×1 cell array
            % num2cell(accessDuration)            %4×1 cell array {1×1 cell}
            % num2cell(checkbox_acc_sel)          %4×1 cell array
            % myData = access_type'               %4×1 cell array %YES
         
            
            %% Perigee Pass Report and Visualize data in Table PerigeePassDateTableInfo_2
            disp('Getting pergiee passes')
            app.ConsoleLog("Getting perigee passes");
            
            % Get Perigee Pass Data
            peri_pass_DP = app.CLIMB.DataProviders.Item('Passes').Exec(app.Scenario_Start_Time,app.Scenario_Stop_Time);
            app.peri_pass_time_2=datetime(peri_pass_DP.DataSets.GetDataSetByName('Time of Perigee').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            
            % Visualize data in Table: PerigeePassDateTableInfo_2
            app.PerigeePassDateTableInfo_2.Data=table([1:length(app.peri_pass_time_2)]',app.peri_pass_time_2);
            
            
            %% Maneuver Data Report and Visualiztion in Tables: ManeuverOutputTable, ManOutputGlobalTable
            
            maneuver_output_report= app.CLIMB.DataProviders.Item('Maneuver Summary').Exec(app.Scenario_Start_Time,app.Scenario_Stop_Time);
            app.maneuver_start_time=datetime(maneuver_output_report.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.maneuver_stop_time=datetime(maneuver_output_report.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            maneuver_duration=maneuver_output_report.DataSets.GetDataSetByName('Duration').GetValues;
            maneuver_dv=maneuver_output_report.DataSets.GetDataSetByName('Delta V').GetValues;

            % From Code Snipptes
            % Code Snipptes = app.CLIMB.DataProviders.Item('Passes').Exec(app.Start_Time,app.End_Time);
            % app.per_pass_time=datetime(passes_res.DataSets.GetDataSetByName('Time of Perigee').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % satPosDP = satellite.DataProviders.Item('Cartesian Position').Group.Item('ICRF').Exec(scenario.StartTime,scenario.StopTime,60);
            % satx = cell2mat(satPosDP.DataSets.GetDataSetByName('x').GetValues);
        
            % Data Visualization in Table: ManeuverOutputTable 
            app.ManeuverOutputTable.Data=table(maneuver_id',app.maneuver_start_time,app.maneuver_stop_time,maneuver_duration,maneuver_dv);
             
            % Data Visualization in Table: ManOutputGlobalTable 
            varname = {'Total Number Maneuvers','Total Delta-V (m/s)','Total Thrust Time (min)'};
            total_dv = sum(cell2mat(maneuver_dv));
            total_thrust_time = sum(cell2mat(maneuver_duration));
            app.ManOutputGlobalTable.Data = table(varname',[string(maneuvers_count), total_dv, total_thrust_time]');    % maneuvers_count to get rid of decimals (int32() didnt work)
            
            
        end

        % Close request function: CLIMBPredictorUIFigure
        function CLIMBPredictorUIFigureCloseRequest(app, event)
            
               app.delete();
            
        end

        % Button pushed function: CreateSTKButton
        function CreateSTKButtonPushed(app, event)
        
            disp('Creating STK')
            app.ConsoleLog("Creating STK");

         %app.STKApp.visible = true;

%% Updating burns
         Burn=app.ManeuverInputTable.Data(:,2);
         Burn=table2array(Burn);
         driver = app.CLIMB.Propagator;
         lastburn = 0;
          for i = 2:(height(app.ManeuverInputTable.Data))
            if Burn(i) == 0 | Burn(1) == 1     % Zeros are deleted Ones are burned
            burnname = append('Burn' ,num2str(i-1));
                try
                    driver.MainSequence.Remove(burnname);
                catch
                 %   break;
                end
            end
            lastburn = i+1;
          end
          burnname = append('Burn' ,num2str(lastburn-1));
                try
                    driver.MainSequence.Remove(burnname);
                catch
                 %   break;
                end
        driver.RunMCS;


%% Inputs for attitude
        disp ('Reading Schedule')
        app.ConsoleLog("Reading Schedule");
        
        Torque_tool_data  = readtable('C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Torque_tool_data.xlsx');

        slew_length= minutes(table2array(Torque_tool_data(19,2)));% kg*m^2 (Roll)); % Slew length for fixed time
        thrust_length_half=minutes(table2array(Torque_tool_data(20,2))); % half before and or after perigee
        thrust_lead_length=minutes(table2array(Torque_tool_data(21,2))); % additional time in ready to thrust attitude before firing (only before, there is also same time after)
        
        startTimes_slew_c0=transpose(cellstr(app.accessStartTimes_1-slew_length));
        startTimes_com=transpose(app.accessStartTimes_str);
        startTimes_slew_c=transpose(app.accessStopTimes_str);
        startTimes_sun_c=transpose(cellstr(app.accessStopTimes_1+slew_length));
        startTimes_slew_t0=transpose(cellstr(app.peri_pass_time_1-thrust_length_half-thrust_lead_length-slew_length));
        startTimes_thrust=transpose(cellstr(app.peri_pass_time_1-thrust_length_half-thrust_lead_length));
        startTimes_slew_t=transpose(cellstr(app.peri_pass_time_1+thrust_length_half+thrust_lead_length));
        startTimes_sun_t=transpose(cellstr(app.peri_pass_time_1+thrust_length_half+thrust_lead_length+slew_length));
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        dateUnit = 'UTCG';% 'UTCG' or 'EpSec'
        command = strcat(['Units_Set * Connect Date "',dateUnit,'"']);
        app.root.ExecuteCommand(command); % Change connect command date units to desired units:
        startTimes = {};
        Access = app.AccessTableInfo_2.Data(:,4);
        Access = table2array(Access);
        StartData= app.AccessTableInfo_2.Data(:,1);
        StopData = app.AccessTableInfo_2.Data(:,2);
        StartData = table2array(StartData);
        StopData = table2array(StopData);
        
        for i = 1:(height(app.AccessTableInfo_2.Data))
            if Access(i) == 1
                startTimes{end+1} = StartData(i);
                startTimes{end+1} = StopData(i);
            end
        end

%% Define Attitude Profiles

        profileNames = {'Sun_c','Communication','Slew_c','Thrust','Slew_t','Sun_t','Slew_t0','Slew_c0'};
        profileTypes = {'AlignConstrain','AlignConstrain','FixedTimeSlew','AlignConstrain','FixedTimeSlew','AlignConstrain','FixedTimeSlew','FixedTimeSlew'};
        
        % for INSTALLED "Aligned and Constrained" attitude profiles:
        offsetAngles = {'0'};
        % for CUSTOM "Aligned and Constrained" attitude profiles:
        alignVectors = {'Sun','Nadir(Centric)','Velocity','Sun'};
        alignRefTypes = {'Axis', 'Axis', 'Axis','Axis' }; % Axis  / <x> <y> <z> Euler / <angle 1> <angle 2> <sequence> (12, 21, 31, 32) , ,
        alignRefValues = {'1 1 0', '0 1 0', '0 0 -1','1 1 0'};
        
        % Constrained vector data:
        constrainVectors = {'Velocity','Velocity','Sun','Velocity'};
        constrainRefTypes = {'Axis','Axis','Axis','Axis'};% PR    / <pitch> <roll>
        constrainRefValues = {'0 0 -1','0 0 -1','1 1 0','0 0 -1'}; %    RaDec / <raan> <dec>
        
        %   SLEW
        % slewtype={'Smooth On'};
        
        alignConstrainIndex = 1;
        installedIndex = 1;
        slewindex=1;


%% Defining time and length for each attitude profile

        Access=app.AccessTableInfo_2.Data(:,4);
        Access = table2array(Access);
        Burn=app.ManeuverInputTable.Data(:,2);
        Burn=table2array(Burn);
        
        %{Starttime, Profile Name}
        % TimeTable = {Start_Time_period};
        % NameTable = {'Sun_t'};
        TimeTable = {};
        NameTable = {};
        
        
        %j = 1;
        for i = 2:(height(app.ManeuverInputTable.Data))
            if Burn(i) == 1 && Burn(1) == 0 
                TimeTable{end +1} = startTimes_thrust(i-1);
                NameTable{end +1} = profileNames(4);
                TimeTable{end +1} = startTimes_sun_t(i-1);
                NameTable{end +1} = profileNames(6);
                TimeTable{end +1} = startTimes_slew_t(i-1);
                NameTable{end +1} = profileNames(5);
                TimeTable{end +1} = startTimes_slew_t0(i-1);
                NameTable{end +1} = profileNames(7);
            end
        end
        for i = 1:(height(app.AccessTableInfo_2.Data))
            if Access(i) == 1     
                TimeTable{end +1} = startTimes_com(i);
                NameTable{end +1} = profileNames(2);
                TimeTable{end +1} = startTimes_sun_c(i);
                NameTable{end +1} = profileNames(1);
                TimeTable{end +1} = startTimes_slew_c(i);
                NameTable{end +1} = profileNames(3);
                TimeTable{end +1} = startTimes_slew_c0(i);
                NameTable{end +1} = profileNames(8);
            end
        end
    
    
%% Add Attitude Profiles 
        disp('Setting attitude schedule')
        app.ConsoleLog("Setting attitude schedule");
        
        %nProfile = numel(profileNames);
        %nProfile = int32(floor(numel(startTimes)/2));
        for i = 1:numel(NameTable())
        
         profileName = string(NameTable{i});
         startTime = TimeTable{i};
        
         switch profileName
             case "Sun_c"
                profileType = profileTypes{1}; 
                index = 1;
             case "Communication"
                profileType = profileTypes{2}; 
                index = 2;
             case "Slew_c"
                profileType = profileTypes{3}; 
             case "Thrust"
                profileType = profileTypes{4}; 
                index = 3;
             case "Slew_t"
                profileType = profileTypes{5}; 
             case "Sun_t"
                profileType = profileTypes{6}; 
                index = 4;
             case "Slew_t0"
                profileType = profileTypes{7}; 
             case "Slew_c0"
                profileType = profileTypes{8}; 
         end
        
            % Start building command string:
            baseString = string(strjoin(['AddAttitude */Satellite/','CLIMB',' Profile ',profileName,' "',startTime,'" ',profileType],''));
        
            % Gather attitude data based on profile type:
            switch profileType
                
                % Custom "Aligned and Constrained" profile type:
                case 'AlignConstrain'
                    
                    % Set align/constrain index:
                    %index = alignConstrainIndex;
        
                    % Aligned vector data:
                      alignVector = alignVectors{index};
                     alignRefType = alignRefTypes{index};
                    alignRefValue = alignRefValues{index};
                      alignString = strcat([alignRefType,' ',alignRefValue,' "',alignVector,'"']);
                    
                    % Constrained vector data:
                      constrainVector = constrainVectors{index};
                     constrainRefType = constrainRefTypes{index};
                    constrainRefValue = constrainRefValues{index};
                      constrainString = strcat([constrainRefType,' ',constrainRefValue,' "',constrainVector,'"']);
                    
                    % Build profile string:
                    profileString = strcat([alignString,' ',constrainString]);
                    
                    % Increment align/constrain index by 1:
                    %alignConstrainIndex = alignConstrainIndex + 1;
                case 'FixedTimeSlew'
        
                    % Set slew index:
                    index = slewindex;
                    
                    % Slew data:
                    %slewtype=slewtype{index};
        %                 slewString=strcat([slewtype]);
                    
                    % Build profile string:
                    profileString = 'Smooth On'; %slewString;
                    
                    % Increment inertial index by 1:
                    slewindex=slewindex+1;
        
                % One of the 'installed' "Aligned and Constrained" profiles:
                otherwise
        %             
                    % Set installed index:
                    index = installedIndex;
                    
                    % Get offset angle:
                    offsetAngle = offsetAngles{index};
                    offsetString = strcat(['Offset ',offsetAngle]);
                    
                    % Build profile string:
                    profileString = offsetString;
                    
                    % Increment installed index by 1:
                    installedIndex = installedIndex + 1;
            end
        
            commandString = convertStringsToChars(strjoin([baseString,' ',profileString],''));
            
            app.root.ExecuteCommand(commandString);% Build command string and execute command:
            %disp(commandString)
        end    
    
disp('Running the Scenario')
app.ConsoleLog("Running Scenario");
%% Checking for Communication-Thrust-Overlaps
        disp('Checking for Communication-Thrust-Overlaps')
        app.ConsoleLog("Checking for Communication-Thrust overlaps");
        
        AttitudeSchedule = app.CLIMB.DataProviders.Item('Attitude Segment Schedule').Exec(app.Scenario_Start_Time,app.Scenario_Stop_Time);
        AttitudeNames = AttitudeSchedule.DataSets.GetDataSetByName('Name').GetValues;
        %disp(AttitudeNames)
        AttitudeNames = transpose(AttitudeNames);
        
        % standard schedule: {'Thrust'        }
        %                    {'QuatData'      }
        %                    {'Thrust'        }
        %                    {'Slew_t'        }
        %                    {'Sun_t'         }
        %                    {'Slew_c0'       }
        %                    {'Communication' }
        %                    {'Slew_c'        }
        %                    {'Sun_c'         }
        %                    {'Slew_t0'       }
        
        
            AttitudeNames = string(AttitudeNames);
        
        Error = false;
        for i = 1:(numel(AttitudeNames)-1)
                Name1 = AttitudeNames(i);
                Name2 = AttitudeNames(i+1);
        
            if Name1 == "Thrust" && Name2 ~= "QuatData" && Name2 ~= "Slew_t"
                Error = true;
                disp('Error: Attitude Profile overlap (1)')
                app.ConsoleLog("Error: Attitude Profile overlap (1)");
                break
            end
            if Name1 == "Slew_t" && Name2 ~= "Sun_t"
                Error = true;
                disp('Error: Attitude Profile overlap (2)')
                app.ConsoleLog("Error: Attitude Profile overlap (2)");
                break
            end
            if Name1 == "Communication" && Name2 ~= "Slew_c"
                Error = true;
                disp('Error: Attitude Profile overlap (3)')
                app.ConsoleLog("Error: Attitude Profile overlap (3)");
                break
            end
            if Name1 == "Slew_c" && Name2 ~= "Sun_c"
                Error = true;
                disp('Error: Attitude Profile overlap (4)')
                app.ConsoleLog("Error: Attitude Profile overlap (4)");
                break
            end
        end
        
        if Error == false
            disp('No overlaps detected. All profiles Nominal!')
            app.ConsoleLog("No overlaps detected. All profiles Nominal!");
        
        else 
            return
        end

%% Create schedule table output
        newHeaderNames = {'Access window start', 'Access window end', 'Access duration', 'Access Status'};
        app.AccessTableInfo_2.Data.Properties.VariableNames = newHeaderNames;
        writetable(app.AccessTableInfo_2.Data, 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Access_schedule.csv');
        newHeaderNames2 = {'Perigee pass time', 'Propulsion On/ Off'};
        app.ManeuverInputTable.Data.Properties.VariableNames = newHeaderNames2;
        writetable(app.ManeuverInputTable.Data, 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Fire_schedule.csv');
        newHeaderNames3= {'Scenario start time','Scenario stop time'};
        app.UITable3.Data.Properties.VariableNames = newHeaderNames3;
        writetable(app.UITable3.Data, 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Scenario_time.csv');
  
%% Creating Solar Power Report
        disp('Creating Solar Power report')
        app.ConsoleLog("Creating Solar Power report");
        
        %Command = string(strjoin(['VO  */Satellite/CLIMB SolarPanel Compute ','" ',app.Start_Time,' "',' " ',app.Start_Time,' " 60'],''));
        % 6 Aug 2023 20:15:04.320,9 Aug 2023 20:15:04.320
        %Command = 'VO */Satellite/CLIMB SolarPanel Compute "6 Aug 2023 20:15:04.320" "9 Aug 2023 20:15:04.320" 600';
        stepsize = 600;
        app.root.UnitPreferences.Item('Power').SetCurrentUnit('W');
        app.root.ExecuteCommand("VO */Satellite/CLIMB SolarPanel Visualization Radius On 1 AddGroup Solar_Panels");
        Command = convertCharsToStrings('VO */Satellite/CLIMB SolarPanel Compute "') + app.Scenario_Start_Time +convertCharsToStrings('" "')+ app.Scenario_Stop_Time + convertCharsToStrings('" ') + string(stepsize);
        app.root.ExecuteCommand(Command);
        Solar = app.CLIMB.DataProviders.Item('Solar Panel Power').Exec(app.Scenario_Start_Time,app.Scenario_Stop_Time,stepsize);
                
        SolarTime = Solar.DataSets.GetDataSetByName('Time').GetValues;
        SolarPower = Solar.DataSets.GetDataSetByName('Power').GetValues;
        SolarIntensity = Solar.DataSets.GetDataSetByName('Solar Intensity').GetValues;
        
        SolarMatrix = [SolarTime,SolarPower,SolarIntensity];
        SolarTable = array2table(SolarMatrix);
        SolarTable.Properties.VariableNames(1:3) = {'Time (UTCG)','Power (W)','Solar Intensity'};
        writetable(SolarTable,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\CLIMB_Solar_Panel_Power.csv');

%% Creating Torque Report
        disp('Creating Torque report')
        app.ConsoleLog("Creating Torque report");
        
        Torque=app.CLIMB.DataProviders.Item('Vectors(Body)').Group.Item('TotalTorque').Exec(app.Scenario_Start_Time,app.Scenario_Stop_Time,5);
        TorqueX=Torque.DataSets.GetDataSetByName('x').GetValues;
        TorqueY=Torque.DataSets.GetDataSetByName('y').GetValues;
        TorqueZ=Torque.DataSets.GetDataSetByName('z').GetValues;
        Torque_time=datetime(Torque.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
        Torque_time= datestr(Torque_time, 'dd mmm yyyy HH:MM:SS');
        Torque_time=cellstr(Torque_time);
        Torque_table = [Torque_time,TorqueX,TorqueY,TorqueZ];
        Torque_table= array2table(Torque_table);
        Torque_table.Properties.VariableNames(1:4) = {'Time (UTCG)','Torque in X (Nm)','Torque in Y (Nm)','Torque in Z (Nm)'};
        writetable(Torque_table,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Torque.csv');

%% Creating Angular Momentum Report
        disp('Creating Angular Momentum report')
        app.ConsoleLog("Creating Angular Momentum report");
        
        AngMomentum =app.CLIMB.DataProviders.Item('Vectors(Body)').Group.Item('AngMomentum').Exec(app.Scenario_Start_Time,app.Scenario_Stop_Time,5);
        MomentumX=AngMomentum.DataSets.GetDataSetByName('x').GetValues;
        MomentumY=AngMomentum.DataSets.GetDataSetByName('y').GetValues;
        MomentumZ=AngMomentum.DataSets.GetDataSetByName('z').GetValues;
        Momentum_time=datetime(AngMomentum.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
        Momentum_time= datestr(Momentum_time, 'dd mmm yyyy HH:MM:SS');
        Momentum_time=cellstr(Momentum_time);
        Momentum_table = [Momentum_time,MomentumX,MomentumY,MomentumZ];
        Momentum_table= array2table(Momentum_table);
        Momentum_table.Properties.VariableNames(1:4) = {'Time (UTCG)','AngMomentum in X (kg*m^2/sec)','AngMomentum in Y (kg*m^2/sec)', 'AngMomentum in Z (kg*m^2/sec)'};
        writetable(Momentum_table,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\AngularMomentum.csv');

%% Creating angle change and angular velocity report
        disp('Creating angle change and angular velocity report')
        app.ConsoleLog("Creating angle change and angular velocity report");
        
        YPR =app.CLIMB.DataProviders.Item('Attitude YPR').Group.Item('Seq RPY').Exec(app.Scenario_Start_Time,app.Scenario_Stop_Time,1);
        Yaw=YPR.DataSets.GetDataSetByName('Yaw').GetValues;
        Pitch=YPR.DataSets.GetDataSetByName('Pitch').GetValues;
        Roll=YPR.DataSets.GetDataSetByName('Roll').GetValues;
        Yaw_rate=YPR.DataSets.GetDataSetByName('Yaw Rate').GetValues;
        Pitch_rate=YPR.DataSets.GetDataSetByName('Pitch Rate').GetValues;
        Roll_rate=YPR.DataSets.GetDataSetByName('Roll Rate').GetValues;
        YPR_time=datetime(YPR.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
        YPR_time= datestr(YPR_time, 'dd mmm yyyy HH:MM:SS');
        YPR_time=cellstr(YPR_time);
        YPR_table = [YPR_time,Yaw,Pitch,Roll,Yaw_rate,Pitch_rate,Roll_rate];
        YPR_table= array2table(YPR_table);
        YPR_table.Properties.VariableNames(1:7) = {'Time (UTCG)','Yaw','Pitch','Roll','Yaw rate','Pitch rate,','Roll rate'};
        writetable(YPR_table,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\YPR.csv');
%% Runninng Torque tool
        disp('Running Power and torque tool')
        app.ConsoleLog("Running Power and torque tool");

        %% Constants

Torque_tool_data  = readtable('C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Torque_tool_data.xlsx');

% CubeSat CAD data
I_xx = table2array(Torque_tool_data(2,2));% kg*m^2 (Roll)
I_yy=table2array(Torque_tool_data(3,2)); % kg*m^2 (Pitch)
I_zz=table2array(Torque_tool_data(4,2)); % kg*m^2 (Yaw)

cm_x=table2array(Torque_tool_data(7,2));%m cm shifts
cm_y=table2array(Torque_tool_data(8,2));%m cm shifts

Height_z=table2array(Torque_tool_data(11,2)); % m

% ADCS data sheet
ADCS_max_L=table2array(Torque_tool_data(2,6)); % mNms
ADCS_max_T=table2array(Torque_tool_data(1,6)); % mNm
RPM_limit=table2array(Torque_tool_data(3,6));% 8000 for CW0017 and 10000 for CW0057for reaction 
I_fw=table2array(Torque_tool_data(5,6)); %kg*m^2 2.4E-05 for CW0017 and 7E-05 for CW0057 and CS has 0.1432 kg*m^2 (need 3.2 E-05)

% ADCS last status from downlink data
RPM_wheel_initial=table2array(Torque_tool_data(15,6));
L_x_cubesat_initial=table2array(Torque_tool_data(16,6));
L_y_cubesat_initial=table2array(Torque_tool_data(17,6));
L_z_cubesat_initial=table2array(Torque_tool_data(18,6));

% Contributors to torque due to CM shift
F=table2array(Torque_tool_data(15,2));% N
t_thrust=table2array(Torque_tool_data(16,2));% s


% Contributors to torque due to thrust vector misallignment 
Misalignment_angle=table2array(Torque_tool_data(17,2)); % deg


%
% T_cm_x=cm_x*F; % Torque, Nm
% T_cm_y=cm_y*F; % Torque, Nm
% L_cm_x=T_cm_x*t_thrust;
% L_cm_y=T_cm_x*t_thrust;

%% Input reading

% YPR 
    Angular_velo_data=readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\YPR.csv",'HeaderLines', 1);
    Velo_time=Angular_velo_data(:,1);
    Velo_time=table2cell(Velo_time);
    Velo_time=datetime(Velo_time, 'InputFormat', 'd MMM yyyy HH:mm:ss');
    Yaw=table2array(Angular_velo_data(:,2));
    Pitch=table2array(Angular_velo_data(:,3));
    Roll=table2array(Angular_velo_data(:,4));
    % Yaw_rate=table2array(Angular_velo_data(:,5));
    % Pitch_rate=table2array(Angular_velo_data(:,6));
    % Roll_rate=table2array(Angular_velo_data(:,7));

        % Correction due to -180 to +180 limit
        for i = 1:(length(Yaw)-1)
         
            while Yaw(i) < (Yaw(i+1)-90)
                Yaw(i+1) = Yaw(i+1)-360;
            end
            while Yaw(i) > (Yaw(i+1)+90)
                Yaw(i+1) = Yaw(i+1)+360;
            end
            while Pitch(i) < (Pitch(i+1)-90)
                Pitch(i+1) = Pitch(i+1)-360;
            end
            while Pitch(i) > (Pitch(i+1)+90)
                Pitch(i+1) = Pitch(i+1)+360;
            end
            while Roll(i) < (Roll(i+1)-90)
                Roll(i+1) = Roll(i+1)-360;
            end
            while Roll(i) > (Roll(i+1)+90)
                Roll(i+1) = Roll(i+1)+360;
            end
        
        end

% Fire schedule
    Fire_data=readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Fire_schedule.csv",'HeaderLines', 1);
    Fire_times = table2array(Fire_data(:,1));
    Fire_status = table2array(Fire_data(:,2));
        Burn_time_status = zeros((size(Velo_time,1))-1,1); %  delta_L_x=zeros(size(L_x, 1), 1);
        
        for i = 1:size(Fire_times)
            if Fire_status(1) == 1
                break;
            end
            for j = 1:size(Burn_time_status)
                if abs(seconds(Velo_time(j) - Fire_times(i))) < 300 && Fire_status(i) == 1
                    Burn_time_status(j) = 1;
                end
            
            end
        
        end

% Torque
    Torque_data= readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Torque.csv", 'HeaderLines', 1);
    Torque_time=Torque_data(:,1);
    Torque_time = table2cell(Torque_time);
    Torque_time= datetime(Torque_time, 'InputFormat', 'd MMM yyyy HH:mm:ss');
    T_x=1000*table2array(Torque_data(:,2)); % mNm
    T_y=1000*table2array(Torque_data(:,3)); % mNm
    T_z=1000*table2array(Torque_data(:,4)); % mNm

    T=sqrt(T_x.^2+T_y.^2+T_z.^2); % Abs. Torque

    Max_Torque=max(T);% Max. Torque
    disp(['Maximum Instantenious Torque (mNm): ', num2str(Max_Torque)]);

    cum_T_x=sum(T_x);
    cum_T_y=sum(T_y);
    cum_T_z=sum(T_z);
    Total_torque=sqrt(cum_T_x^2+cum_T_y^2+cum_T_z^2);% Abs. Cum. Torque
    disp(['Total Torque (mNm): ', num2str(Max_Torque)]);

% Angular Momentum
    L_data= readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\AngularMomentum.csv", 'HeaderLines', 1);
    L_time=L_data(:,1);
    L_time = table2cell(L_time);
    L_time= datetime(L_time, 'InputFormat', 'd MMM yyyy HH:mm:ss');
    L_x=1000*table2array(L_data(:,2)); % 1000*kg*m^2/sec= mNms % + L_cm_x*      
    L_y=1000*table2array(L_data(:,3)); % 1000*kg*m^2/sec= mNms
    L_z=1000*table2array(L_data(:,4)); % 1000*kg*m^2/sec= mNms
    L=sqrt(L_x.^2+L_y.^2+L_z.^2);
    Max_L=max(L);
    disp(['Maximum instantaneous Angular Momentum (mNms) ', num2str(Max_L)]);
    
    delta_L_x=zeros(size(L_x, 1), 1);
    for i = 2:length(delta_L_x)
        delta_L_x_i = L_x(i);
        delta_L_x_iminus1 = L_x(i-1);
        delta_L_x(i) = L_x(i)-L_x(i-1);
    end
    L_x(1)=L_x_cubesat_initial+L_x(1);
    Cum_L_x=L_x(1)+cumsum(delta_L_x);


    delta_L_y=zeros(size(L_y, 1), 1);
    for i = 2:length(delta_L_y)
        delta_L_y_i = L_y(i);
        delta_L_y_iminus1 = L_y(i-1);
        delta_L_y(i) = L_y(i)-L_y(i-1);
    end
    L_y(1)=L_y_cubesat_initial+L_y(1);
    Cum_L_y=L(1)+cumsum(delta_L_y);

    delta_L_z=zeros(size(L_z, 1), 1);
    for i = 2:length(delta_L_z)
        delta_L_z_i = L_z(i);
        delta_L_z_iminus1 = L_z(i-1);
        delta_L_z(i) = L_z(i)-L_z(i-1);
    end
    L_z(1)=L_z_cubesat_initial+L_z(1);
    Cum_L_z=L(1)+cumsum(delta_L_z);

    L_limit_pos=ADCS_max_L*ones(size(L_time));
    L_limit_neg=-ADCS_max_L*ones(size(L_time));

%     time_L_limit_x =max( L_time(abs(Cum_L_x) <= L_limit_pos));
%     time_L_limit_y =max( L_time(abs(Cum_L_y) <= L_limit_pos));
%     time_L_limit_z =max( L_time(abs(Cum_L_z) <= L_limit_pos));

%%  Angular velcoity derivation
    time_step = seconds(Velo_time(2) - Velo_time(1)); % Calculate the time step in seconds
    
    Yaw_rate = diff(Yaw(:))./time_step; % deg/s
    Pitch_rate = diff(Pitch(:))./time_step;
    Roll_rate = diff(Roll(:))./time_step;
  
%% Angular momentum due to CM shift
    Angular_momentum_pitch_thrust_persecond = F*cm_x; % Nm
    Angular_momentum_roll_thrust_persecond = F*cm_y; % Nm
    Thrust_momentum_pitch = Angular_momentum_pitch_thrust_persecond*time_step*Burn_time_status; 
    Thrust_momentum_roll = Angular_momentum_roll_thrust_persecond*time_step*Burn_time_status;
    Thrust_momentum_pitch = cumtrapz(Thrust_momentum_pitch);
    Thrust_momentum_roll = cumtrapz(Thrust_momentum_roll);

 %% Angular mometum due to Thrust vector misallignment 
    Thrust_momentum_PR=F*Height_z*sind(5); % Either could be added to X or Y, roll or pitch, Nm
    Angular_momentum_misall_PR=cumtrapz(Thrust_momentum_PR*time_step*Burn_time_status);

 %% Total angular momentum   
    Angular_momentum_yaw_STK = (Yaw_rate*pi/180) * I_zz; % Nms
    Angular_momentum_pitch_STK = (Pitch_rate*pi/180) * I_yy;
    Angular_momentum_roll_STK = (Roll_rate*pi/180) * I_xx;

    Angular_momentum_yaw = Angular_momentum_yaw_STK ; % Nms
    Angular_momentum_pitch= Angular_momentum_pitch_STK + Thrust_momentum_pitch+Angular_momentum_misall_PR; 
    Angular_momentum_roll= Angular_momentum_roll_STK +Thrust_momentum_roll;
%     figure Name STK_L
%         hold on
%         plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_yaw_STK,  'Color', 'green')
%         plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_pitch_STK, 'Color', 'black')
%         plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_roll_STK,  'Color', 'red')
%         xlabel('Time') 
%         ylabel('Angular momentum (Nms)')
%         legend({'Angular momentum in yaw','Angular momentum in pitch','Angular momentum in roll', ...
%             },'Location','southeast')
%         hold off
% 
% 
%      figure Name VectorMis_L
%             hold on
%             plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_misall_PR, 'Color', 'black')
%             
%             xlabel('Time') 
%             ylabel('Angular momentum (Nms)')
%             legend({'Angular momentum due to thrust vector misalignment', ...
%                 },'Location','southeast')
%             hold off
% 
%       figure Name Total_L
%             hold on
%             plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_yaw,  'Color', 'green')
%             plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_pitch, 'Color', 'black')
%             plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_roll,  'Color', 'red')
%             xlabel('Time') 
%             ylabel('Angular momentum (Nms)')
%             legend({'Angular momentum in yaw','Angular momentum in pitch','Angular momentum in roll', ...
%                 },'Location','southeast')
%             hold off

%% RPM of the wheel
    RPM_x = Angular_momentum_roll/I_fw*30/pi+RPM_wheel_initial; 
    RPM_y = Angular_momentum_pitch/I_fw*30/pi+RPM_wheel_initial;
    RPM_z = Angular_momentum_yaw/I_fw*30/pi+RPM_wheel_initial;
    
    figure Name RPM
        hold on
        plot(Velo_time(1:length(Yaw_rate)),RPM_x, 'Color', 'red','LineWidth',2)
        plot(Velo_time(1:length(Yaw_rate)),RPM_y, 'Color', 'black', 'LineWidth',2)
        plot(Velo_time(1:length(Yaw_rate)),RPM_z,'Color', 'green','LineWidth',2)
    
        yline(RPM_limit, '--', 'LineWidth',2, 'Color', 'red')
        yline(-RPM_limit, '--', 'LineWidth',2, 'Color', 'red')
        ylim([-8000 8000])
        xlabel('Time','FontSize',24,'FontWeight','bold') 
        ylabel('RPMs','FontSize',24,'FontWeight','bold') 
        legend({'RPM around x','RPM around y','RPM around z', ...
            'Upper Limit' ,'Lower Limit'},'Location','southeast','FontSize',24,'FontWeight','bold')
        set(gca,'FontSize', 24)
        hold off 
    
    Velo_time=cellstr(Velo_time(1:size(RPM_x)));
    RPM_over_time=[Velo_time,num2cell(RPM_x),num2cell(RPM_y),num2cell(RPM_z)];
    RPM_over_time=array2table(RPM_over_time);
    RPM_over_time.Properties.VariableNames(1:4) = {'Time (UTCG)','RPM in X','RPM in Y','RPM in Z'};
    writetable(RPM_over_time,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\RPM_overTime.csv');
    disp('RPM report is generated')
    app.ConsoleLog("RPM report is generated");

%% Runninng Power tool
pyrunfile('C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\PowerTool.py')

disp('Power tool report is generated')
app.ConsoleLog("Power tool report is genrated");

disp('Completed')
app.ConsoleLog("Completed");

        end

        % Menu selected function: PowertoolMenu
        function PowertoolMenuSelected(app, event)


        end

        % Menu selected function: PowerconsumptiondataMenu
        function PowerconsumptiondataMenuSelected(app, event)
            winopen('CLIMB Power Budget V2.xlsx');
        end

        % Menu selected function: TorquetoolMenu
        function TorquetoolMenuSelected(app, event)
          app.accessStartTimes_str
        end

        % Menu selected function: TorquetooldataMenu
        function TorquetooldataMenuSelected(app, event)
             winopen('Torque_tool_data.xlsx');
        end

        % Menu selected function: BatterymodeldataMenu
        function BatterymodeldataMenuSelected(app, event)
            winopen('Battery_InitialStatus_Model_Parameters.xlsx');
        end

        % Menu selected function: PowermodesMenu
        function PowermodesMenuSelected(app, event)
            app.DialogApp=power_tool_modeSelection(app);
        end

        % Button pushed function: PlotScheduleButton
        function PlotScheduleButtonPushed3(app, event)
            
            % Second Schedule Plot 
            yaxis_names = {'Contact Windows','Perigee Passes','Maneuvers','Custom Activity'};
            
            % Use try/end to skip error if no access/manuever/activity data are available
            % Access Data
            try
            access_start = app.accessStartTimes_2;
            access_stop = app.accessStopTimes_2;
            end
            
            % Maneuver Data
            try
            man_start = app.maneuver_start_time;
            man_stop = app.maneuver_stop_time;
            end
            
            % Activity Data
            try
            activity_start = datetime(app.ActivityOutputTable.Data.Var3);
            activity_stop = datetime(app.ActivityOutputTable.Data.Var4);
            end
            
            cla(app.SchedulePlot_2) % Delete all plots

            ax=app.SchedulePlot_2;
            
            % ----Plot----
            try
                plot(ax,[access_start access_stop],[1 1], 'linewidth',5,'color','green')
            end

            try
                plot(ax,app.peri_pass_time_2,2,'+','linewidth',1,'color','blue')
            end

            try
                plot(ax,[man_start man_stop],[3 3],'linewidth',1,'color','blue')
            end

            try
                plot(ax,[activity_start activity_stop],[4 4],'linewidth',1,'color','#D95319')
            end

            ylim(ax, [0,5])

            % Set y-axis labels
            yticks(ax,[1 2 3 4]);  % Position the yaxis names
            yticklabels(ax,yaxis_names);            % Use activity names as labels


        end

        % Button pushed function: AddActivityButton
        function AddActivityButtonPushed(app, event)
            % Add Custom Activities, add to a table and plot in graph

            activityName = {app.ActivityNameEditField.Value}; % input from activity name text field and convert to cell with {}
            app.activityStartTime_custom = datetime(app.ActivityStartTimeEditField.Value,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.activityStopTime_custom = datetime(app.ActivityStopTimeEditField.Value,'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            activityPower = app.PowerWEditField.Value;
            
            activityDuration = app.activityStopTime_custom-app.activityStartTime_custom;
            numRows = size(app.ActivityOutputTable.Data, 1); % Get the current number of rows

            % Create table with activity data and insert in new row in Table: ActivityOutputTable
            % Without numRows+1, it would overwrite the current activity row in table every time the "add activity" button is pushed
            newRow = table(numRows+1,activityName,app.activityStartTime_custom,app.activityStopTime_custom,activityDuration,activityPower);
            app.ActivityOutputTable.Data = [app.ActivityOutputTable.Data; newRow];

        end

        % Button pushed function: TLEInfoRequestButton
        function TLEInfoRequestButtonPushed(app, event)
            % Button shows orbital elements from TLE in table: TLEInfoTable
            % This function is completely indepenet from rest of the code,
            % only needs function TLE_processing_fcn
            
            TLE_file = 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\TLE_PEGASUS_07082023.txt';  % TLE file path\name
            [OE_array,tle_epoch] = TLE_processing_fcn(app,TLE_file); % function that extracts orbital elements from TLEs
            
            a = OE_array(:,1);
            ra = OE_array(:,4);
            rp = OE_array(:,5);
            ecc = OE_array(:,2);
            Incl = OE_array(:,3);
            RAAN = OE_array(:,6);
            ArgPeri = OE_array(:,7);
            MeanAno = OE_array(:,11);
            MeanMotion = OE_array(:,8); % Something wrong here, is it true anomaly?
            Db = OE_array(:,5); %ballistic coefficienbt
            Orbit_array_sorted = [ra rp a ecc Incl RAAN MeanAno ArgPeri MeanMotion Db];

            Name={'Apogee (km)','Perigee (km)','Semi-Major Axis (km)','Eccentrictiy','Inclination (deg)','RAAN (deg)','Mean Anomaly (deg)','Argument of Perigee (deg)','Mean Motion','Ballsistic Coef'};
            app.TLEInfoTable.Data=table(["TLE Epoch",Name]',[{tle_epoch},num2cell(Orbit_array_sorted)]');

        end

        % Button pushed function: CreateActivityScheduleButton
        function CreateActivityScheduleButtonPushed(app, event)
            
           % SoE Table Output
           % Creates a list of all activities (manuevers, access times,
           % custom activites) and sorts it chronoligical with start_time
           % Every activity table needs same variable/column names -> VariableNames = ["Name","Start Time","Stop Time","Duration"];
           
           % ----manuevers----
           try
           maneuver_id = 1:length(app.maneuver_start_time);
           manuever_name = append(string(maneuver_id'),"_Maneuver");    % Append maneuver number and "manuever" -> 1_maneuver, 2_...
           manuever_duration = app.maneuver_stop_time-app.maneuver_start_time;
           Maneuvers = table(manuever_name, app.maneuver_start_time, app.maneuver_stop_time,manuever_duration);
           Maneuvers.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
           end
           
           % ----Access Times----
           try
           % Selected access
           access_table_2 = app.AccessTableInfo_2.Data;
           access_table_2 = access_table_2(cell2mat(access_table_2(:,4)) ~= 0, :); % delete rows where x(:,2)==0 ("~" not % operator)

           access_start = datetime(access_table_2(:,1));
           access_stop = datetime(access_table_2(:,2));
           access_duration = access_stop - access_start;
           access_mode = string(access_table_2(:,5));% string with "UHF" or "S-Band"
           access_id = 1:length(access_start);
           access_name = append(string(access_id'),"_Access_",access_mode); % Append access number, "access" and mode (UHF or S-Band) for name in SoE
           
           Access = table(access_name,access_start,access_stop,access_duration);
           Access.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
           end

           % ----Activities----
           try
           app.ActivityOutputTable.Data;
           Activities = app.ActivityOutputTable.Data;
           Activities(:,'Var1') = [];   % Remove Access ID
           Activities(:,'Var6') = [];   % Remove Power column
           Activities.Properties.VariableNames = ["Name","Start Time","Stop Time","Duration"];
           end

           
           % ----Create and Sort SoE----
           app.SoE = [Maneuvers;Activities;Access];
           [~, idx] = sort(app.SoE{:,'Start Time'},'ascend');       % SoE{:,'Start Time'} -> datetime. Sort chronol with start time
           app.SoE = app.SoE(idx,:);
           SoE_activities_id = 1:height(app.SoE);
           app.SoE = [table(SoE_activities_id'),app.SoE];
           
           % Activity Table 
           app.SoETable.Data = app.SoE;

        end

        % Button pushed function: ConflictCheckButton
        function ConflictCheckButtonPushed(app, event)
        % Button checks for conflicts (overlap) of activities in SoE, shows
        % results in table: ConflictOutputTable and adds column with Time 
        % between activiteies in table: SoETable
            
        activity_start = datetime(app.SoE{:,'Start Time'});
        activity_stop = datetime(app.SoE{:,'Stop Time'});
        activity_start(1) = []; % delete first activity start time
        activity_stop(end) = [];  % delete last activity stop time

        time_between_activites = activity_start-activity_stop; % calculate duration between stop time of acitivty (activity_i) 
        % and start time of next activity (activity_i+1) 
        % If one of the durtions is negative e.g. -00:05:00, there is a conflict

        index_conflict = find(time_between_activites<0);    % finds index of duration<0
        number_conflicts = length(index_conflict);          % number of conflicts
        
        % Conflict output Table: ConflictOutputTable
        number_conf_out = append("Number Conflicts: ",string(number_conflicts));
        shortest_time_conflict = append("Shortest time between activities: ",string(min(time_between_activites)));
        app.ConflictOutputTable.Data = [number_conf_out; shortest_time_conflict];

        
        % Add column with Time between activiteies in table: SoETable
        timeBactivity = table([time_between_activites;duration('00:00:00')]);
        timeBactivity.Properties.VariableNames = "Time between Activities";
        app.SoETable.Data = [app.SoE, timeBactivity];
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CLIMBPredictorUIFigure = uifigure('Visible', 'off');
            app.CLIMBPredictorUIFigure.Name = 'CLIMB Predictor';
            app.CLIMBPredictorUIFigure.CloseRequestFcn = createCallbackFcn(app, @CLIMBPredictorUIFigureCloseRequest, true);
            app.PowertoolMenu = uimenu(app.CLIMBPredictorUIFigure);
            app.PowertoolMenu.MenuSelectedFcn = createCallbackFcn(app, @PowertoolMenuSelected, true);
            app.PowertoolMenu.Text = 'Power tool';
            app.PowermodesMenu = uimenu(app.PowertoolMenu);
            app.PowermodesMenu.MenuSelectedFcn = createCallbackFcn(app, @PowermodesMenuSelected, true);
            app.PowermodesMenu.Text = 'Power modes';
            app.PowerconsumptiondataMenu = uimenu(app.PowertoolMenu);
            app.PowerconsumptiondataMenu.MenuSelectedFcn = createCallbackFcn(app, @PowerconsumptiondataMenuSelected, true);
            app.PowerconsumptiondataMenu.Text = 'Power consumption data';
            app.BatterymodeldataMenu = uimenu(app.PowertoolMenu);
            app.BatterymodeldataMenu.MenuSelectedFcn = createCallbackFcn(app, @BatterymodeldataMenuSelected, true);
            app.BatterymodeldataMenu.Text = 'Battery model data';
            app.TorquetoolMenu = uimenu(app.CLIMBPredictorUIFigure);
            app.TorquetoolMenu.MenuSelectedFcn = createCallbackFcn(app, @TorquetoolMenuSelected, true);
            app.TorquetoolMenu.Text = 'Torque tool';
            app.TorquetooldataMenu = uimenu(app.TorquetoolMenu);
            app.TorquetooldataMenu.MenuSelectedFcn = createCallbackFcn(app, @TorquetooldataMenuSelected, true);
            app.TorquetooldataMenu.Text = 'Torque tool data';
            app.Menu = uimenu(app.CLIMBPredictorUIFigure);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.CLIMBPredictorUIFigure);
            app.ScheduleTab = uitab(app.TabGroup);
            app.ScheduleTab.Title = 'Schedule';
            app.SchedulePlot_2 = uiaxes(app.ScheduleTab);
            title(app.SchedulePlot_2, 'Schdedule Plot')
            zlabel(app.SchedulePlot_2, 'Z')
            app.PlotScheduleButton = uibutton(app.ScheduleTab, 'push');
            app.PlotScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @PlotScheduleButtonPushed3, true);
            app.PlotScheduleButton.Text = 'Plot Schedule';
            app.TabGroup2 = uitabgroup(app.ScheduleTab);
            app.InitialStateTab = uitab(app.TabGroup2);
            app.InitialStateTab.Title = 'Initial State';
            app.TLEInfoTable = uitable(app.InitialStateTab);
            app.TLEInfoTable.ColumnName = {''; ''};
            app.TLEInfoTable.RowName = {};
            app.TLEInfoRequestButton = uibutton(app.InitialStateTab, 'push');
            app.TLEInfoRequestButton.ButtonPushedFcn = createCallbackFcn(app, @TLEInfoRequestButtonPushed, true);
            app.TLEInfoRequestButton.Text = 'TLE Info Request';
            app.PropagateButton = uibutton(app.InitialStateTab, 'push');
            app.PropagateButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateButtonPushed, true);
            app.PropagateButton.Text = 'Propagate';
            app.ScenarioStartTimeEditFieldLabel = uilabel(app.InitialStateTab);
            app.ScenarioStartTimeEditFieldLabel.Text = 'Scenario Start  Time';
            app.ScenarioStartTimeEditField = uieditfield(app.InitialStateTab, 'text');
            app.ScenarioStartTimeEditField.Value = '6 Aug 2023 20:15:04.320';
            app.ScenarioStopTimeEditFieldLabel = uilabel(app.InitialStateTab);
            app.ScenarioStopTimeEditFieldLabel.Text = 'Scenario Stop Time';
            app.ScenarioStopTimeEditField = uieditfield(app.InitialStateTab, 'text');
            app.ScenarioStopTimeEditField.Value = '7 Aug 2023 20:15:04.320';
            app.AccessTableInfo_1 = uitable(app.InitialStateTab);
            app.AccessTableInfo_1.ColumnName = {'ID'; 'Access Window Start'; 'Access Window Start'; 'Accesss Duration (min)'; 'Max Elevation (deg)'};
            app.AccessTableInfo_1.RowName = {};
            app.PerigeePassDateTableInfo = uitable(app.InitialStateTab);
            app.PerigeePassDateTableInfo.ColumnName = {''; 'Perigee Pass Date'};
            app.PerigeePassDateTableInfo.RowName = {};
            app.ConsoleLogTextAreaLabel = uilabel(app.InitialStateTab);
            app.ConsoleLogTextAreaLabel.Text = 'Console Log:';
            app.ConsoleLogTextArea = uitextarea(app.InitialStateTab);
            app.ConsoleLogTextArea.Editable = 'off';
            app.ConsoleLogTextArea.Value = {'Logging'};
            app.AccessDataLabel = uilabel(app.InitialStateTab);
            app.AccessDataLabel.Text = 'Access Data';
            app.PerigeePassesLabel = uilabel(app.InitialStateTab);
            app.PerigeePassesLabel.Text = 'Perigee Passes';
            app.ManeuverSelectionTab = uitab(app.TabGroup2);
            app.ManeuverSelectionTab.Title = 'Maneuver Selection';
            app.ManeuverInputTable = uitable(app.ManeuverSelectionTab);
            app.ManeuverInputTable.ColumnName = {''; 'Perigee Pass Time'; 'Maneuver Select'; 'Maneuver Duration (min)'};
            app.ManeuverInputTable.RowName = {};
            app.ManeuverInputTable.ColumnEditable = [false true true];
            app.PropagateManeuversButton = uibutton(app.ManeuverSelectionTab, 'push');
            app.PropagateManeuversButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateManeuversButtonPushed, true);
            app.PropagateManeuversButton.Text = 'Propagate Maneuvers';
            app.ManeuverOutputTable = uitable(app.ManeuverSelectionTab);
            app.ManeuverOutputTable.ColumnName = {'ID'; 'Start Time'; 'Stop Time'; 'Duration (min)'; 'Delta-V (m/s)'};
            app.ManeuverOutputTable.RowName = {};
            app.ManOutputGlobalTable = uitable(app.ManeuverSelectionTab);
            app.ManOutputGlobalTable.ColumnName = {''; ''};
            app.ManOutputGlobalTable.RowName = {};
            app.ManeuverDurationinminEditFieldLabel = uilabel(app.ManeuverSelectionTab);
            app.ManeuverDurationinminEditFieldLabel.Text = 'Maneuver Duration in (min)';
            app.ManeuverDurationinminEditField = uieditfield(app.ManeuverSelectionTab, 'numeric');
            app.ManeuverDurationinminEditField.Value = 10;
            app.IspsEditFieldLabel = uilabel(app.ManeuverSelectionTab);
            app.IspsEditFieldLabel.Text = 'Isp (s)';
            app.IspsEditField = uieditfield(app.ManeuverSelectionTab, 'numeric');
            app.IspsEditField.Value = 1900;
            app.ThrustNEditFieldLabel = uilabel(app.ManeuverSelectionTab);
            app.ThrustNEditFieldLabel.Text = 'Thrust (N)';
            app.ThrustNEditField = uieditfield(app.ManeuverSelectionTab, 'numeric');
            app.ThrustNEditField.Value = 0.00035;
            app.SetforallManeuversButton = uibutton(app.ManeuverSelectionTab, 'push');
            app.SetforallManeuversButton.Text = 'Set for all Maneuvers';
            app.ActivityNameEditFieldLabel_4 = uilabel(app.ManeuverSelectionTab);
            app.ActivityNameEditFieldLabel_4.Text = 'Maneuver Output';
            app.ActivityNameEditFieldLabel_5 = uilabel(app.ManeuverSelectionTab);
            app.ActivityNameEditFieldLabel_5.Text = 'Maneuver Selection';
            app.ThrusterEfficiencyEditFieldLabel = uilabel(app.ManeuverSelectionTab);
            app.ThrusterEfficiencyEditFieldLabel.Text = 'Thruster Efficiency';
            app.ThrusterEfficiencyEditField = uieditfield(app.ManeuverSelectionTab, 'numeric');
            app.ThrusterEfficiencyEditField.Value = 1;
            app.AcitvitySchedulerTab = uitab(app.TabGroup2);
            app.AcitvitySchedulerTab.Title = 'Acitvity Scheduler';
            app.AccessTableInfo_2 = uitable(app.AcitvitySchedulerTab);
            app.AccessTableInfo_2.ColumnName = {'Access Start'; 'Access End'; 'Access duration'; 'Access Select'; 'Access Type'};
            app.AccessTableInfo_2.RowName = {};
            app.AccessTableInfo_2.ColumnEditable = [false false false true true];
            app.ActivityOutputTable = uitable(app.AcitvitySchedulerTab);
            app.ActivityOutputTable.ColumnName = {'Activity ID'; 'Activity Name'; 'Start Time'; 'Stop Time'; 'Duration (min)'; 'Power (W)'};
            app.ActivityOutputTable.RowName = {};
            app.AddActivityButton = uibutton(app.AcitvitySchedulerTab, 'push');
            app.AddActivityButton.ButtonPushedFcn = createCallbackFcn(app, @AddActivityButtonPushed, true);
            app.AddActivityButton.Text = 'Add Activity';
            app.PerigeePassDateTableInfo_2 = uitable(app.AcitvitySchedulerTab);
            app.PerigeePassDateTableInfo_2.ColumnName = {''; 'Perigee Pass Time'};
            app.PerigeePassDateTableInfo_2.RowName = {};
            app.PerigeePassDateTableInfo_2.ColumnEditable = [true true true true];
            app.ActivityNameEditFieldLabel = uilabel(app.AcitvitySchedulerTab);
            app.ActivityNameEditFieldLabel.Text = 'Activity Name';
            app.ActivityNameEditField = uieditfield(app.AcitvitySchedulerTab, 'text');
            app.ActivityNameEditField.Value = 'Science Activity';
            app.ActivityStartTimeEditFieldLabel = uilabel(app.AcitvitySchedulerTab);
            app.ActivityStartTimeEditFieldLabel.Text = 'Activity Start Time ';
            app.ActivityStartTimeEditField = uieditfield(app.AcitvitySchedulerTab, 'text');
            app.ActivityStartTimeEditField.Value = '7 Aug 2023 02:15:00.000';
            app.ActivityStopTimeEditFieldLabel = uilabel(app.AcitvitySchedulerTab);
            app.ActivityStopTimeEditFieldLabel.Text = 'Activity Stop Time ';
            app.ActivityStopTimeEditField = uieditfield(app.AcitvitySchedulerTab, 'text');
            app.ActivityStopTimeEditField.Value = '7 Aug 2023 03:00:00.000';
            app.PowerWEditFieldLabel = uilabel(app.AcitvitySchedulerTab);
            app.PowerWEditFieldLabel.Text = 'Power (W)';
            app.PowerWEditField = uieditfield(app.AcitvitySchedulerTab, 'numeric');
            app.ActivityNameEditFieldLabel_2 = uilabel(app.AcitvitySchedulerTab);
            app.ActivityNameEditFieldLabel_2.Text = 'Custom Activities';
            app.ActivityNameEditFieldLabel_3 = uilabel(app.AcitvitySchedulerTab);
            app.ActivityNameEditFieldLabel_3.Text = 'Ground Contact Selection';
            app.SequenceofEventsTab = uitab(app.TabGroup2);
            app.SequenceofEventsTab.Title = 'Sequence of Events';
            app.SoETable = uitable(app.SequenceofEventsTab);
            app.SoETable.ColumnName = {'ID'; 'Name'; 'Start Time'; 'Stop Time'; 'Duration'; 'Time to next Activity'};
            app.SoETable.RowName = {};
            app.CreateActivityScheduleButton = uibutton(app.SequenceofEventsTab, 'push');
            app.CreateActivityScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @CreateActivityScheduleButtonPushed, true);
            app.CreateActivityScheduleButton.Text = 'Create Activity Schedule';
            app.ConflictCheckButton = uibutton(app.SequenceofEventsTab, 'push');
            app.ConflictCheckButton.ButtonPushedFcn = createCallbackFcn(app, @ConflictCheckButtonPushed, true);
            app.ConflictCheckButton.Text = 'Conflict Check';
            app.ConflictOutputTable = uitable(app.SequenceofEventsTab);
            app.ConflictOutputTable.ColumnName = '';
            app.ConflictOutputTable.RowName = {};
            app.LongTermPropulsoinToolTab = uitab(app.TabGroup);
            app.LongTermPropulsoinToolTab.Title = 'Long-Term Propulsoin Tool';
            app.UIAxes = uiaxes(app.LongTermPropulsoinToolTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ApogeeAltitudekmEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.ApogeeAltitudekmEditFieldLabel.Text = 'Apogee Altitude (km)';
            app.ApogeeAltitudekmEditField = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.MaxIterationsEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.UITable = uitable(app.LongTermPropulsoinToolTab);
            app.UITable.ColumnName = {'Time to reach orbit'; 'Apogee increase'; 'dv '; 'Column 4'};
            app.UITable.RowName = {};
            app.ManeuverDurationinminEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.ManeuverDurationinminEditField_2Label.Text = 'Maneuver Duration in (min)';
            app.ManeuverDurationinminEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.ManeuverDurationinminEditField_2.Value = 10;
            app.IspsEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.IspsEditField_2Label.Text = 'Isp (s)';
            app.IspsEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.IspsEditField_2.Value = 1900;
            app.ThrustNEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.ThrustNEditField_2Label.Text = 'Thrust (N)';
            app.ThrustNEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.ThrustNEditField_2.Value = 0.00035;
            app.ThrusterEfficiencyEditField_2Label = uilabel(app.LongTermPropulsoinToolTab);
            app.ThrusterEfficiencyEditField_2Label.Text = 'Thruster Efficiency';
            app.ThrusterEfficiencyEditField_2 = uieditfield(app.LongTermPropulsoinToolTab, 'numeric');
            app.ThrusterEfficiencyEditField_2.Value = 1;
            app.StopTimeEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.LongTermPropulsoinToolTab, 'text');
            app.StopTimeEditField.Value = '6 Aug 2024 20:15:04.320';
            app.StartTimeEditFieldLabel = uilabel(app.LongTermPropulsoinToolTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.LongTermPropulsoinToolTab, 'text');
            app.StartTimeEditField.Value = '6 Aug 2023 20:15:04.320';
            app.PropagateButton_2 = uibutton(app.LongTermPropulsoinToolTab, 'push');
            app.PropagateButton_2.Text = 'Propagate';
            app.ConstraintAnalysisTab = uitab(app.TabGroup);
            app.ConstraintAnalysisTab.Title = 'Constraint Analysis';
            app.CreateSTKButton = uibutton(app.ConstraintAnalysisTab, 'push');
            app.CreateSTKButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSTKButtonPushed, true);
            app.CreateSTKButton.Text = 'Create STK';
            app.Calculatefortheupcoming72hoursbasedonthecurrenttimetableLabel = uilabel(app.ConstraintAnalysisTab);
            app.Calculatefortheupcoming72hoursbasedonthecurrenttimetableLabel.Text = 'Create a scenario for the upcoming 72 hours based on the current timetable.';
            % Show the figure after all components are created
            app.CLIMBPredictorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Predictor_app_v3_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CLIMBPredictorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLIMBPredictorUIFigure)
        end
    end
end