classdef power_tool_modeSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CSVfilegeneratedLabel           matlab.ui.control.Label
        ScenarioendtimestampTextArea    matlab.ui.control.TextArea
        ScenarioendtimestampTextAreaLabel  matlab.ui.control.Label
        ScenariostarttimestampTextArea  matlab.ui.control.TextArea
        ScenariostarttimestampTextAreaLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        StartdateDatePicker_Cruise_4    matlab.ui.control.DatePicker
        StartdateDatePicker_3Label_5    matlab.ui.control.Label
        EnddateDatePicker_Cruise_4      matlab.ui.control.DatePicker
        EnddateDatePicker_3Label_5      matlab.ui.control.Label
        StartdateDatePicker_Cruise_3    matlab.ui.control.DatePicker
        StartdateDatePicker_3Label_4    matlab.ui.control.Label
        EnddateDatePicker_Cruise_3      matlab.ui.control.DatePicker
        EnddateDatePicker_3Label_4      matlab.ui.control.Label
        StartdateDatePicker_3Label_3    matlab.ui.control.Label
        EnddateDatePicker_3Label_3      matlab.ui.control.Label
        secSpinner_10                   matlab.ui.control.Spinner
        secSpinner_10Label              matlab.ui.control.Label
        minSpinner_10                   matlab.ui.control.Spinner
        minSpinner_10Label              matlab.ui.control.Label
        hourSpinner_10                  matlab.ui.control.Spinner
        hourSpinner_10Label             matlab.ui.control.Label
        secSpinner_9                    matlab.ui.control.Spinner
        secSpinner_9Label               matlab.ui.control.Label
        minSpinner_9                    matlab.ui.control.Spinner
        minSpinner_9Label               matlab.ui.control.Label
        hourSpinner_9                   matlab.ui.control.Spinner
        hourSpinner_9Label              matlab.ui.control.Label
        ActiveCheckBox_Safe             matlab.ui.control.CheckBox
        SafemodeLabel                   matlab.ui.control.Label
        AllthetimeCheckBox_5            matlab.ui.control.CheckBox
        secSpinner_8                    matlab.ui.control.Spinner
        secSpinner_8Label               matlab.ui.control.Label
        minSpinner_8                    matlab.ui.control.Spinner
        minSpinner_8Label               matlab.ui.control.Label
        hourSpinner_8                   matlab.ui.control.Spinner
        hourSpinner_8Label              matlab.ui.control.Label
        secSpinner_7                    matlab.ui.control.Spinner
        secSpinner_7Label               matlab.ui.control.Label
        minSpinner_7                    matlab.ui.control.Spinner
        minSpinner_7Label               matlab.ui.control.Label
        hourSpinner_7                   matlab.ui.control.Spinner
        hourSpinner_7Label              matlab.ui.control.Label
        ActiveCheckBox_Recovery         matlab.ui.control.CheckBox
        RecoverymodeLabel               matlab.ui.control.Label
        AllthetimeCheckBox_4            matlab.ui.control.CheckBox
        secSpinner_6Label               matlab.ui.control.Label
        minSpinner_6Label               matlab.ui.control.Label
        hourSpinner_6Label              matlab.ui.control.Label
        EnddateDatePicker_3Label        matlab.ui.control.Label
        secSpinner_5Label               matlab.ui.control.Label
        minSpinner_5Label               matlab.ui.control.Label
        hourSpinner_5Label              matlab.ui.control.Label
        StartdateDatePicker_3Label      matlab.ui.control.Label
        secSpinner_4                    matlab.ui.control.Spinner
        secSpinner_4Label               matlab.ui.control.Label
        minSpinner_4                    matlab.ui.control.Spinner
        minSpinner_4Label               matlab.ui.control.Label
        hourSpinner_4                   matlab.ui.control.Spinner
        hourSpinner_4Label              matlab.ui.control.Label
        secSpinner_3                    matlab.ui.control.Spinner
        secSpinner_3Label               matlab.ui.control.Label
        minSpinner_3                    matlab.ui.control.Spinner
        minSpinner_3Label               matlab.ui.control.Label
        hourSpinner_3                   matlab.ui.control.Spinner
        EnddateDatePicker_Science       matlab.ui.control.DatePicker
        StartdateDatePicker_Science     matlab.ui.control.DatePicker
        hourSpinner_3Label              matlab.ui.control.Label
        CruisemodedefaultLabel          matlab.ui.control.Label
        SciencemodeLabel                matlab.ui.control.Label
        AllthetimeCheckBox_Science      matlab.ui.control.CheckBox
        ActiveCheckBox_Science          matlab.ui.control.CheckBox
        secSpinner_Cruise_End           matlab.ui.control.Spinner
        minSpinner_Cruise_End           matlab.ui.control.Spinner
        hourSpinner_Cruise_End          matlab.ui.control.Spinner
        EnddateDatePicker_Cruise        matlab.ui.control.DatePicker
        secSpinner_Cruise_Start         matlab.ui.control.Spinner
        minSpinner_Cruise_Start         matlab.ui.control.Spinner
        hourSpinner_Cruise_Start        matlab.ui.control.Spinner
        StartdateDatePicker_Cruise      matlab.ui.control.DatePicker
        AllthetimeCheckBox_Cruise       matlab.ui.control.CheckBox
        ActiveCheckBox_Cruise           matlab.ui.control.CheckBox
        GenerateCSVFileButton           matlab.ui.control.Button
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = public)
        %DefaultMode = 'Cruise mode' 
        ScenarioStartTime_Obj;
        ScenarioStartTime_Str;
        ScenarioEndTime_Obj;
        ScenarioEndTime_Str;
         

    end
    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            scenario_times_table = readtable('Scenario_time.csv','Delimiter',',');
            scenario_times_array = table2array(scenario_times_table);
            app.ScenarioStartTime_Obj = datetime(scenario_times_array(1),'InputFormat', 'd MMM yyyy HH:mm:ss.SSS');
            app.ScenarioStartTime_Str = datestr(app.ScenarioStartTime_Obj, 'dd mmm yyyy HH:MM:SS');
            app.ScenarioEndTime_Obj = datetime(scenario_times_array(2),'InputFormat', 'd MMM yyyy HH:mm:ss.SSS');
            app.ScenarioEndTime_Str = datestr(app.ScenarioEndTime_Obj, 'dd mmm yyyy HH:MM:SS');

            app.ScenariostarttimestampTextArea.Value = app.ScenarioStartTime_Str;
            app.ScenarioendtimestampTextArea.Value = app.ScenarioEndTime_Str;

            app.StartdateDatePicker_Cruise.Limits = [app.ScenarioStartTime_Obj app.ScenarioEndTime_Obj];
            app.EnddateDatePicker_Cruise.Limits = [app.ScenarioStartTime_Obj app.ScenarioEndTime_Obj];
            % REPEAT THIS FOR EVERY DATE PICKER
            app.CallingApp = caller;
            
            
        end

        % Button pushed function: GenerateCSVFileButton
        function GenerateCSVFileButtonPushed(app, event)
           clc          

            CruiseModeOn = app.ActiveCheckBox_Cruise.Value;

            if app.AllthetimeCheckBox_Cruise.Value == 0 %if "All the time" checkbox is unchecked(False)
              
                %%% Cruise START date and time selection 
                CruiseModeStart_Str_day = datestr(app.StartdateDatePicker_Cruise.Value, 'dd mmm yyyy');
                
                hours_counter_cruise_start = app.hourSpinner_Cruise_Start.Value;
                minutes_counter_cruise_start = app.minSpinner_Cruise_Start.Value;
                seconds_counter_cruise_start = app.secSpinner_Cruise_Start.Value;
                
                if hours_counter_cruise_start<10
                hours_counter_cruise_start_Str = "0"+hours_counter_cruise_start;
                else
                hours_counter_cruise_start_Str = ""+hours_counter_cruise_start;
                end

                if minutes_counter_cruise_start<10
                minutes_counter_cruise_start_Str = "0"+minutes_counter_cruise_start;
                else
                minutes_counter_cruise_start_Str = ""+minutes_counter_cruise_start;
                end

                if seconds_counter_cruise_start<10
                seconds_counter_cruise_start_Str = "0"+seconds_counter_cruise_start;
                else
                seconds_counter_cruise_start_Str = ""+seconds_counter_cruise_start;
                end

                CruiseModeStart_Str_Hms = "" + hours_counter_cruise_start_Str + ":"+ minutes_counter_cruise_start_Str + ":" +seconds_counter_cruise_start_Str;
                
                CruiseModeStart_Str = CruiseModeStart_Str_day+" "+CruiseModeStart_Str_Hms;
                
                %%%

                %%% Cruise END date and time selection
                CruiseModeEnd_Str_day = datestr(app.EnddateDatePicker_Cruise.Value, 'dd mmm yyyy');
                
                hours_counter_cruise_End = app.hourSpinner_Cruise_End.Value;
                minutes_counter_cruise_End = app.minSpinner_Cruise_End.Value;
                seconds_counter_cruise_End = app.secSpinner_Cruise_End.Value;
                
                if hours_counter_cruise_End<10
                hours_counter_cruise_End_Str = "0"+hours_counter_cruise_End;
                else
                hours_counter_cruise_End_Str = ""+hours_counter_cruise_End;
                end

                if minutes_counter_cruise_End<10
                minutes_counter_cruise_End_Str = "0"+minutes_counter_cruise_End;
                else
                minutes_counter_cruise_End_Str = ""+minutes_counter_cruise_End;
                end

                if seconds_counter_cruise_End<10
                seconds_counter_cruise_End_Str = "0"+seconds_counter_cruise_End;
                else
                seconds_counter_cruise_End_Str = ""+seconds_counter_cruise_End;
                end

                CruiseModeEnd_Str_Hms = "" + hours_counter_cruise_End_Str + ":"+ minutes_counter_cruise_End_Str + ":" +seconds_counter_cruise_End_Str;

                CruiseModeEnd_Str = CruiseModeEnd_Str_day+" "+CruiseModeEnd_Str_Hms;
               
                %%%         
                
            else %if "All the time" checkbox is checked(True)
                CruiseModeStart_Str = app.ScenarioStartTime_Str;
                CruiseModeEnd_Str = app.ScenarioEndTime_Str;
            end


            ModeSelectionArray(1,:) = ["CruiseModeOn" "CruiseModeStart" "CruiseModeEnd" "ScienceModeOn" "ScienceModeStart" "ScienceModeEnd" "RecoveryModeOn" "RecoveryModeStart" "RecoveryModeEnd" "SafeModeOn" "SafeModeStart" "SafeModeEnd"];
            ModeSelectionArray(2,:) = [CruiseModeOn CruiseModeStart_Str CruiseModeEnd_Str "AAA" "AAA" "AAA" "AAA" "AAA" "AAA" "AAA" "AAA" "AAA"];
          
            writematrix(ModeSelectionArray,'C:\CLIMB Operation\ModeSelection.csv','Delimiter',',')
            
            app.CSVfilegeneratedLabel.Visible = "on";
            pause(3)
            app.CSVfilegeneratedLabel.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Power modes selection tool';
            app.GenerateCSVFileButton = uibutton(app.UIFigure, 'push');
            app.GenerateCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCSVFileButtonPushed, true);
            app.GenerateCSVFileButton.Text = 'Generate CSV File';
            app.Panel = uipanel(app.UIFigure);
            app.ActiveCheckBox_Cruise = uicheckbox(app.Panel);
            app.ActiveCheckBox_Cruise.Text = 'Active';
            app.ActiveCheckBox_Cruise.Value = true;
            app.AllthetimeCheckBox_Cruise = uicheckbox(app.Panel);
            app.AllthetimeCheckBox_Cruise.Text = 'All the time';
            app.AllthetimeCheckBox_Cruise.Value = true;
            app.StartdateDatePicker_Cruise = uidatepicker(app.Panel);
            app.StartdateDatePicker_Cruise.DisplayFormat = 'dd MMM uuuu';
            app.hourSpinner_Cruise_Start = uispinner(app.Panel);
            app.hourSpinner_Cruise_Start.Limits = [0 23];
            app.hourSpinner_Cruise_Start.RoundFractionalValues = 'on';
            app.hourSpinner_Cruise_Start.ValueDisplayFormat = '%.0f';
            app.minSpinner_Cruise_Start = uispinner(app.Panel);
            app.minSpinner_Cruise_Start.Limits = [0 60];
            app.minSpinner_Cruise_Start.ValueDisplayFormat = '%.0f';
            app.secSpinner_Cruise_Start = uispinner(app.Panel);
            app.secSpinner_Cruise_Start.Limits = [0 60];
            app.secSpinner_Cruise_Start.ValueDisplayFormat = '%.0f';
            app.EnddateDatePicker_Cruise = uidatepicker(app.Panel);
            app.EnddateDatePicker_Cruise.DisplayFormat = 'dd MMM uuuu';
            app.hourSpinner_Cruise_End = uispinner(app.Panel);
            app.hourSpinner_Cruise_End.Limits = [0 23];
            app.hourSpinner_Cruise_End.RoundFractionalValues = 'on';
            app.hourSpinner_Cruise_End.ValueDisplayFormat = '%.0f';
            app.minSpinner_Cruise_End = uispinner(app.Panel);
            app.minSpinner_Cruise_End.Limits = [0 60];
            app.minSpinner_Cruise_End.ValueDisplayFormat = '%.0f';
            app.secSpinner_Cruise_End = uispinner(app.Panel);
            app.secSpinner_Cruise_End.Limits = [0 60];
            app.secSpinner_Cruise_End.ValueDisplayFormat = '%.0f';
            app.ActiveCheckBox_Science = uicheckbox(app.Panel);
            app.ActiveCheckBox_Science.Text = 'Active';
            app.AllthetimeCheckBox_Science = uicheckbox(app.Panel);
            app.AllthetimeCheckBox_Science.Text = 'All the time';
            app.SciencemodeLabel = uilabel(app.Panel);
            app.SciencemodeLabel.Text = 'Science mode';
            app.CruisemodedefaultLabel = uilabel(app.Panel);
            app.CruisemodedefaultLabel.Text = 'Cruise mode(default)';
            app.hourSpinner_3Label = uilabel(app.Panel);
            app.hourSpinner_3Label.Text = 'hour';
            app.StartdateDatePicker_Science = uidatepicker(app.Panel);
            app.StartdateDatePicker_Science.DisplayFormat = 'dd MMM uuuu';
            app.EnddateDatePicker_Science = uidatepicker(app.Panel);
            app.EnddateDatePicker_Science.DisplayFormat = 'dd MMM uuuu';
            app.hourSpinner_3 = uispinner(app.Panel);
            app.hourSpinner_3.Limits = [0 23];
            app.hourSpinner_3.RoundFractionalValues = 'on';
            app.hourSpinner_3.ValueDisplayFormat = '%.0f';
            app.minSpinner_3Label = uilabel(app.Panel);
            app.minSpinner_3Label.Text = 'min';
            app.minSpinner_3 = uispinner(app.Panel);
            app.minSpinner_3.Limits = [0 60];
            app.minSpinner_3.ValueDisplayFormat = '%.0f';
            app.secSpinner_3Label = uilabel(app.Panel);
            app.secSpinner_3Label.Text = 'sec';
            app.secSpinner_3 = uispinner(app.Panel);
            app.secSpinner_3.Limits = [0 60];
            app.secSpinner_3.ValueDisplayFormat = '%.0f';
            app.hourSpinner_4Label = uilabel(app.Panel);
            app.hourSpinner_4Label.Text = 'hour';
            app.hourSpinner_4 = uispinner(app.Panel);
            app.hourSpinner_4.Limits = [0 23];
            app.hourSpinner_4.RoundFractionalValues = 'on';
            app.hourSpinner_4.ValueDisplayFormat = '%.0f';
            app.minSpinner_4Label = uilabel(app.Panel);
            app.minSpinner_4Label.Text = 'min';
            app.minSpinner_4 = uispinner(app.Panel);
            app.minSpinner_4.Limits = [0 60];
            app.minSpinner_4.ValueDisplayFormat = '%.0f';
            app.secSpinner_4Label = uilabel(app.Panel);
            app.secSpinner_4Label.Text = 'sec';
            app.secSpinner_4 = uispinner(app.Panel);
            app.secSpinner_4.Limits = [0 60];
            app.secSpinner_4.ValueDisplayFormat = '%.0f';
            app.StartdateDatePicker_3Label = uilabel(app.Panel);
            app.StartdateDatePicker_3Label.Text = 'Start date';
            app.hourSpinner_5Label = uilabel(app.Panel);
            app.hourSpinner_5Label.Text = 'hour';
            app.minSpinner_5Label = uilabel(app.Panel);
            app.minSpinner_5Label.Text = 'min';
            app.secSpinner_5Label = uilabel(app.Panel);
            app.secSpinner_5Label.Text = 'sec';
            app.EnddateDatePicker_3Label = uilabel(app.Panel);
            app.EnddateDatePicker_3Label.Text = 'End date';
            app.hourSpinner_6Label = uilabel(app.Panel);
            app.hourSpinner_6Label.Text = 'hour';
            app.minSpinner_6Label = uilabel(app.Panel);
            app.minSpinner_6Label.Text = 'min';
            app.secSpinner_6Label = uilabel(app.Panel);
            app.secSpinner_6Label.Text = 'sec';
            app.AllthetimeCheckBox_4 = uicheckbox(app.Panel);
            app.AllthetimeCheckBox_4.Text = 'All the time';
            app.RecoverymodeLabel = uilabel(app.Panel);
            app.RecoverymodeLabel.Text = 'Recovery mode';
            app.ActiveCheckBox_Recovery = uicheckbox(app.Panel);
            app.ActiveCheckBox_Recovery.Text = 'Active';
            app.hourSpinner_7Label = uilabel(app.Panel);
            app.hourSpinner_7Label.Text = 'hour';
            app.hourSpinner_7 = uispinner(app.Panel);
            app.hourSpinner_7.Limits = [0 23];
            app.hourSpinner_7.RoundFractionalValues = 'on';
            app.hourSpinner_7.ValueDisplayFormat = '%.0f';
            app.minSpinner_7Label = uilabel(app.Panel);
            app.minSpinner_7Label.Text = 'min';
            app.minSpinner_7 = uispinner(app.Panel);
            app.minSpinner_7.Limits = [0 60];
            app.minSpinner_7.ValueDisplayFormat = '%.0f';
            app.secSpinner_7Label = uilabel(app.Panel);
            app.secSpinner_7Label.Text = 'sec';
            app.secSpinner_7 = uispinner(app.Panel);
            app.secSpinner_7.Limits = [0 60];
            app.secSpinner_7.ValueDisplayFormat = '%.0f';
            app.hourSpinner_8Label = uilabel(app.Panel);
            app.hourSpinner_8Label.Text = 'hour';
            app.hourSpinner_8 = uispinner(app.Panel);
            app.hourSpinner_8.Limits = [0 23];
            app.hourSpinner_8.RoundFractionalValues = 'on';
            app.hourSpinner_8.ValueDisplayFormat = '%.0f';
            app.minSpinner_8Label = uilabel(app.Panel);
            app.minSpinner_8Label.Text = 'min';
            app.minSpinner_8 = uispinner(app.Panel);
            app.minSpinner_8.Limits = [0 60];
            app.minSpinner_8.ValueDisplayFormat = '%.0f';
            app.secSpinner_8Label = uilabel(app.Panel);
            app.secSpinner_8Label.Text = 'sec';
            app.secSpinner_8 = uispinner(app.Panel);
            app.secSpinner_8.Limits = [0 60];
            app.secSpinner_8.ValueDisplayFormat = '%.0f';
            app.AllthetimeCheckBox_5 = uicheckbox(app.Panel);
            app.AllthetimeCheckBox_5.Text = 'All the time';
            app.SafemodeLabel = uilabel(app.Panel);
            app.SafemodeLabel.Text = 'Safe mode';
            app.ActiveCheckBox_Safe = uicheckbox(app.Panel);
            app.ActiveCheckBox_Safe.Text = 'Active';
            app.hourSpinner_9Label = uilabel(app.Panel);
            app.hourSpinner_9Label.Text = 'hour';
            app.hourSpinner_9 = uispinner(app.Panel);
            app.hourSpinner_9.Limits = [0 23];
            app.hourSpinner_9.RoundFractionalValues = 'on';
            app.hourSpinner_9.ValueDisplayFormat = '%.0f';
            app.minSpinner_9Label = uilabel(app.Panel);
            app.minSpinner_9Label.Text = 'min';
            app.minSpinner_9 = uispinner(app.Panel);
            app.minSpinner_9.Limits = [0 60];
            app.minSpinner_9.ValueDisplayFormat = '%.0f';
            app.secSpinner_9Label = uilabel(app.Panel);
            app.secSpinner_9Label.Text = 'sec';
            app.secSpinner_9 = uispinner(app.Panel);
            app.secSpinner_9.Limits = [0 60];
            app.secSpinner_9.ValueDisplayFormat = '%.0f';
            app.hourSpinner_10Label = uilabel(app.Panel);
            app.hourSpinner_10Label.Text = 'hour';
            app.hourSpinner_10 = uispinner(app.Panel);
            app.hourSpinner_10.Limits = [0 23];
            app.hourSpinner_10.RoundFractionalValues = 'on';
            app.hourSpinner_10.ValueDisplayFormat = '%.0f';
            app.minSpinner_10Label = uilabel(app.Panel);
            app.minSpinner_10Label.Text = 'min';
            app.minSpinner_10 = uispinner(app.Panel);
            app.minSpinner_10.Limits = [0 60];
            app.minSpinner_10.ValueDisplayFormat = '%.0f';
            app.secSpinner_10Label = uilabel(app.Panel);
            app.secSpinner_10Label.Text = 'sec';
            app.secSpinner_10 = uispinner(app.Panel);
            app.secSpinner_10.Limits = [0 60];
            app.secSpinner_10.ValueDisplayFormat = '%.0f';
            app.EnddateDatePicker_3Label_3 = uilabel(app.Panel);
            app.EnddateDatePicker_3Label_3.Text = 'End date';
            app.StartdateDatePicker_3Label_3 = uilabel(app.Panel);
            app.StartdateDatePicker_3Label_3.Text = 'Start date';
            app.EnddateDatePicker_3Label_4 = uilabel(app.Panel);
            app.EnddateDatePicker_3Label_4.Text = 'End date';
            app.EnddateDatePicker_Cruise_3 = uidatepicker(app.Panel);
            app.EnddateDatePicker_Cruise_3.DisplayFormat = 'dd MMM uuuu';
            app.StartdateDatePicker_3Label_4 = uilabel(app.Panel);
            app.StartdateDatePicker_3Label_4.Text = 'Start date';
            app.StartdateDatePicker_Cruise_3 = uidatepicker(app.Panel);
            app.StartdateDatePicker_Cruise_3.DisplayFormat = 'dd MMM uuuu';
            app.EnddateDatePicker_3Label_5 = uilabel(app.Panel);
            app.EnddateDatePicker_3Label_5.Text = 'End date';
            app.EnddateDatePicker_Cruise_4 = uidatepicker(app.Panel);
            app.EnddateDatePicker_Cruise_4.DisplayFormat = 'dd MMM uuuu';
            app.StartdateDatePicker_3Label_5 = uilabel(app.Panel);
            app.StartdateDatePicker_3Label_5.Text = 'Start date';
            app.StartdateDatePicker_Cruise_4 = uidatepicker(app.Panel);
            app.StartdateDatePicker_Cruise_4.DisplayFormat = 'dd MMM uuuu';
            app.ScenariostarttimestampTextAreaLabel = uilabel(app.UIFigure);
            app.ScenariostarttimestampTextAreaLabel.Text = 'Scenario start timestamp';
            app.ScenariostarttimestampTextArea = uitextarea(app.UIFigure);
            app.ScenariostarttimestampTextArea.Editable = 'off';
            app.ScenarioendtimestampTextAreaLabel = uilabel(app.UIFigure);
            app.ScenarioendtimestampTextAreaLabel.Text = 'Scenario end timestamp';
            app.ScenarioendtimestampTextArea = uitextarea(app.UIFigure);
            app.ScenarioendtimestampTextArea.Editable = 'off';
            app.CSVfilegeneratedLabel = uilabel(app.UIFigure);
            app.CSVfilegeneratedLabel.Visible = 'off';
            app.CSVfilegeneratedLabel.Text = 'CSV file generated';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = power_tool_modeSelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end