classdef Predictor_app_v3_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLIMBPredictorUIFigure          matlab.ui.Figure
        PowertoolMenu                   matlab.ui.container.Menu
        PowermodesMenu                  matlab.ui.container.Menu
        PowerconsumptiondataMenu        matlab.ui.container.Menu
        BatterymodeldataMenu            matlab.ui.container.Menu
        TorquetoolMenu                  matlab.ui.container.Menu
        TorquetooldataMenu              matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        ResultsDropDown                 matlab.ui.control.DropDown
        ResultsDropDownLabel            matlab.ui.control.Label
        UITable5_2                      matlab.ui.control.Table
        UITable5                        matlab.ui.control.Table
        Calculatefortheupcoming72hoursbasedonthecurrenttimetableLabel  matlab.ui.control.Label
        CreateSTKButton                 matlab.ui.control.Button
        TLEInfoRequestButton            matlab.ui.control.Button
        ScenarioStopTimeEditField       matlab.ui.control.EditField
        ScenarioStopTimeEditFieldLabel  matlab.ui.control.Label
        ScenarioStartTimeEditField      matlab.ui.control.EditField
        ScenarioStartTimeEditFieldLabel  matlab.ui.control.Label
        ConsoleLogTextArea              matlab.ui.control.TextArea
        ConsoleLogTextAreaLabel         matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        PropagateButton                 matlab.ui.control.Button
        SchedulePlot                    matlab.ui.control.UIAxes
        ScheduleTab                     matlab.ui.container.Tab
        UITable8                        matlab.ui.control.Table
        UITable7                        matlab.ui.control.Table
        ThrustEditField                 matlab.ui.control.NumericEditField
        ThrustEditFieldLabel            matlab.ui.control.Label
        IspEditField                    matlab.ui.control.NumericEditField
        IspEditFieldLabel               matlab.ui.control.Label
        ManeuverDurationEditField       matlab.ui.control.NumericEditField
        ManeuverDurationEditFieldLabel  matlab.ui.control.Label
        PropagateManeuversButton        matlab.ui.control.Button
        PlotScheduleButton              matlab.ui.control.Button
        PowerEditField                  matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        UITable6_2                      matlab.ui.control.Table
        AddActivityButton               matlab.ui.control.Button
        UITable6                        matlab.ui.control.Table
        ActivityStopTimeEditField       matlab.ui.control.EditField
        ActivityStopTimeEditFieldLabel  matlab.ui.control.Label
        ActivityStartTimeEditField      matlab.ui.control.EditField
        ActivityStartTimeEditFieldLabel  matlab.ui.control.Label
        ActivityNameEditField           matlab.ui.control.EditField
        ActivityNameEditFieldLabel      matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        SchedulePlot_2                  matlab.ui.control.UIAxes
        PropulsionTab                   matlab.ui.container.Tab
        ConstraintAnalysisTab           matlab.ui.container.Tab
    end


    properties (Access = public) % To keep STK open after button release
       STKApp
       CLIMB
       root
       accessStartTimes
       accessStopTimes
       accessStartTimes_UTC
       accessStopTimes_UTC
       per_pass_time
       Start_Time
       End_Time

    end
    
    properties (Access = private)
        DialogApp % Description
        
    end
    methods (Access = public)

        function ConsoleLog(app,newstr)
            app.ConsoleLogTextArea.Value =  [app.ConsoleLogTextArea.Value; newstr];
            scroll(app.ConsoleLogTextArea, 'bottom');
            %app.ConsoleLogTextArea.Value = str;
        end

        % function TLE_info_text(app,newstr)
        %     app.TLEInfoTextArea.Value =  [app.TLEInfoTextArea.Value; newstr];
        %     scroll(app.TLEInfoTextArea, 'bottom');
        %     %app.ConsoleLogTextArea.Value = str;
        % end


        function [OE_array,Epoch_vect,End_Time,epoch_jul,epoch] = TLE_processing_fcn(app,fname)
                
                fid = fopen(fname, 'r');        % Open the TLE file for reading
                OE_array = [];                  % Initialize an empty array to store orbital elements
                Epoch_vect = [];
                mu = 398600; % Standard gravitational parameter for the earth
                
                % Read and process TLE sets from the file
                while ~feof(fid)
                % while fopen(fid)
            
                    % Read two lines from the file
                    L1c = fgets(fid);
                    L2c = fgets(fid);
                    
                    % Display the two lines
                    %fprintf(L1c);
                    %fprintf(L2c);
                    
                    % Extract relevant data from the lines
                    %L1 = sscanf(L1c,'%d%6d%*c%9d%*c%*2f%f%f%5d%*c%*d%5d%*c%*d%d%5d',[1,9])  % deletes first two digits (year) of epoch
            
                    L1 = sscanf(L1c,'%d%6d%*c%9d%*c%f%f%5d%*c%*d%5d%*c%*d%d%5d%*2f',[1,9]);   % keeps first two digits (year) of epoch
                    L2 = sscanf(L2c,'%d%6d%f%f%f%f%f%f%f',[1,8]);
                    
                    % Epoch
                    epoch_jul = L1(1,4);   % Epoch Date and Julian Date Fraction
                    
                    % Apparently there is no direct way in matlab to convert tle epoch
                    % in date, therefore the apporach below is used (there is for sure a simpler way)
                    epoch_str =  num2str(epoch_jul);                            % convert tle to str
                    year = str2num(epoch_str(1:2))+2000;                        % extract number of year (first 2 digits)
                    epoch_dayfract = str2num(epoch_str(3:end));                % exctract the other digits (day fraction)
                    date_0 = append('1 Jan ',num2str(year),' 00:00:00.000');    % combine it and convert to date
                    epoch = char(datetime(date_0,'Format','d MMM uuuu HH:mm:ss.SSS') + days(epoch_dayfract-1)); % actual date of TLE
            
                    
                    epoch_dayfract = epoch_dayfract + 3;
                    if epoch_dayfract > 365
                        epoch_dayfract = epoch_dayfract - 365;
                        year = year + 1;
                    end
            
                    date_0 = append('1 Jan ',num2str(year),' 00:00:00.000');    % combine it and convert to date
                    End_Time = char(datetime(date_0,'Format','d MMM uuuu HH:mm:ss.SSS') + days(epoch_dayfract-1)); 
                    
            
                    % Orbital elements
                    Db = L1(1,5);          % Ballistic Coefficient
                    inc = L2(1,3);         % Inclination [deg]
                    RAAN = L2(1,4);        % Right Ascension of the Ascending Node [deg]
                    e = L2(1,5)/1e7;       % Eccentricity
                    w = L2(1,6);           % Argument of periapsis [deg]
                    M = L2(1,7);           % Mean anomaly [deg]
                    n = L2(1,8);           % Mean motion [Revs per day]
            
                    a = (mu/(n*2*pi/(24*3600))^2)^(1/3);  % Semi-major axis [km]
                    ra = a*(e+1)-6378;      % Apogee
                    rp = a*(1-e)-6378;      % Perigee
                
                    % Calculate the eccentric anomaly using Mean anomaly
                    err = 1e-10;  % Calculation Error
                    M_rad = M*pi/180;
                    E0 = M;
                    t = 1;
                    itt = 0;
                    while (t)
                        E = M + e*sind(E0);     % Eccentrcic anomaly
                        if (abs(E-E0) < err)
                            t = 0;
                        end
                        E0 = E;
                        itt = itt + 1;
                    end
                    
                    % get true anoamly
                    theta = acosd((cosd(E)-e)/(1-e*cosd(E)));   % Not 100% sure this is correct
            
                    % Six orbital elements
                    OE = [a e inc ra rp RAAN w E n Db M];
                    OE_array = [OE_array; OE];                  % Append OE to the array
                    Epoch_vect = [Epoch_vect; epoch];
                   
                end
                
                % Close the file
                fclose(fid);
            end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TLEInfoRequestButton
        function TLEInfoRequestButtonPushed(app, event)
            TLE_file = 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\TLE_PEGASUS_07082023.txt';  % TLE file path\name
            [OE_array,Epoch_vect,End_Time,epoch_jul,epoch] = TLE_processing_fcn(app,TLE_file); % function that extracts orbital elements from TLEs
            
            Name={'a','e','inc','ra','rp','RAAN','w','E','n','Db','M'};
            app.UITable4.Data=table(["epoch",Name]',[{epoch},num2cell(OE_array)]');
        end

        % Value changed function: ScenarioStartTimeEditField
        function ScenarioStartTimeEditFieldValueChanged(app, event)
            %start_time_value_read = app.ScenarioStartTimeEditField.Value;
            %start_time_value=datetime(start_time_value_read, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            %app.UITable4.Data=start_time_value;

            % 6 Aug 2023 20:15:04.320


        end

        % Value changed function: ScenarioStopTimeEditField
        function ScenarioStopTimeEditFieldValueChanged(app, event)
            % stop_time_value_read = app.ScenarioStopTimeEditField.Value;
            % stop_time_value=datetime(stop_time_value_read, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            %app.UITable4_2.Data=stop_time_value;
        end

        % Button pushed function: PropagateButton
        function PropagateButtonPushed(app, event)
                         clc 
            % Description
            % Takes TLE, Time and Attitude from latest status from the satellite
            % Take user Input in the form of [Time, Maneuvre] as well as Start time
            % Runs STK scenario on these Inputs
            % Outputs TLE and Attitude as well as Power generated, Angular
            % acceleration, deltaV all over time for the next 72 hours%% Get Inputs
            %% Get Inputs from TLE
            disp("Getting Input files")
            app.ConsoleLog("Getting Input files");
            
            % TLE
            L1 = "";
            TLE_file = 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\TLE_PEGASUS_07082023.txt';  % TLE file path\name
            [Orbit_data_array,epoch_tle,app.End_Time] = TLE_processing_fcn(app,TLE_file); % function that extracts orbital elements from TLEs
            
            %Time----------------
            % Scenario time input
            start_time_value_read = app.ScenarioStartTimeEditField.Value;
            start_time_value=datetime(start_time_value_read, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            start_time_value_cell=cellstr(start_time_value);

            stop_time_value_read = app.ScenarioStopTimeEditField.Value;
            stop_time_value=datetime(stop_time_value_read, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            stop_time_value_cell=cellstr(stop_time_value);

            scenario_period = table(start_time_value_cell, stop_time_value_cell);


            app.Start_Time=epoch_tle;
            
            % % For display and attitude input only
            % Start_Time_period=datetime(start_time_value, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % Start_Time_period=cellstr(Start_Time_period);
            % Stop_Time_period=datetime(app.End_Time, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % Stop_Time_period=cellstr(Stop_Time_period);
            % 
            % scenario_period = table(Start_Time_period, Stop_Time_period);
            % app.UITable3.Data=scenario_period;
            
            %% Connect to STK
            if isempty(app.STKApp) || ~iscom(app.STKApp)
                    app.STKApp = actxserver('STK12.Application');
                    %app.STKApp.visible = false;
            end
              
                app.root = app.STKApp.Personality2;
                
            %% Open Scenario
            disp('Opening STK Scenario')
            app.ConsoleLog("Opening STK Scenario");
            
            scenario_file = 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\CLIMB Operation\CLIMB_scenario_v2\CLIMB_scenario1.sc';
            
            app.root.LoadScenario(scenario_file)
            scenario = app.root.CurrentScenario;
            scenario.SetTimePeriod(start_time_value_read,stop_time_value_read);
            scenario.StartTime = start_time_value_read;%app.Start_Time;
            scenario.StopTime = stop_time_value_read;%app.End_Time;
            app.root.ExecuteCommand('Animate * Reset');
            
            %% Open Objects
            disp('Opening Objects')
            app.ConsoleLog("Opening Objects");
            
            app.CLIMB = scenario.Children.Item('CLIMB');       % Open Satellite
            FHWN = scenario.Children.Item('FHWN');       % Open Satellite
            Sensor=FHWN.Children.Item('Sensor');
            
            %% Set initial state

            % IAgSatellite satellite: Satellite object
            app.CLIMB.SetPropagatorType('ePropagatorHPOP');
            set(app.CLIMB.Propagator,'Step',60);
            
            propagator = app.CLIMB.Propagator.InitialState.Representation.ConvertTo('eOrbitStateClassical');
            propagator.AssignCartesian('eCoordinateSystemTrueOfDate',6678.14,0,0,0,6.78953,3.68641);
            propagator.SizeShapeType = 'eSizeShapeAltitude';
            propagator.LocationType = 'eLocationMeanAnomaly';
            propagator.Orientation.AscNodeType = 'eAscNodeRAAN';
            
            % forceModel = app.CLIMB.Propagator.ForceModel;
            % forceModel.CentralBodyGravity.File = 'C:\Program Files\AGI\STK 12\STKData\CentralBodies\Earth\WGS84_EGM96.grv';
            % forceModel.CentralBodyGravity.MaxDegree = 21;
            % forceModel.CentralBodyGravity.MaxOrder = 21;
            % forceModel.Drag.Use=1;
            % forceModel.Drag.DragModel.Cd=0.01;
            % forceModel.Drag.DragModel.AreaMassRatio=0.01;
            % forceModel.SolarRadiationPressure.Use=0;
            
            % integrator = app.CLIMB.Propagator.Integrator;
            % integrator.DoNotPropagateBelowAlt=-1e6;
            % integrator.IntegrationModel=3;
            % integrator.StepSizeControl.Method=1;
            % integrator.StepSizeControl.ErrorTolerance=1e-13;
            % integrator.StepSizeControl.MinStepSize=0.1;
            % integrator.StepSizeControl.MaxStepSize=30;
            % integrator.Interpolation.Method=1;
            % integrator.Interpolation.Order=7;
            
            % Initial State
            ra = Orbit_data_array(:,4);
            rp = Orbit_data_array(:,5);
            ecc = Orbit_data_array(:,2);
            Incl = Orbit_data_array(:,3);
            RAAN = Orbit_data_array(:,6);
            ArgPeri = Orbit_data_array(:,7);
            MeanAno = Orbit_data_array(:,11);
            
            propagator.SizeShape.ApogeeAltitude = ra;      % Apogee
            propagator.SizeShape.PerigeeAltitude = rp;     % Perigee 
            propagator.Orientation.Inclination = Incl;     % Inclination
            propagator.Orientation.AscNode.Value = RAAN;   % RAAN
            propagator.Orientation.ArgOfPerigee = ArgPeri; % Arg. Perigee
            propagator.Location.Value = MeanAno;           % True Anomaly
            
            % propagate
            app.CLIMB.Propagator.InitialState.Representation.Assign(propagator);
            app.CLIMB.Propagator.Propagate;


            % disp('Setting Initial State')
            % app.ConsoleLog("Setting Initial State");
            % 
            % % Initial State parameters
            % ra = Orbit_data_array(:,4);
            % rp = Orbit_data_array(:,5);
            % ecc = Orbit_data_array(:,2);
            % Incl = Orbit_data_array(:,3);
            % RAAN = Orbit_data_array(:,6);
            % ArgPeri = Orbit_data_array(:,7);
            % MeanAno = Orbit_data_array(:,11);
            % 
            % % Initial State definition
            % driver = app.CLIMB.Propagator;
            % initState = driver.MainSequence.GetItemByName('Initial State');
            % initState.OrbitEpoch = app.Start_Time;
            % 
            % % Elements:
            % initState.SetElementType('eVAElementTypeKeplerian');
            % kep = initState.Element;
            % kep.ApoapsisAltitudeSize = ra;
            % kep.Eccentricity = ecc;
            % kep.Inclination = Incl;
            % kep.RAAN = RAAN;
            % kep.ArgOfPeriapsis = ArgPeri;
            % kep.MeanAnomaly = MeanAno;
            % 
            % % Run the Astrogator
            % driver.RunMCS;


            %% Access report
            disp('Getting access windows')
            app.ConsoleLog("Getting access windows");

            access = app.CLIMB.GetAccessToObject(Sensor);
            access.ComputeAccess;
            accessDP = access.DataProviders.Item('Access Data').Exec(scenario.StartTime,scenario.StopTime);
            app.accessStartTimes_UTC=datetime(accessDP.DataSets.GetDataSetByName('Start Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.accessStartTimes = datestr(app.accessStartTimes_UTC, 'dd mmm yyyy HH:MM:SS');
            app.accessStartTimes=cellstr(app.accessStartTimes);
            app.accessStopTimes_UTC=datetime(accessDP.DataSets.GetDataSetByName('Stop Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            app.accessStopTimes= datestr(app.accessStopTimes_UTC, 'dd mmm yyyy HH:MM:SS');
            app.accessStopTimes=cellstr(app.accessStopTimes);
            app.root.UnitPreferences.Item('Time').SetCurrentUnit('Min');
            accessDuration = accessDP.DataSets.GetDataSetByName('Duration').GetValues;
            linktable=[app.accessStartTimes app.accessStopTimes accessDuration]; 
            linktable=cell2table(linktable);
            checkboxColumn1 = false(height(linktable), 1); % Add this new column to the existing table
            linktable= [linktable, array2table(checkboxColumn1)];
            app.UITable.Data = linktable;% Update the UITable with the new data
            
            %% Perigee passes report
            disp('Getting pergiee passes')
            app.ConsoleLog("Getting perigee passes");

            passes_res = app.CLIMB.DataProviders.Item('Passes').Exec(scenario.StartTime,scenario.StopTime);
            app.per_pass_time=datetime(passes_res.DataSets.GetDataSetByName('Time of Perigee').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            per_pass_cell = cellstr(app.per_pass_time);
            per_pass_table = table(per_pass_cell);
            checkboxColumn2=true(height(per_pass_table), 1);
            per_pass_table=[per_pass_table,array2table(checkboxColumn2)];
            per_pass_table = [{'Remove all burns (Override)', false};per_pass_table];
            per_pass_table(height(per_pass_table),:) = [];
            app.UITable2.Data=per_pass_table;
            
            %% Maneuver data
            
            % Code Snipptes = app.CLIMB.DataProviders.Item('Passes').Exec(app.Start_Time,app.End_Time);
            % app.per_pass_time=datetime(passes_res.DataSets.GetDataSetByName('Time of Perigee').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
            % 
            % satPosDP = satellite.DataProviders.Item('Cartesian Position').Group.Item('ICRF').Exec(scenario.StartTime,scenario.StopTime,60);
            % satx = cell2mat(satPosDP.DataSets.GetDataSetByName('x').GetValues);


            %% TLE Function
            % TLE processing function (https://de.mathworks.com/matlabcentral/fileexchange/39364-two-line-element)
            
            % function [OE_array,Epoch_vect,End_Time,epoch_jul] = TLE_processing_fcn(fname)
            % 
            %     fid = fopen(fname, 'r');        % Open the TLE file for reading
            %     OE_array = [];                  % Initialize an empty array to store orbital elements
            %     Epoch_vect = [];
            %     mu = 398600; % Standard gravitational parameter for the earth
            % 
            %     % Read and process TLE sets from the file
            %     while ~feof(fid)
            %     % while fopen(fid)
            % 
            %         % Read two lines from the file
            %         L1c = fgets(fid);
            %         L2c = fgets(fid);
            % 
            %         % Display the two lines
            %         %fprintf(L1c);
            %         %fprintf(L2c);
            % 
            %         % Extract relevant data from the lines
            %         %L1 = sscanf(L1c,'%d%6d%*c%9d%*c%*2f%f%f%5d%*c%*d%5d%*c%*d%d%5d',[1,9])  % deletes first two digits (year) of epoch
            % 
            %         L1 = sscanf(L1c,'%d%6d%*c%9d%*c%f%f%5d%*c%*d%5d%*c%*d%d%5d%*2f',[1,9]);   % keeps first two digits (year) of epoch
            %         L2 = sscanf(L2c,'%d%6d%f%f%f%f%f%f%f',[1,8]);
            % 
            %         % Epoch
            %         epoch_jul = L1(1,4);   % Epoch Date and Julian Date Fraction
            % 
            %         % Apparently there is no direct way in matlab to convert tle epoch
            %         % in date, therefore the apporach below is used (there is for sure a simpler way)
            %         epoch_str =  num2str(epoch_jul);                            % convert tle to str
            %         year = str2num(epoch_str(1:2))+2000;                        % extract number of year (first 2 digits)
            %         epoch_dayfract = str2num(epoch_str(3:end));                % exctract the other digits (day fraction)
            %         date_0 = append('1 Jan ',num2str(year),' 00:00:00.000');    % combine it and convert to date
            %         epoch = char(datetime(date_0,'Format','d MMM uuuu HH:mm:ss.SSS') + days(epoch_dayfract-1)); % actual date of TLE
            % 
            % 
            %         epoch_dayfract = epoch_dayfract + 3;
            %         if epoch_dayfract > 365
            %             epoch_dayfract = epoch_dayfract - 365;
            %             year = year + 1;
            %         end
            % 
            %         date_0 = append('1 Jan ',num2str(year),' 00:00:00.000');    % combine it and convert to date
            %         End_Time = char(datetime(date_0,'Format','d MMM uuuu HH:mm:ss.SSS') + days(epoch_dayfract-1)); 
            % 
            % 
            %         % Orbital elements
            %         Db = L1(1,5);          % Ballistic Coefficient
            %         inc = L2(1,3);         % Inclination [deg]
            %         RAAN = L2(1,4);        % Right Ascension of the Ascending Node [deg]
            %         e = L2(1,5)/1e7;       % Eccentricity
            %         w = L2(1,6);           % Argument of periapsis [deg]
            %         M = L2(1,7);           % Mean anomaly [deg]
            %         n = L2(1,8);           % Mean motion [Revs per day]
            % 
            %         a = (mu/(n*2*pi/(24*3600))^2)^(1/3);  % Semi-major axis [km]
            %         ra = a*(e+1)-6378;      % Apogee
            %         rp = a*(1-e)-6378;      % Perigee
            % 
            %         % Calculate the eccentric anomaly using Mean anomaly
            %         err = 1e-10;  % Calculation Error
            %         M_rad = M*pi/180;
            %         E0 = M;
            %         t = 1;
            %         itt = 0;
            %         while (t)
            %             E = M + e*sind(E0);     % Eccentrcic anomaly
            %             if (abs(E-E0) < err)
            %                 t = 0;
            %             end
            %             E0 = E;
            %             itt = itt + 1;
            %         end
            % 
            %         % get true anoamly
            %         theta = acosd((cosd(E)-e)/(1-e*cosd(E)));   % Not 100% sure this is correct
            % 
            %         % Six orbital elements
            %         OE = [a e inc ra rp RAAN w E n Db M];
            %         OE_array = [OE_array; OE];                  % Append OE to the array
            %         Epoch_vect = [Epoch_vect; epoch];
            % 
            %     end
            % 
            %     % Close the file
            %     fclose(fid);
            % end


            %%
            %-----------------------------------------------------------------
            %------------------- Plot-----------------------------------------
            %-----------------------------------------------------------------

            activity_names = {'Contact Windows', 'Perigee Passes'};
            
            access_start = app.accessStartTimes_UTC;
            access_stop = app.accessStopTimes_UTC;

            hold on
            ax=app.SchedulePlot;
            %plot(app.SchedulePlot,x,y)
            plot(ax,app.per_pass_time,2,'+','linewidth',0.5,'color','blue')
            plot(ax,[access_start access_stop],[1 1], 'linewidth',5,'color','green')

            
            %ax.YLim([0,3])
            ylim(ax, [0,3])
            % --Plot properties--
            %ylim([0 4])
            %ax.xlim([start_time_value stop_time_value])
            grid minor

            % Set y-axis labels
            yticks(ax,[1 2]);  % Position the ticks at the same locations as before
            yticklabels(ax,activity_names);            % Use activity names as labels

            %%
            disp('App is running') % For progress track after button push
            app.ConsoleLog("App is running");


        end

        % Button pushed function: PropagateManeuversButton
        function PropagateManeuversButtonPushed(app, event)


            %% Set initial state

            % IAgSatellite satellite: Satellite object
            app.CLIMB.SetPropagatorType('ePropagatorAstrogator');
            driver = app.CLIMB.Propagator;
            driver.MainSequence.RemoveAll();    %Clear all segments from the MCS

            %Get the Engine Models Folder
            scenario = app.root.CurrentScenario;
            engine = scenario.ComponentDirectory.GetComponents('eComponentAstrogator').GetFolder('Engine Models');
            engine_model = engine.Item('Constant Thrust and Isp');
            engine_model_clone = engine_model.CloneObject;

            %Grab a handle of the new Engine Model and edit properties
            Engine = engine.Item('Constant Thrust and Isp1');
            %Engine.Name = 'Enpulsion';
            Engine.Thrust = 0.00035;                         % Thrust - [N]
            Engine.Isp = 1900;       

            %% Create Sequence

            %-------------------------Insert an initial state--------------------------
            initState1 = driver.MainSequence.Insert('eVASegmentTypeInitialState','Initail State','-');
            initState1.OrbitEpoch = app.Start_Time;
            

            TLE_file = 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\TLE_PEGASUS_07082023.txt';  % TLE file path\name
            [Orbit_data_array,epoch_tle,app.End_Time] = TLE_processing_fcn(app,TLE_file); % function that extracts orbital elements from TLEs
            
            
            % Initial State
            ra = Orbit_data_array(:,4);
            rp = Orbit_data_array(:,5);
            ecc = Orbit_data_array(:,2);
            Incl = Orbit_data_array(:,3);
            RAAN = Orbit_data_array(:,6);
            ArgPeri = Orbit_data_array(:,7);
            MeanAno = Orbit_data_array(:,11);

            % Elements:
            initState1.SetElementType('eVAElementTypeKeplerian');
            kep = initState1.Element;
            kep.ApoapsisAltitudeSize = ra;
            kep.Eccentricity = ecc;
            kep.Inclination = Incl;
            kep.RAAN = RAAN;
            kep.ArgOfPeriapsis = ArgPeri;


            %input table
            %-------------------------------------------------------------------------------------------
            
            thrust_duration = 600/60; % in min, dont know why


            % Manuever Selection
            % x = x(x{:, 2} ~= 0, :); delete rows where x(:,2)==0 ("~" not % operator)
            maneuver_table_input = app.UITable2.Data; % table with manuever data imput
            maneuver_locig = maneuver_table_input(:,2); % maneuver selection (1 or 0)
            maneuver_table_input = maneuver_table_input(maneuver_table_input{:,2} ~=0,:); % delete unselected maneuvers

            maneuver_date = maneuver_table_input{:,1};  % selected maneuver dates
            maneuver_start_date = datetime(maneuver_date)-minutes(thrust_duration/2); % maneuver start date (perigee pass date - maneuver duration/2)
            maneuver_start_date = char(maneuver_start_date);    % Convert to char for STK input -> te get single elements maneuver_start_date(i,:)

            number_maneuvers = length(maneuver_date(:,1));
            maneuver_id = 1:number_maneuvers;
            disp(maneuver_id)
            % for i = 1:number_maneuvers
            %     disp(maneuver_start_date(i,:))
            % end
            
            %maneuver_duration = table2array(maneuver_table_input(:,3));  % selected maneuver dates

            %--------------------------------------------------------------------------------------------


            % Loop which creates a propagaor segment and afterwards manuever segment. The propagates until
            % perigee_date-man_duration/2 and then a maneuver segment
            % 
            for i=1:number_maneuvers
                %disp(i)

                %%----------------------------Insert Propagator-----------------------------
                propagator1 = driver.MainSequence.Insert('eVASegmentTypePropagate','Propage_1','-');
                propagator1.PropagatorName = 'Earth HPOP Default v10';
                propagator1.Properties.Color = 65280;     % green
                propagator1_stopcond = propagator1.StoppingConditions.Add('Epoch');
                propagator1_stopcond.Properties.Trip = maneuver_start_date(i,:);
                propagator1.StoppingConditions.Remove('Duration');
            

                %%----------------------------Insert Maneuver -----------------------------
                % %Get the Engine Models Folder
                % engine = scenario.ComponentDirectory.GetComponents('eComponentAstrogator').GetFolder('Engine Models');
                % engine_model = engine.Item('Constant Thrust and Isp');
                % engine_model_clone = engine_model.CloneObject;
                % Engine = engine.Item(item(i));
                % Engine.Name = name(i);
                % Engine.Thrust = thrust_engine(i);                         % Thrust - [N]
                % Engine.Isp = Isp;                               % Specific Impulse - [s]
                % %Engine.Thrust = thrust_engine(i);
            
                in_manu = driver.MainSequence.Insert('eVASegmentTypeManeuver','Maneuver','-');
                in_manu.SetManeuverType('eVAManeuverTypeFinite');
                in_manu.Properties.Color = 16777215;    % color magenta
                maneuver1=in_manu.Maneuver;
                
                % Attitude:
                maneuver1.SetAttitudeControlType('eVAAttitudeControlVelocityVector');
                alt_manu = maneuver1.AttitudeControl;
                
                % Engine:
                maneuver1.ThrustEfficiency = 1;
                maneuver1.SetPropulsionMethod('eVAPropulsionMethodEngineModel', 'Enpulsion'); % select engine fromm browser
                
                % Maneuver Propagator:
                prop_manu = maneuver1.Propagator;
                %maneuver1.CenterBurn = 1;
                %prop_manu.CenterBurn = 1;
                
                trip = prop_manu.StoppingConditions.Add('Duration');         % in min, dont know why
                trip.Properties.Trip = thrust_duration;                 % in min, dont know why
                prop_manu.StoppingConditions.Remove('Duration');

            end

        driver.RunMCS;
        
            %app.ConsoleLog(table2cell(maneuver_locig));
            %app.UITable6_2.Data(maneuver_duration)
            %app.ConsoleLog(table2cell(maneuver_date));

            % app.ConsoleLog(table2cell(maneuver_table_input(:,1)));
            % app.ConsoleLog("----- now manuever -----");
            % app.ConsoleLog(table2cell(maneuver_date));
            

            % TEsting of maneuver date
            % thrust_date = ["6 Aug 2023 22:00:00.000" "6 Aug 2023 23:00:00.000" "6 Aug 2023 23:30:00.000"];
            % nr_man = length(thrust_date);
            % color = [65280 16777215 65280];
            % thrust_duration = [600 600 600]/60; % in min, dont know why
            % thrust_engine = [0.003 1 0.2];
            % item = ["Constant Thrust and Isp2" "Constant Thrust and Isp3" "Constant Thrust and Isp4"];
            % name = ["Enpulsion2" "Enpulsion3" "Enpulsion4"];
            % Engine.Isp = Isp;


        end

        % Close request function: CLIMBPredictorUIFigure
        function CLIMBPredictorUIFigureCloseRequest(app, event)
            
               app.delete();
            
        end

        % Button pushed function: CreateSTKButton
        function CreateSTKButtonPushed(app, event)
        
            disp('Creating STK')
            app.ConsoleLog("Creating STK");

         %app.STKApp.visible = true;

%% Updating burns
         Burn=app.UITable2.Data(:,2);
         Burn=table2array(Burn);
         driver = app.CLIMB.Propagator;
         lastburn = 0;
          for i = 2:(height(app.UITable2.Data))
            if Burn(i) == 0 | Burn(1) == 1     % Zeros are deleted Ones are burned
            burnname = append('Burn' ,num2str(i-1));
                try
                    driver.MainSequence.Remove(burnname);
                catch
                 %   break;
                end
            end
            lastburn = i+1;
          end
          burnname = append('Burn' ,num2str(lastburn-1));
                try
                    driver.MainSequence.Remove(burnname);
                catch
                 %   break;
                end
        driver.RunMCS;


%% Inputs for attitude
        disp ('Reading Schedule')
        app.ConsoleLog("Reading Schedule");
        
        Torque_tool_data  = readtable('C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Torque_tool_data.xlsx');

        slew_length= minutes(table2array(Torque_tool_data(19,2)));% kg*m^2 (Roll)); % Slew length for fixed time
        thrust_length_half=minutes(table2array(Torque_tool_data(20,2))); % half before and or after perigee
        thrust_lead_length=minutes(table2array(Torque_tool_data(21,2))); % additional time in ready to thrust attitude before firing (only before, there is also same time after)
        
        startTimes_slew_c0=transpose(cellstr(app.accessStartTimes_UTC-slew_length));
        startTimes_com=transpose(app.accessStartTimes);
        startTimes_slew_c=transpose(app.accessStopTimes);
        startTimes_sun_c=transpose(cellstr(app.accessStopTimes_UTC+slew_length));
        startTimes_slew_t0=transpose(cellstr(app.per_pass_time-thrust_length_half-thrust_lead_length-slew_length));
        startTimes_thrust=transpose(cellstr(app.per_pass_time-thrust_length_half-thrust_lead_length));
        startTimes_slew_t=transpose(cellstr(app.per_pass_time+thrust_length_half+thrust_lead_length));
        startTimes_sun_t=transpose(cellstr(app.per_pass_time+thrust_length_half+thrust_lead_length+slew_length));
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        dateUnit = 'UTCG';% 'UTCG' or 'EpSec'
        command = strcat(['Units_Set * Connect Date "',dateUnit,'"']);
        app.root.ExecuteCommand(command); % Change connect command date units to desired units:
        startTimes = {};
        Access = app.UITable.Data(:,4);
        Access = table2array(Access);
        StartData= app.UITable.Data(:,1);
        StopData = app.UITable.Data(:,2);
        StartData = table2array(StartData);
        StopData = table2array(StopData);
        
        for i = 1:(height(app.UITable.Data))
            if Access(i) == 1
                startTimes{end+1} = StartData(i);
                startTimes{end+1} = StopData(i);
            end
        end

%% Define Attitude Profiles

        profileNames = {'Sun_c','Communication','Slew_c','Thrust','Slew_t','Sun_t','Slew_t0','Slew_c0'};
        profileTypes = {'AlignConstrain','AlignConstrain','FixedTimeSlew','AlignConstrain','FixedTimeSlew','AlignConstrain','FixedTimeSlew','FixedTimeSlew'};
        
        % for INSTALLED "Aligned and Constrained" attitude profiles:
        offsetAngles = {'0'};
        % for CUSTOM "Aligned and Constrained" attitude profiles:
        alignVectors = {'Sun','Nadir(Centric)','Velocity','Sun'};
        alignRefTypes = {'Axis', 'Axis', 'Axis','Axis' }; % Axis  / <x> <y> <z> Euler / <angle 1> <angle 2> <sequence> (12, 21, 31, 32) , ,
        alignRefValues = {'1 1 0', '0 1 0', '0 0 -1','1 1 0'};
        
        % Constrained vector data:
        constrainVectors = {'Velocity','Velocity','Sun','Velocity'};
        constrainRefTypes = {'Axis','Axis','Axis','Axis'};% PR    / <pitch> <roll>
        constrainRefValues = {'0 0 -1','0 0 -1','1 1 0','0 0 -1'}; %    RaDec / <raan> <dec>
        
        %   SLEW
        % slewtype={'Smooth On'};
        
        alignConstrainIndex = 1;
        installedIndex = 1;
        slewindex=1;


%% Defining time and length for each attitude profile

        Access=app.UITable.Data(:,4);
        Access = table2array(Access);
        Burn=app.UITable2.Data(:,2);
        Burn=table2array(Burn);
        
        %{Starttime, Profile Name}
        % TimeTable = {Start_Time_period};
        % NameTable = {'Sun_t'};
        TimeTable = {};
        NameTable = {};
        
        
        %j = 1;
        for i = 2:(height(app.UITable2.Data))
            if Burn(i) == 1 && Burn(1) == 0 
                TimeTable{end +1} = startTimes_thrust(i-1);
                NameTable{end +1} = profileNames(4);
                TimeTable{end +1} = startTimes_sun_t(i-1);
                NameTable{end +1} = profileNames(6);
                TimeTable{end +1} = startTimes_slew_t(i-1);
                NameTable{end +1} = profileNames(5);
                TimeTable{end +1} = startTimes_slew_t0(i-1);
                NameTable{end +1} = profileNames(7);
            end
        end
        for i = 1:(height(app.UITable.Data))
            if Access(i) == 1     
                TimeTable{end +1} = startTimes_com(i);
                NameTable{end +1} = profileNames(2);
                TimeTable{end +1} = startTimes_sun_c(i);
                NameTable{end +1} = profileNames(1);
                TimeTable{end +1} = startTimes_slew_c(i);
                NameTable{end +1} = profileNames(3);
                TimeTable{end +1} = startTimes_slew_c0(i);
                NameTable{end +1} = profileNames(8);
            end
        end
    
    
%% Add Attitude Profiles 
        disp('Setting attitude schedule')
        app.ConsoleLog("Setting attitude schedule");
        
        %nProfile = numel(profileNames);
        %nProfile = int32(floor(numel(startTimes)/2));
        for i = 1:numel(NameTable())
        
         profileName = string(NameTable{i});
         startTime = TimeTable{i};
        
         switch profileName
             case "Sun_c"
                profileType = profileTypes{1}; 
                index = 1;
             case "Communication"
                profileType = profileTypes{2}; 
                index = 2;
             case "Slew_c"
                profileType = profileTypes{3}; 
             case "Thrust"
                profileType = profileTypes{4}; 
                index = 3;
             case "Slew_t"
                profileType = profileTypes{5}; 
             case "Sun_t"
                profileType = profileTypes{6}; 
                index = 4;
             case "Slew_t0"
                profileType = profileTypes{7}; 
             case "Slew_c0"
                profileType = profileTypes{8}; 
         end
        
            % Start building command string:
            baseString = string(strjoin(['AddAttitude */Satellite/','CLIMB',' Profile ',profileName,' "',startTime,'" ',profileType],''));
        
            % Gather attitude data based on profile type:
            switch profileType
                
                % Custom "Aligned and Constrained" profile type:
                case 'AlignConstrain'
                    
                    % Set align/constrain index:
                    %index = alignConstrainIndex;
        
                    % Aligned vector data:
                      alignVector = alignVectors{index};
                     alignRefType = alignRefTypes{index};
                    alignRefValue = alignRefValues{index};
                      alignString = strcat([alignRefType,' ',alignRefValue,' "',alignVector,'"']);
                    
                    % Constrained vector data:
                      constrainVector = constrainVectors{index};
                     constrainRefType = constrainRefTypes{index};
                    constrainRefValue = constrainRefValues{index};
                      constrainString = strcat([constrainRefType,' ',constrainRefValue,' "',constrainVector,'"']);
                    
                    % Build profile string:
                    profileString = strcat([alignString,' ',constrainString]);
                    
                    % Increment align/constrain index by 1:
                    %alignConstrainIndex = alignConstrainIndex + 1;
                case 'FixedTimeSlew'
        
                    % Set slew index:
                    index = slewindex;
                    
                    % Slew data:
                    %slewtype=slewtype{index};
        %                 slewString=strcat([slewtype]);
                    
                    % Build profile string:
                    profileString = 'Smooth On'; %slewString;
                    
                    % Increment inertial index by 1:
                    slewindex=slewindex+1;
        
                % One of the 'installed' "Aligned and Constrained" profiles:
                otherwise
        %             
                    % Set installed index:
                    index = installedIndex;
                    
                    % Get offset angle:
                    offsetAngle = offsetAngles{index};
                    offsetString = strcat(['Offset ',offsetAngle]);
                    
                    % Build profile string:
                    profileString = offsetString;
                    
                    % Increment installed index by 1:
                    installedIndex = installedIndex + 1;
            end
        
            commandString = convertStringsToChars(strjoin([baseString,' ',profileString],''));
            
            app.root.ExecuteCommand(commandString);% Build command string and execute command:
            %disp(commandString)
        end    
    
disp('Running the Scenario')
app.ConsoleLog("Running Scenario");
%% Checking for Communication-Thrust-Overlaps
        disp('Checking for Communication-Thrust-Overlaps')
        app.ConsoleLog("Checking for Communication-Thrust overlaps");
        
        AttitudeSchedule = app.CLIMB.DataProviders.Item('Attitude Segment Schedule').Exec(app.Start_Time,app.End_Time);
        AttitudeNames = AttitudeSchedule.DataSets.GetDataSetByName('Name').GetValues;
        %disp(AttitudeNames)
        AttitudeNames = transpose(AttitudeNames);
        
        % standard schedule: {'Thrust'        }
        %                    {'QuatData'      }
        %                    {'Thrust'        }
        %                    {'Slew_t'        }
        %                    {'Sun_t'         }
        %                    {'Slew_c0'       }
        %                    {'Communication' }
        %                    {'Slew_c'        }
        %                    {'Sun_c'         }
        %                    {'Slew_t0'       }
        
        
            AttitudeNames = string(AttitudeNames);
        
        Error = false;
        for i = 1:(numel(AttitudeNames)-1)
                Name1 = AttitudeNames(i);
                Name2 = AttitudeNames(i+1);
        
            if Name1 == "Thrust" && Name2 ~= "QuatData" && Name2 ~= "Slew_t"
                Error = true;
                disp('Error: Attitude Profile overlap (1)')
                app.ConsoleLog("Error: Attitude Profile overlap (1)");
                break
            end
            if Name1 == "Slew_t" && Name2 ~= "Sun_t"
                Error = true;
                disp('Error: Attitude Profile overlap (2)')
                app.ConsoleLog("Error: Attitude Profile overlap (2)");
                break
            end
            if Name1 == "Communication" && Name2 ~= "Slew_c"
                Error = true;
                disp('Error: Attitude Profile overlap (3)')
                app.ConsoleLog("Error: Attitude Profile overlap (3)");
                break
            end
            if Name1 == "Slew_c" && Name2 ~= "Sun_c"
                Error = true;
                disp('Error: Attitude Profile overlap (4)')
                app.ConsoleLog("Error: Attitude Profile overlap (4)");
                break
            end
        end
        
        if Error == false
            disp('No overlaps detected. All profiles Nominal!')
            app.ConsoleLog("No overlaps detected. All profiles Nominal!");
        
        else 
            return
        end

%% Create schedule table output
        newHeaderNames = {'Access window start', 'Access window end', 'Access duration', 'Access Status'};
        app.UITable.Data.Properties.VariableNames = newHeaderNames;
        writetable(app.UITable.Data, 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Access_schedule.csv');
        newHeaderNames2 = {'Perigee pass time', 'Propulsion On/ Off'};
        app.UITable2.Data.Properties.VariableNames = newHeaderNames2;
        writetable(app.UITable2.Data, 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Fire_schedule.csv');
        newHeaderNames3= {'Scenario start time','Scenario stop time'};
        app.UITable3.Data.Properties.VariableNames = newHeaderNames3;
        writetable(app.UITable3.Data, 'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Scenario_time.csv');
  
%% Creating Solar Power Report
        disp('Creating Solar Power report')
        app.ConsoleLog("Creating Solar Power report");
        
        %Command = string(strjoin(['VO  */Satellite/CLIMB SolarPanel Compute ','" ',app.Start_Time,' "',' " ',app.Start_Time,' " 60'],''));
        % 6 Aug 2023 20:15:04.320,9 Aug 2023 20:15:04.320
        %Command = 'VO */Satellite/CLIMB SolarPanel Compute "6 Aug 2023 20:15:04.320" "9 Aug 2023 20:15:04.320" 600';
        stepsize = 600;
        app.root.UnitPreferences.Item('Power').SetCurrentUnit('W');
        app.root.ExecuteCommand("VO */Satellite/CLIMB SolarPanel Visualization Radius On 1 AddGroup Solar_Panels");
        Command = convertCharsToStrings('VO */Satellite/CLIMB SolarPanel Compute "') + app.Start_Time +convertCharsToStrings('" "')+ app.End_Time + convertCharsToStrings('" ') + string(stepsize);
        app.root.ExecuteCommand(Command);
        Solar = app.CLIMB.DataProviders.Item('Solar Panel Power').Exec(app.Start_Time,app.End_Time,stepsize);
                
        SolarTime = Solar.DataSets.GetDataSetByName('Time').GetValues;
        SolarPower = Solar.DataSets.GetDataSetByName('Power').GetValues;
        SolarIntensity = Solar.DataSets.GetDataSetByName('Solar Intensity').GetValues;
        
        SolarMatrix = [SolarTime,SolarPower,SolarIntensity];
        SolarTable = array2table(SolarMatrix);
        SolarTable.Properties.VariableNames(1:3) = {'Time (UTCG)','Power (W)','Solar Intensity'};
        writetable(SolarTable,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\CLIMB_Solar_Panel_Power.csv');

%% Creating Torque Report
        disp('Creating Torque report')
        app.ConsoleLog("Creating Torque report");
        
        Torque=app.CLIMB.DataProviders.Item('Vectors(Body)').Group.Item('TotalTorque').Exec(app.Start_Time,app.End_Time,5);
        TorqueX=Torque.DataSets.GetDataSetByName('x').GetValues;
        TorqueY=Torque.DataSets.GetDataSetByName('y').GetValues;
        TorqueZ=Torque.DataSets.GetDataSetByName('z').GetValues;
        Torque_time=datetime(Torque.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
        Torque_time= datestr(Torque_time, 'dd mmm yyyy HH:MM:SS');
        Torque_time=cellstr(Torque_time);
        Torque_table = [Torque_time,TorqueX,TorqueY,TorqueZ];
        Torque_table= array2table(Torque_table);
        Torque_table.Properties.VariableNames(1:4) = {'Time (UTCG)','Torque in X (Nm)','Torque in Y (Nm)','Torque in Z (Nm)'};
        writetable(Torque_table,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Torque.csv');

%% Creating Angular Momentum Report
        disp('Creating Angular Momentum report')
        app.ConsoleLog("Creating Angular Momentum report");
        
        AngMomentum =app.CLIMB.DataProviders.Item('Vectors(Body)').Group.Item('AngMomentum').Exec(app.Start_Time,app.End_Time,5);
        MomentumX=AngMomentum.DataSets.GetDataSetByName('x').GetValues;
        MomentumY=AngMomentum.DataSets.GetDataSetByName('y').GetValues;
        MomentumZ=AngMomentum.DataSets.GetDataSetByName('z').GetValues;
        Momentum_time=datetime(AngMomentum.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
        Momentum_time= datestr(Momentum_time, 'dd mmm yyyy HH:MM:SS');
        Momentum_time=cellstr(Momentum_time);
        Momentum_table = [Momentum_time,MomentumX,MomentumY,MomentumZ];
        Momentum_table= array2table(Momentum_table);
        Momentum_table.Properties.VariableNames(1:4) = {'Time (UTCG)','AngMomentum in X (kg*m^2/sec)','AngMomentum in Y (kg*m^2/sec)', 'AngMomentum in Z (kg*m^2/sec)'};
        writetable(Momentum_table,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\AngularMomentum.csv');

%% Creating angle change and angular velocity report
        disp('Creating angle change and angular velocity report')
        app.ConsoleLog("Creating angle change and angular velocity report");
        
        YPR =app.CLIMB.DataProviders.Item('Attitude YPR').Group.Item('Seq RPY').Exec(app.Start_Time,app.End_Time,1);
        Yaw=YPR.DataSets.GetDataSetByName('Yaw').GetValues;
        Pitch=YPR.DataSets.GetDataSetByName('Pitch').GetValues;
        Roll=YPR.DataSets.GetDataSetByName('Roll').GetValues;
        Yaw_rate=YPR.DataSets.GetDataSetByName('Yaw Rate').GetValues;
        Pitch_rate=YPR.DataSets.GetDataSetByName('Pitch Rate').GetValues;
        Roll_rate=YPR.DataSets.GetDataSetByName('Roll Rate').GetValues;
        YPR_time=datetime(YPR.DataSets.GetDataSetByName('Time').GetValues, 'InputFormat', 'd MMM yyyy HH:mm:ss.SSSSSSSSS');
        YPR_time= datestr(YPR_time, 'dd mmm yyyy HH:MM:SS');
        YPR_time=cellstr(YPR_time);
        YPR_table = [YPR_time,Yaw,Pitch,Roll,Yaw_rate,Pitch_rate,Roll_rate];
        YPR_table= array2table(YPR_table);
        YPR_table.Properties.VariableNames(1:7) = {'Time (UTCG)','Yaw','Pitch','Roll','Yaw rate','Pitch rate,','Roll rate'};
        writetable(YPR_table,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\YPR.csv');
%% Runninng Torque tool
        disp('Running Power and torque tool')
        app.ConsoleLog("Running Power and torque tool");

        %% Constants

Torque_tool_data  = readtable('C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Torque_tool_data.xlsx');

% CubeSat CAD data
I_xx = table2array(Torque_tool_data(2,2));% kg*m^2 (Roll)
I_yy=table2array(Torque_tool_data(3,2)); % kg*m^2 (Pitch)
I_zz=table2array(Torque_tool_data(4,2)); % kg*m^2 (Yaw)

cm_x=table2array(Torque_tool_data(7,2));%m cm shifts
cm_y=table2array(Torque_tool_data(8,2));%m cm shifts

Height_z=table2array(Torque_tool_data(11,2)); % m

% ADCS data sheet
ADCS_max_L=table2array(Torque_tool_data(2,6)); % mNms
ADCS_max_T=table2array(Torque_tool_data(1,6)); % mNm
RPM_limit=table2array(Torque_tool_data(3,6));% 8000 for CW0017 and 10000 for CW0057for reaction 
I_fw=table2array(Torque_tool_data(5,6)); %kg*m^2 2.4E-05 for CW0017 and 7E-05 for CW0057 and CS has 0.1432 kg*m^2 (need 3.2 E-05)

% ADCS last status from downlink data
RPM_wheel_initial=table2array(Torque_tool_data(15,6));
L_x_cubesat_initial=table2array(Torque_tool_data(16,6));
L_y_cubesat_initial=table2array(Torque_tool_data(17,6));
L_z_cubesat_initial=table2array(Torque_tool_data(18,6));

% Contributors to torque due to CM shift
F=table2array(Torque_tool_data(15,2));% N
t_thrust=table2array(Torque_tool_data(16,2));% s


% Contributors to torque due to thrust vector misallignment 
Misalignment_angle=table2array(Torque_tool_data(17,2)); % deg


%
% T_cm_x=cm_x*F; % Torque, Nm
% T_cm_y=cm_y*F; % Torque, Nm
% L_cm_x=T_cm_x*t_thrust;
% L_cm_y=T_cm_x*t_thrust;

%% Input reading

% YPR 
    Angular_velo_data=readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\YPR.csv",'HeaderLines', 1);
    Velo_time=Angular_velo_data(:,1);
    Velo_time=table2cell(Velo_time);
    Velo_time=datetime(Velo_time, 'InputFormat', 'd MMM yyyy HH:mm:ss');
    Yaw=table2array(Angular_velo_data(:,2));
    Pitch=table2array(Angular_velo_data(:,3));
    Roll=table2array(Angular_velo_data(:,4));
    % Yaw_rate=table2array(Angular_velo_data(:,5));
    % Pitch_rate=table2array(Angular_velo_data(:,6));
    % Roll_rate=table2array(Angular_velo_data(:,7));

        % Correction due to -180 to +180 limit
        for i = 1:(length(Yaw)-1)
         
            while Yaw(i) < (Yaw(i+1)-90)
                Yaw(i+1) = Yaw(i+1)-360;
            end
            while Yaw(i) > (Yaw(i+1)+90)
                Yaw(i+1) = Yaw(i+1)+360;
            end
            while Pitch(i) < (Pitch(i+1)-90)
                Pitch(i+1) = Pitch(i+1)-360;
            end
            while Pitch(i) > (Pitch(i+1)+90)
                Pitch(i+1) = Pitch(i+1)+360;
            end
            while Roll(i) < (Roll(i+1)-90)
                Roll(i+1) = Roll(i+1)-360;
            end
            while Roll(i) > (Roll(i+1)+90)
                Roll(i+1) = Roll(i+1)+360;
            end
        
        end

% Fire schedule
    Fire_data=readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Fire_schedule.csv",'HeaderLines', 1);
    Fire_times = table2array(Fire_data(:,1));
    Fire_status = table2array(Fire_data(:,2));
        Burn_time_status = zeros((size(Velo_time,1))-1,1); %  delta_L_x=zeros(size(L_x, 1), 1);
        
        for i = 1:size(Fire_times)
            if Fire_status(1) == 1
                break;
            end
            for j = 1:size(Burn_time_status)
                if abs(seconds(Velo_time(j) - Fire_times(i))) < 300 && Fire_status(i) == 1
                    Burn_time_status(j) = 1;
                end
            
            end
        
        end

% Torque
    Torque_data= readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\Torque.csv", 'HeaderLines', 1);
    Torque_time=Torque_data(:,1);
    Torque_time = table2cell(Torque_time);
    Torque_time= datetime(Torque_time, 'InputFormat', 'd MMM yyyy HH:mm:ss');
    T_x=1000*table2array(Torque_data(:,2)); % mNm
    T_y=1000*table2array(Torque_data(:,3)); % mNm
    T_z=1000*table2array(Torque_data(:,4)); % mNm

    T=sqrt(T_x.^2+T_y.^2+T_z.^2); % Abs. Torque

    Max_Torque=max(T);% Max. Torque
    disp(['Maximum Instantenious Torque (mNm): ', num2str(Max_Torque)]);

    cum_T_x=sum(T_x);
    cum_T_y=sum(T_y);
    cum_T_z=sum(T_z);
    Total_torque=sqrt(cum_T_x^2+cum_T_y^2+cum_T_z^2);% Abs. Cum. Torque
    disp(['Total Torque (mNm): ', num2str(Max_Torque)]);

% Angular Momentum
    L_data= readtable("C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\AngularMomentum.csv", 'HeaderLines', 1);
    L_time=L_data(:,1);
    L_time = table2cell(L_time);
    L_time= datetime(L_time, 'InputFormat', 'd MMM yyyy HH:mm:ss');
    L_x=1000*table2array(L_data(:,2)); % 1000*kg*m^2/sec= mNms % + L_cm_x*      
    L_y=1000*table2array(L_data(:,3)); % 1000*kg*m^2/sec= mNms
    L_z=1000*table2array(L_data(:,4)); % 1000*kg*m^2/sec= mNms
    L=sqrt(L_x.^2+L_y.^2+L_z.^2);
    Max_L=max(L);
    disp(['Maximum instantaneous Angular Momentum (mNms) ', num2str(Max_L)]);
    
    delta_L_x=zeros(size(L_x, 1), 1);
    for i = 2:length(delta_L_x)
        delta_L_x_i = L_x(i);
        delta_L_x_iminus1 = L_x(i-1);
        delta_L_x(i) = L_x(i)-L_x(i-1);
    end
    L_x(1)=L_x_cubesat_initial+L_x(1);
    Cum_L_x=L_x(1)+cumsum(delta_L_x);


    delta_L_y=zeros(size(L_y, 1), 1);
    for i = 2:length(delta_L_y)
        delta_L_y_i = L_y(i);
        delta_L_y_iminus1 = L_y(i-1);
        delta_L_y(i) = L_y(i)-L_y(i-1);
    end
    L_y(1)=L_y_cubesat_initial+L_y(1);
    Cum_L_y=L(1)+cumsum(delta_L_y);

    delta_L_z=zeros(size(L_z, 1), 1);
    for i = 2:length(delta_L_z)
        delta_L_z_i = L_z(i);
        delta_L_z_iminus1 = L_z(i-1);
        delta_L_z(i) = L_z(i)-L_z(i-1);
    end
    L_z(1)=L_z_cubesat_initial+L_z(1);
    Cum_L_z=L(1)+cumsum(delta_L_z);

    L_limit_pos=ADCS_max_L*ones(size(L_time));
    L_limit_neg=-ADCS_max_L*ones(size(L_time));

%     time_L_limit_x =max( L_time(abs(Cum_L_x) <= L_limit_pos));
%     time_L_limit_y =max( L_time(abs(Cum_L_y) <= L_limit_pos));
%     time_L_limit_z =max( L_time(abs(Cum_L_z) <= L_limit_pos));

%%  Angular velcoity derivation
    time_step = seconds(Velo_time(2) - Velo_time(1)); % Calculate the time step in seconds
    
    Yaw_rate = diff(Yaw(:))./time_step; % deg/s
    Pitch_rate = diff(Pitch(:))./time_step;
    Roll_rate = diff(Roll(:))./time_step;
  
%% Angular momentum due to CM shift
    Angular_momentum_pitch_thrust_persecond = F*cm_x; % Nm
    Angular_momentum_roll_thrust_persecond = F*cm_y; % Nm
    Thrust_momentum_pitch = Angular_momentum_pitch_thrust_persecond*time_step*Burn_time_status; 
    Thrust_momentum_roll = Angular_momentum_roll_thrust_persecond*time_step*Burn_time_status;
    Thrust_momentum_pitch = cumtrapz(Thrust_momentum_pitch);
    Thrust_momentum_roll = cumtrapz(Thrust_momentum_roll);

 %% Angular mometum due to Thrust vector misallignment 
    Thrust_momentum_PR=F*Height_z*sind(5); % Either could be added to X or Y, roll or pitch, Nm
    Angular_momentum_misall_PR=cumtrapz(Thrust_momentum_PR*time_step*Burn_time_status);

 %% Total angular momentum   
    Angular_momentum_yaw_STK = (Yaw_rate*pi/180) * I_zz; % Nms
    Angular_momentum_pitch_STK = (Pitch_rate*pi/180) * I_yy;
    Angular_momentum_roll_STK = (Roll_rate*pi/180) * I_xx;

    Angular_momentum_yaw = Angular_momentum_yaw_STK ; % Nms
    Angular_momentum_pitch= Angular_momentum_pitch_STK + Thrust_momentum_pitch+Angular_momentum_misall_PR; 
    Angular_momentum_roll= Angular_momentum_roll_STK +Thrust_momentum_roll;
%     figure Name STK_L
%         hold on
%         plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_yaw_STK,  'Color', 'green')
%         plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_pitch_STK, 'Color', 'black')
%         plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_roll_STK,  'Color', 'red')
%         xlabel('Time') 
%         ylabel('Angular momentum (Nms)')
%         legend({'Angular momentum in yaw','Angular momentum in pitch','Angular momentum in roll', ...
%             },'Location','southeast')
%         hold off
% 
% 
%      figure Name VectorMis_L
%             hold on
%             plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_misall_PR, 'Color', 'black')
%             
%             xlabel('Time') 
%             ylabel('Angular momentum (Nms)')
%             legend({'Angular momentum due to thrust vector misalignment', ...
%                 },'Location','southeast')
%             hold off
% 
%       figure Name Total_L
%             hold on
%             plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_yaw,  'Color', 'green')
%             plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_pitch, 'Color', 'black')
%             plot(Velo_time(1:length(Yaw_rate)),Angular_momentum_roll,  'Color', 'red')
%             xlabel('Time') 
%             ylabel('Angular momentum (Nms)')
%             legend({'Angular momentum in yaw','Angular momentum in pitch','Angular momentum in roll', ...
%                 },'Location','southeast')
%             hold off

%% RPM of the wheel
    RPM_x = Angular_momentum_roll/I_fw*30/pi+RPM_wheel_initial; 
    RPM_y = Angular_momentum_pitch/I_fw*30/pi+RPM_wheel_initial;
    RPM_z = Angular_momentum_yaw/I_fw*30/pi+RPM_wheel_initial;
    
    figure Name RPM
        hold on
        plot(Velo_time(1:length(Yaw_rate)),RPM_x, 'Color', 'red','LineWidth',2)
        plot(Velo_time(1:length(Yaw_rate)),RPM_y, 'Color', 'black', 'LineWidth',2)
        plot(Velo_time(1:length(Yaw_rate)),RPM_z,'Color', 'green','LineWidth',2)
    
        yline(RPM_limit, '--', 'LineWidth',2, 'Color', 'red')
        yline(-RPM_limit, '--', 'LineWidth',2, 'Color', 'red')
        ylim([-8000 8000])
        xlabel('Time','FontSize',24,'FontWeight','bold') 
        ylabel('RPMs','FontSize',24,'FontWeight','bold') 
        legend({'RPM around x','RPM around y','RPM around z', ...
            'Upper Limit' ,'Lower Limit'},'Location','southeast','FontSize',24,'FontWeight','bold')
        set(gca,'FontSize', 24)
        hold off 
    
    Velo_time=cellstr(Velo_time(1:size(RPM_x)));
    RPM_over_time=[Velo_time,num2cell(RPM_x),num2cell(RPM_y),num2cell(RPM_z)];
    RPM_over_time=array2table(RPM_over_time);
    RPM_over_time.Properties.VariableNames(1:4) = {'Time (UTCG)','RPM in X','RPM in Y','RPM in Z'};
    writetable(RPM_over_time,'C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\RPM_overTime.csv');
    disp('RPM report is generated')
    app.ConsoleLog("RPM report is generated");

%% Runninng Power tool
pyrunfile('C:\Users\120960\FHWN\Master Thesis_cloud - Dokumente\10_MOS_Software\STP\CLIMB Operation\PowerTool.py')

disp('Power tool report is generated')
app.ConsoleLog("Power tool report is genrated");

disp('Completed')
app.ConsoleLog("Completed");

        end

        % Menu selected function: PowertoolMenu
        function PowertoolMenuSelected(app, event)


        end

        % Menu selected function: PowerconsumptiondataMenu
        function PowerconsumptiondataMenuSelected(app, event)
            winopen('CLIMB Power Budget V2.xlsx');
        end

        % Menu selected function: TorquetoolMenu
        function TorquetoolMenuSelected(app, event)
          app.accessStartTimes
        end

        % Menu selected function: TorquetooldataMenu
        function TorquetooldataMenuSelected(app, event)
             winopen('Torque_tool_data.xlsx');
        end

        % Menu selected function: BatterymodeldataMenu
        function BatterymodeldataMenuSelected(app, event)
            winopen('Battery_InitialStatus_Model_Parameters.xlsx');
        end

        % Menu selected function: PowermodesMenu
        function PowermodesMenuSelected(app, event)
            app.DialogApp=power_tool_modeSelection(app);
        end

        % Button pushed function: PlotScheduleButton
        function PlotScheduleButtonPushed2(app, event)
            
            activity_names = {'Contact Windows', 'Perigee Passes'};
            
            access_start = app.accessStartTimes_UTC;
            access_stop = app.accessStopTimes_UTC;
    
            hold on
            ax=app.SchedulePlot_2;
            %plot(app.SchedulePlot,x,y)
            plot(ax,app.per_pass_time,2,'+','linewidth',0.5,'color','blue')
            plot(ax,[access_start access_stop],[1 1], 'linewidth',5,'color','green')
    
            
            %ax.YLim([0,3])
            ylim(ax, [0,3])
            % --Plot properties--
            %ylim([0 4])
            %ax.xlim([start_time_value stop_time_value])
            grid minor
    
            % Set y-axis labels
            yticks(ax,[1 2]);  % Position the ticks at the same locations as before
            yticklabels(ax,activity_names);            % Use activity names as labels


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CLIMBPredictorUIFigure = uifigure('Visible', 'off');
            app.CLIMBPredictorUIFigure.Name = 'CLIMB Predictor';
            app.CLIMBPredictorUIFigure.CloseRequestFcn = createCallbackFcn(app, @CLIMBPredictorUIFigureCloseRequest, true);
            app.PowertoolMenu = uimenu(app.CLIMBPredictorUIFigure);
            app.PowertoolMenu.MenuSelectedFcn = createCallbackFcn(app, @PowertoolMenuSelected, true);
            app.PowertoolMenu.Text = 'Power tool';
            app.PowermodesMenu = uimenu(app.PowertoolMenu);
            app.PowermodesMenu.MenuSelectedFcn = createCallbackFcn(app, @PowermodesMenuSelected, true);
            app.PowermodesMenu.Text = 'Power modes';
            app.PowerconsumptiondataMenu = uimenu(app.PowertoolMenu);
            app.PowerconsumptiondataMenu.MenuSelectedFcn = createCallbackFcn(app, @PowerconsumptiondataMenuSelected, true);
            app.PowerconsumptiondataMenu.Text = 'Power consumption data';
            app.BatterymodeldataMenu = uimenu(app.PowertoolMenu);
            app.BatterymodeldataMenu.MenuSelectedFcn = createCallbackFcn(app, @BatterymodeldataMenuSelected, true);
            app.BatterymodeldataMenu.Text = 'Battery model data';
            app.TorquetoolMenu = uimenu(app.CLIMBPredictorUIFigure);
            app.TorquetoolMenu.MenuSelectedFcn = createCallbackFcn(app, @TorquetoolMenuSelected, true);
            app.TorquetoolMenu.Text = 'Torque tool';
            app.TorquetooldataMenu = uimenu(app.TorquetoolMenu);
            app.TorquetooldataMenu.MenuSelectedFcn = createCallbackFcn(app, @TorquetooldataMenuSelected, true);
            app.TorquetooldataMenu.Text = 'Torque tool data';
            app.TabGroup = uitabgroup(app.CLIMBPredictorUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.SchedulePlot = uiaxes(app.Tab);
            title(app.SchedulePlot, 'SchdedulePlot')
            xlabel(app.SchedulePlot, 'X')
            ylabel(app.SchedulePlot, 'Y')
            zlabel(app.SchedulePlot, 'Z')
            app.PropagateButton = uibutton(app.Tab, 'push');
            app.PropagateButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateButtonPushed, true);
            app.PropagateButton.Text = 'Propagate';
            app.UITable4 = uitable(app.Tab);
            app.UITable4.ColumnName = {''; ''};
            app.UITable4.RowName = {};
            app.ConsoleLogTextAreaLabel = uilabel(app.Tab);
            app.ConsoleLogTextAreaLabel.Text = 'Console Log:';
            app.ConsoleLogTextArea = uitextarea(app.Tab);
            app.ConsoleLogTextArea.Editable = 'off';
            app.ConsoleLogTextArea.Value = {'Logging'};
            app.ScenarioStartTimeEditFieldLabel = uilabel(app.Tab);
            app.ScenarioStartTimeEditFieldLabel.Text = 'Scenario Start  Time';
            app.ScenarioStartTimeEditField = uieditfield(app.Tab, 'text');
            app.ScenarioStartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ScenarioStartTimeEditFieldValueChanged, true);
            app.ScenarioStartTimeEditField.Value = '6 Aug 2023 20:15:04.320';
            app.ScenarioStopTimeEditFieldLabel = uilabel(app.Tab);
            app.ScenarioStopTimeEditFieldLabel.Text = 'Scenario Stop Time';
            app.ScenarioStopTimeEditField = uieditfield(app.Tab, 'text');
            app.ScenarioStopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ScenarioStopTimeEditFieldValueChanged, true);
            app.ScenarioStopTimeEditField.Value = '7 Aug 2023 20:15:04.320';
            app.TLEInfoRequestButton = uibutton(app.Tab, 'push');
            app.TLEInfoRequestButton.ButtonPushedFcn = createCallbackFcn(app, @TLEInfoRequestButtonPushed, true);
            app.TLEInfoRequestButton.Text = 'TLE Info Request';
            app.CreateSTKButton = uibutton(app.Tab, 'push');
            app.CreateSTKButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSTKButtonPushed, true);
            app.CreateSTKButton.Text = 'Create STK';
            app.Calculatefortheupcoming72hoursbasedonthecurrenttimetableLabel = uilabel(app.Tab);
            app.Calculatefortheupcoming72hoursbasedonthecurrenttimetableLabel.Text = 'Create a scenario for the upcoming 72 hours based on the current timetable.';
            app.UITable5 = uitable(app.Tab);
            app.UITable5.ColumnName = {'Access Window Start'; 'Access Window Start'; 'Accesss Duration'; 'Max Elevation'};
            app.UITable5.RowName = {};
            app.UITable5_2 = uitable(app.Tab);
            app.UITable5_2.ColumnName = {'Perigee Pass Date'};
            app.UITable5_2.RowName = {};
            app.ResultsDropDownLabel = uilabel(app.Tab);
            app.ResultsDropDownLabel.Text = 'Results';
            app.ResultsDropDown = uidropdown(app.Tab);
            app.ResultsDropDown.Items = {'SGP4', 'Astrogator'};
            app.ResultsDropDown.Value = 'SGP4';
            app.ScheduleTab = uitab(app.TabGroup);
            app.ScheduleTab.Title = 'Schedule';
            app.SchedulePlot_2 = uiaxes(app.ScheduleTab);
            title(app.SchedulePlot_2, 'SchdedulePlotDetail')
            xlabel(app.SchedulePlot_2, 'X')
            ylabel(app.SchedulePlot_2, 'Y')
            zlabel(app.SchedulePlot_2, 'Z')
            app.UITable = uitable(app.ScheduleTab);
            app.UITable.ColumnName = {'Access window start'; 'Access window end'; 'Access duration'; 'Access Status'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true];
            app.UITable2 = uitable(app.ScheduleTab);
            app.UITable2.ColumnName = {'Perigee Pass Time'; 'Maneuver Select'; 'Maneuver Duration (min)'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true true];
            app.ActivityNameEditFieldLabel = uilabel(app.ScheduleTab);
            app.ActivityNameEditFieldLabel.Text = 'Activity Name';
            app.ActivityNameEditField = uieditfield(app.ScheduleTab, 'text');
            app.ActivityStartTimeEditFieldLabel = uilabel(app.ScheduleTab);
            app.ActivityStartTimeEditFieldLabel.Text = 'Activity Start Time ';
            app.ActivityStartTimeEditField = uieditfield(app.ScheduleTab, 'text');
            app.ActivityStartTimeEditField.Value = '10 Aug 2023 20:15:04.320';
            app.ActivityStopTimeEditFieldLabel = uilabel(app.ScheduleTab);
            app.ActivityStopTimeEditFieldLabel.Text = 'Activity Stop Time ';
            app.ActivityStopTimeEditField = uieditfield(app.ScheduleTab, 'text');
            app.ActivityStopTimeEditField.Value = '10 Aug 2023 20:15:04.320';
            app.UITable6 = uitable(app.ScheduleTab);
            app.UITable6.ColumnName = {'Activity ID'; 'Activity Name'; 'Start Time'; 'Stop Time'; 'Duration'};
            app.UITable6.RowName = {};
            app.AddActivityButton = uibutton(app.ScheduleTab, 'push');
            app.AddActivityButton.Text = 'Add Activity';
            app.UITable6_2 = uitable(app.ScheduleTab);
            app.UITable6_2.ColumnName = {'Activity Name'; 'Start Time'; 'Stop Time'; 'Power'};
            app.UITable6_2.RowName = {'1,2,3,4,5'};
            app.UITable6_2.ColumnEditable = [true true true true];
            app.PowerEditFieldLabel = uilabel(app.ScheduleTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.ScheduleTab, 'numeric');
            app.PlotScheduleButton = uibutton(app.ScheduleTab, 'push');
            app.PlotScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @PlotScheduleButtonPushed2, true);
            app.PlotScheduleButton.Text = 'Plot Schedule';
            app.PropagateManeuversButton = uibutton(app.ScheduleTab, 'push');
            app.PropagateManeuversButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateManeuversButtonPushed, true);
            app.PropagateManeuversButton.Text = 'Propagate Maneuvers';
            app.ManeuverDurationEditFieldLabel = uilabel(app.ScheduleTab);
            app.ManeuverDurationEditFieldLabel.Text = 'Maneuver Duration';
            app.ManeuverDurationEditField = uieditfield(app.ScheduleTab, 'numeric');
            app.IspEditFieldLabel = uilabel(app.ScheduleTab);
            app.IspEditFieldLabel.Text = 'Isp';
            app.IspEditField = uieditfield(app.ScheduleTab, 'numeric');
            app.ThrustEditFieldLabel = uilabel(app.ScheduleTab);
            app.ThrustEditFieldLabel.Text = 'Thrust';
            app.ThrustEditField = uieditfield(app.ScheduleTab, 'numeric');
            app.UITable7 = uitable(app.ScheduleTab);
            app.UITable7.ColumnName = {'ID'; 'Delta-V'; 'Start Time'; 'Stop Time'; 'Duration'};
            app.UITable7.RowName = {};
            app.UITable8 = uitable(app.ScheduleTab);
            app.UITable8.ColumnName = {'Totoal Delta-V'; 'velocoty before/after'; 'total thrust time'};
            app.UITable8.RowName = {};
            app.PropulsionTab = uitab(app.TabGroup);
            app.PropulsionTab.Title = 'Propulsion';
            app.ConstraintAnalysisTab = uitab(app.TabGroup);
            app.ConstraintAnalysisTab.Title = 'Constraint Analysis';
            % Show the figure after all components are created
            app.CLIMBPredictorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Predictor_app_v3_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CLIMBPredictorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLIMBPredictorUIFigure)
        end
    end
end